classdef tile_view < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         % Description
         sortingapp;
         dname;
         col_num;
         row_num;
    end
    
    methods (Access = public)

function fillTableWithImages(app, imagePath, row_num, col_num)
    % Initialize cell array to store images
    imageData = cell(row_num, col_num);
    outputImage = cell(row_num, col_num);
    excelnumberM = app.sortingapp.enMatrix;
    sort = app.sortingapp.status;
    ch1 = app.sortingapp.Ch1; % update so it makes the tile vies based off of ch1 look at imaging.m to get a better idea of how to detect the naming convention 
    ch2 = app.sortingapp.Ch2;
    % Get image size
    [fixedHeight, fixedWidth] = deal(50, 50);
    
    parfor row = 1:row_num
        for column_counter = 1:col_num
            % Construct the folder name for the current column
            col = 73 - column_counter;
            columnFolder = sprintf('C%02d', col);

            % Construct the file path for the image
            if isequal(ch1, 'Cyan')
                filePath_ch1 = fullfile(imagePath, 'Cyan', 'S0000', columnFolder, sprintf('R%02d_C%02d_0000_00_Cyan.tif', row, col));
            elseif isequal(ch1, 'Green')
                filePath_ch1 = fullfile(imagePath, 'Green', 'S0000', columnFolder, sprintf('R%02d_C%02d_0000_01_Green.tif', row, col));
            elseif isequal(ch1, 'UV')
                filePath_ch1 = fullfile(imagePath, 'UV', 'S0000', columnFolder, sprintf('R%02d_C%02d_0000_02_UV.tif', row, col));
            elseif isequal(ch1, 'Blue')
                filePath_ch1 = fullfile(imagePath, 'Blue', 'S0000', columnFolder, sprintf('R%02d_C%02d_0000_03_Blue.tif', row, col));
            elseif isequal(ch1, 'Teal')
                filePath_ch1 = fullfile(imagePath, 'Teal', 'S0000', columnFolder, sprintf('R%02d_C%02d_0000_04_Teal.tif', row, col));
            elseif isequal(ch1, 'Red')
                filePath_ch1 = fullfile(imagePath, 'Red', 'S0000', columnFolder, sprintf('R%02d_C%02d_0000_05_Red.tif', row, col));
            else
                error('Invalid wavelength');
            end
    
            % Read and preprocess the image
            image_ch1 = imread(filePath_ch1);
            % image_ch1 = im2uint16(rgb2gray(image_ch1));

            if isequal(ch2, 'Cyan')
                filePath_ch2 = fullfile(imagePath, 'Cyan', 'S0000', columnFolder, sprintf('R%02d_C%02d_0000_00_Cyan.tif', row, col));
            elseif isequal(ch2, 'Green')
                filePath_ch2 = fullfile(imagePath, 'Green', 'S0000', columnFolder, sprintf('R%02d_C%02d_0000_01_Green.tif', row, col));
            elseif isequal(ch2, 'UV')
                filePath_ch2 = fullfile(imagePath, 'UV', 'S0000', columnFolder, sprintf('R%02d_C%02d_0000_02_UV.tif', row, col));
            elseif isequal(ch2, 'Blue')
                filePath_ch2 = fullfile(imagePath, 'Blue', 'S0000', columnFolder, sprintf('R%02d_C%02d_0000_03_Blue.tif', row, col));
            elseif isequal(ch2, 'Teal')
                filePath_ch2 = fullfile(imagePath, 'Teal', 'S0000', columnFolder, sprintf('R%02d_C%02d_0000_04_Teal.tif', row, col));
            elseif isequal(ch2, 'Red')
                filePath_ch2 = fullfile(imagePath, 'Red', 'S0000', columnFolder, sprintf('R%02d_C%02d_0000_05_Red.tif', row, col));
            else
                error('Invalid wavelength');
            end
            image_ch2 = imread(filePath_ch2);
            % image_ch2 = im2uint16(rgb2gray(image_ch2));
            color_image = zeros([size(image_ch2) 3]);
            color_image(:,:,1) = im2double(image_ch1);
            color_image(:,:,2) = im2double(image_ch2);

            % Calculate the center of the image
            center = floor(size(color_image) / 2);
            % color_image = color_image(200:400,200:400,:);

            color_image = imresize( color_image, [fixedHeight, fixedWidth]);
    
            
            % Convert to double with values between 0 and 1
            img_8bit = color_image / 100;

            % Determine the status of the current cell
            
            excelnumber = (excelnumberM(row,column_counter));
            % disp(excelnumberM);
            cellStatus = sort(excelnumber,21);
            condition = sort(excelnumber,16);
            
            centerX = size(color_image, 2) / 2;
            centerY = size(color_image, 1) / 2;
            radius = 15;
            % Apply filters based on cell status
            % if strcmp(condition, '~')
            %     % Create a gray image with the same size as color_image
            %     gray_value = 128;  % Adjust the gray value as needed (0 for black, 255 for white)
            %     gray_image = repmat(gray_value, size(color_image, 1), size(color_image, 2), size(color_image, 3));
            % 
            %     % Assign the gray_image to color_image
            %     color_image = gray_image;
            % end

            outputImage{row, column_counter} = color_image;
            % outputImage{row, column_counter} = img_8bit;
            if strcmp(cellStatus, 'C1') % Live cell
                % Overlay a blue square with 20% opacity
              color_image = insertShape(color_image, 'circle', [centerX, centerY, radius], 'Color', 'green',"Opacity",0.2,LineWidth=1 );
            elseif strcmp(cellStatus, 'C2') % Dead cell
               color_image = insertShape(color_image, 'circle', [centerX, centerY, radius], 'Color', 'cyan',"Opacity",0.2,LineWidth=1 );     
            elseif strcmp(cellStatus,'C3')
                color_image = insertShape(color_image, 'circle', [centerX, centerY, radius], 'Color', 'yellow',"Opacity",0.2,LineWidth=1 );
            end
            % 
            % image = color_image;
            img_8bit = color_image / 10;

            image = color_image;
            % Store the image
            imageData{row, column_counter} = image; % Convert to uint8 for compatibility
        end
    end
    
    % Determine the size of the assembled image
    [imageHeight, imageWidth, ~] = size(imageData{1, 1});
    assembledHeight = imageHeight * row_num;
    assembledWidth = imageWidth * col_num;

    % Initialize assembledImage using the size of the assembled image
    assembledImage = zeros(assembledHeight, assembledWidth, 3);
    
    Image2Save = zeros(assembledHeight, assembledWidth, 3);
    % Populate the assembled image
    % Populate the assembled image
    for row = 1:row_num
        for col = 1:col_num
            % Calculate the starting position for the current image
            xStart = (col - 1) * fixedWidth + 1;
            yStart = (row - 1) * fixedHeight + 1;

            Image2Save(yStart:yStart+fixedHeight-1, xStart:xStart+fixedWidth-1, :) = outputImage{row, col};
            % Insert the current image into the assembled image
            assembledImage(yStart:yStart+fixedHeight-1, xStart:xStart+fixedWidth-1, :) = imageData{row, col};
        end
    end
    
    % Display the assembled image on the UIAxes
    hImage = imshow(assembledImage, 'Parent', app.UIAxes);
    
    % Set callback function for image click
    set(hImage, 'ButtonDownFcn', @(src, event) handleClick(app, src, event));
    
    % Get the size of the app window
    appWidth = app.UIFigure.Position(3);
    appHeight = app.UIFigure.Position(4);
    
    % Calculate the aspect ratio of the assembled image
    aspectRatio = size(assembledImage, 2) / size(assembledImage, 1);
    
    % Calculate the maximum dimensions of the image while maintaining its aspect ratio
    maxWidth = appWidth;
    maxHeight = appHeight;
    
    if appWidth / appHeight > aspectRatio
        maxWidth = appHeight * aspectRatio;
    else
        maxHeight = appWidth / aspectRatio;
    end
    
    % Resize the UIAxes to match the maximum dimensions
    axesWidth = min(assembledWidth, maxWidth);
    axesHeight = min(assembledHeight, maxHeight);
    axesX = (appWidth - axesWidth) / 2;
    axesY = (appHeight - axesHeight) / 2;
    
    % Set the position of the UIAxes to center it and fill the app window
    app.UIAxes.Position = [axesX, axesY, axesWidth, axesHeight];
    app.UIAxes.Visible = 'on';
    
    % Set the limits and maintain aspect ratio
    app.UIAxes.XLim = [0.5 assembledWidth + 0.5];
    app.UIAxes.YLim = [0.5 assembledHeight + 0.5];
    app.UIAxes.DataAspectRatio = [1 1 1];

    % Specify the file path for saving the JPEG file
      % Provide the desired file path
    outputFileName = 'Results.jpg';  % File name

    % Create the full file path by concatenating folder location and file name
    outputFilePath = fullfile(app.sortingapp.result_filepath, outputFileName);
    % Save the assembledImage as a JPEG file
    imwrite(Image2Save, outputFilePath);

end


% Callback function for handling image click
function handleClick(app, src, event)
    % Get the clicked point
    point = event.IntersectionPoint;
    
    % Calculate the row and column of the clicked image
    row = ceil(point(2) / 50);  % Assuming fixed height and width are 21
    col = ceil(point(1) / 50);
    
    % Display the row and column in the terminal
    disp(['Clicked on image at row ', num2str(row), ' and column ', num2str(col)]);

    app.sortingapp.clickedonCell(row,col);
end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sortapp)
            app.sortingapp = sortapp;
            app.dname = sortapp.folder_location; 
            app.row_num = sortapp.EditField_10.Value;
            app.col_num = sortapp.EditField_11.Value;
            app.fillTableWithImages(app.dname, app.row_num,app.col_num);


        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Tile View ')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tile_view(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end