classdef advanced_laser_settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        NOWAVELENGTHSSELECTEDLabel  matlab.ui.control.Label
        SaveSettingsButton          matlab.ui.control.StateButton
        TabGroup                    matlab.ui.container.TabGroup
        UVTab                       matlab.ui.container.Tab
        FilterCubeUV                matlab.ui.control.DropDown
        FilterCubeBlue_2            matlab.ui.control.Label
        ExposureUV                  matlab.ui.control.NumericEditField
        ExposureEditField_2Label_4  matlab.ui.control.Label
        ImagingInformationLabel_5   matlab.ui.control.Label
        BlueTab                     matlab.ui.container.Tab
        FilterCubeBlue              matlab.ui.control.DropDown
        FilterCubeLabel             matlab.ui.control.Label
        ExposureBlue                matlab.ui.control.NumericEditField
        ExposureEditField_2Label_3  matlab.ui.control.Label
        ImagingInformationLabel_4   matlab.ui.control.Label
        CyanTab                     matlab.ui.container.Tab
        FilterCubeCyan              matlab.ui.control.DropDown
        FilterCubeLabel_2           matlab.ui.control.Label
        ImagingInformationLabel     matlab.ui.control.Label
        ExposureCyan                matlab.ui.control.NumericEditField
        ExposuresLabel              matlab.ui.control.Label
        TealTab                     matlab.ui.container.Tab
        FilterCubeTeal              matlab.ui.control.DropDown
        FilterCubeLabel_3           matlab.ui.control.Label
        ExposureTeal                matlab.ui.control.NumericEditField
        ExposureEditField_2Label_2  matlab.ui.control.Label
        ImagingInformationLabel_3   matlab.ui.control.Label
        GreenTab                    matlab.ui.container.Tab
        FilterCubeGreen             matlab.ui.control.DropDown
        FilterCubeLabel_4           matlab.ui.control.Label
        ExposureEditFieldGreen      matlab.ui.control.NumericEditField
        ExposuresLabel_2            matlab.ui.control.Label
        ImagingInformationLabel_2   matlab.ui.control.Label
        RedTab                      matlab.ui.container.Tab
        FilterCubeRed               matlab.ui.control.DropDown
        FilterCubeLabel_5           matlab.ui.control.Label
        ExposureRed                 matlab.ui.control.NumericEditField
        ExposureEditField_2Label_5  matlab.ui.control.Label
        ImagingInformationLabel_6   matlab.ui.control.Label
    end

    
    properties (Access = public)
        mainapp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.NOWAVELENGTHSSELECTEDLabel.Visible = "off";
            movegui(app.UIFigure,'center');
            app.mainapp = mainapp;
            app.ExposureRed.Value = app.mainapp.RedExposure;
            app.ExposureCyan.Value = app.mainapp.CyanExposure;
            app.ExposureTeal.Value = app.mainapp.TealExposure;
            app.ExposureEditFieldGreen.Value = app.mainapp.GreenExposure;
            app.ExposureBlue.Value = app.mainapp.BlueExposure;
            app.ExposureUV.Value = app.mainapp.UVExposure;

            if app.mainapp.CheckRed.Value
                app.TabGroup.SelectedTab = app.RedTab;
                
            elseif app.mainapp.CheckCyan.Value
                app.TabGroup.SelectedTab = app.CyanTab;
                
            elseif app.mainapp.CheckTeal.Value
                app.TabGroup.SelectedTab = app.TealTab;
                
            elseif app.mainapp.CheckGreen.Value
                app.TabGroup.SelectedTab = app.GreenTab;
                
            elseif app.mainapp.CheckBlue.Value
                app.TabGroup.SelectedTab = app.BlueTab;
                
            elseif app.mainapp.CheckUV.Value
                app.TabGroup.SelectedTab = app.UVTab;
                
            end
            if app.mainapp.CheckRed.Value == 0 && app.mainapp.CheckCyan.Value == 0 && app.mainapp.CheckTeal.Value == 0 && app.mainapp.CheckGreen.Value == 0 && app.mainapp.CheckBlue.Value == 0 && app.mainapp.CheckUV.Value == 0
                app.TabGroup.Visible ="off";
                app.NOWAVELENGTHSSELECTEDLabel.Visible = "on";
                disp("Check Selections in the Main App!");
            end
        end

        % Value changed function: SaveSettingsButton
        function SaveSettingsButtonValueChanged(app, event)
            % value = app.SaveSettingsButton.Value;
            app.mainapp.CyanExposure = app.ExposureCyan.Value;
            app.mainapp.GreenExposure = app.ExposureEditFieldGreen.Value;
            app.mainapp.BlueExposure = app.ExposureBlue.Value;
            app.mainapp.TealExposure = app.ExposureTeal.Value;
            app.mainapp.RedExposure = app.ExposureRed.Value;
            app.mainapp.UVExposure = app.ExposureUV.Value;
            app.mainapp.CyanFC = app.FilterCubeCyan.Value;
            app.mainapp.GreenFC = app.FilterCubeGreen.Value;
            app.mainapp.BlueFC = app.FilterCubeBlue.Value;
            app.mainapp.TealFC = app.FilterCubeTeal.Value;
            app.mainapp.RedFC = app.FilterCubeRed.Value;
            app.mainapp.UVFC = app.FilterCubeUV.Value; 

            delete(app)
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            % Look up the value of the paired checkbox            
            % Each "case" is the title of a Tab and contains the pair checkbox.
            switch selectedTab.Title
                case 'Red'
                    checkboxValue = app.mainapp.CheckRed.Value;
                case 'Cyan'
                    checkboxValue = app.mainapp.CheckCyan.Value;
                case 'Teal'
                    checkboxValue = app.mainapp.CheckTeal.Value;
                case 'Green'
                    checkboxValue = app.mainapp.CheckGreen.Value;
                case 'Blue'
                    checkboxValue = app.mainapp.CheckBlue.Value;
                case 'UV'
                    checkboxValue = app.mainapp.CheckUV.Value;
                otherwise
                    error('Unknown tab: %s.', selectedTab.Title)
            end
            
            % If paired checkbox is checked, return to the previously selected tab
            if ~checkboxValue
                app.TabGroup.SelectedTab = event.OldValue;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.UVTab = uitab(app.TabGroup);
            app.UVTab.Title = 'UV';
            app.ImagingInformationLabel_5 = uilabel(app.UVTab);
            app.ImagingInformationLabel_5.Text = 'Imaging Information';
            app.ExposureEditField_2Label_4 = uilabel(app.UVTab);
            app.ExposureEditField_2Label_4.Text = 'Exposure (s)';
            app.ExposureUV = uieditfield(app.UVTab, 'numeric');
            app.ExposureUV.Value = 1;
            app.FilterCubeBlue_2 = uilabel(app.UVTab);
            app.FilterCubeBlue_2.Text = 'Filter Cube';
            app.FilterCubeUV = uidropdown(app.UVTab);
            app.FilterCubeUV.Items = {'Empty', 'Analyzer', 'CFP-YFP-mCherry (Ex. 440-508-575)', 'DAPI-FITC-CY3-CY5 (Ex. 395-470-555-640)', 'Penta-Cube DO NOT USE', 'Empty'};
            app.FilterCubeUV.Placeholder = 'CFP-YFP-mCherry (Ex. 40-508-575)';
            app.FilterCubeUV.Value = 'CFP-YFP-mCherry (Ex. 440-508-575)';
            app.BlueTab = uitab(app.TabGroup);
            app.BlueTab.Title = 'Blue';
            app.ImagingInformationLabel_4 = uilabel(app.BlueTab);
            app.ImagingInformationLabel_4.Text = 'Imaging Information';
            app.ExposureEditField_2Label_3 = uilabel(app.BlueTab);
            app.ExposureEditField_2Label_3.Text = 'Exposure (s)';
            app.ExposureBlue = uieditfield(app.BlueTab, 'numeric');
            app.ExposureBlue.Value = 1;
            app.FilterCubeLabel = uilabel(app.BlueTab);
            app.FilterCubeLabel.Text = 'Filter Cube';
            app.FilterCubeBlue = uidropdown(app.BlueTab);
            app.FilterCubeBlue.Items = {'Empty', 'Analyzer', 'CFP-YFP-mCherry (Ex. 440-508-575)', 'DAPI-FITC-CY3-CY5 (Ex. 395-470-555-640)', 'Penta-Cube DO NOT USE', 'Empty'};
            app.FilterCubeBlue.Placeholder = 'CFP-YFP-mCherry (Ex. 40-508-575)';
            app.FilterCubeBlue.Value = 'CFP-YFP-mCherry (Ex. 440-508-575)';
            app.CyanTab = uitab(app.TabGroup);
            app.CyanTab.Title = 'Cyan';
            app.ExposuresLabel = uilabel(app.CyanTab);
            app.ExposuresLabel.Text = 'Exposure (s)';
            app.ExposureCyan = uieditfield(app.CyanTab, 'numeric');
            app.ExposureCyan.Value = 1;
            app.ImagingInformationLabel = uilabel(app.CyanTab);
            app.ImagingInformationLabel.Text = 'Imaging Information';
            app.FilterCubeLabel_2 = uilabel(app.CyanTab);
            app.FilterCubeLabel_2.Text = 'Filter Cube';
            app.FilterCubeCyan = uidropdown(app.CyanTab);
            app.FilterCubeCyan.Items = {'Empty', 'Analyzer', 'CFP-YFP-mCherry (Ex. 440-508-575)', 'DAPI-FITC-CY3-CY5 (Ex. 395-470-555-640)', 'Penta-Cube DO NOT USE', 'Empty'};
            app.FilterCubeCyan.Placeholder = 'CFP-YFP-mCherry (Ex. 40-508-575)';
            app.FilterCubeCyan.Value = 'CFP-YFP-mCherry (Ex. 440-508-575)';
            app.TealTab = uitab(app.TabGroup);
            app.TealTab.Title = 'Teal';
            app.ImagingInformationLabel_3 = uilabel(app.TealTab);
            app.ImagingInformationLabel_3.Text = 'Imaging Information';
            app.ExposureEditField_2Label_2 = uilabel(app.TealTab);
            app.ExposureEditField_2Label_2.Text = 'Exposure (s)';
            app.ExposureTeal = uieditfield(app.TealTab, 'numeric');
            app.ExposureTeal.Value = 1;
            app.FilterCubeLabel_3 = uilabel(app.TealTab);
            app.FilterCubeLabel_3.Text = 'Filter Cube';
            app.FilterCubeTeal = uidropdown(app.TealTab);
            app.FilterCubeTeal.Items = {'Empty', 'Analyzer', 'CFP-YFP-mCherry (Ex. 440-508-575)', 'DAPI-FITC-CY3-CY5 (Ex. 395-470-555-640)', 'Penta-Cube DO NOT USE', 'Empty'};
            app.FilterCubeTeal.Placeholder = 'CFP-YFP-mCherry (Ex. 40-508-575)';
            app.FilterCubeTeal.Value = 'CFP-YFP-mCherry (Ex. 440-508-575)';
            app.GreenTab = uitab(app.TabGroup);
            app.GreenTab.Title = 'Green';
            app.ImagingInformationLabel_2 = uilabel(app.GreenTab);
            app.ImagingInformationLabel_2.Text = 'Imaging Information';
            app.ExposuresLabel_2 = uilabel(app.GreenTab);
            app.ExposuresLabel_2.Text = 'Exposure (s)';
            app.ExposureEditFieldGreen = uieditfield(app.GreenTab, 'numeric');
            app.ExposureEditFieldGreen.Value = 1;
            app.FilterCubeLabel_4 = uilabel(app.GreenTab);
            app.FilterCubeLabel_4.Text = 'Filter Cube';
            app.FilterCubeGreen = uidropdown(app.GreenTab);
            app.FilterCubeGreen.Items = {'Empty', 'Analyzer', 'CFP-YFP-mCherry (Ex. 440-508-575)', 'DAPI-FITC-CY3-CY5 (Ex. 395-470-555-640)', 'Penta-Cube DO NOT USE', 'Empty'};
            app.FilterCubeGreen.Placeholder = 'CFP-YFP-mCherry (Ex. 40-508-575)';
            app.FilterCubeGreen.Value = 'CFP-YFP-mCherry (Ex. 440-508-575)';
            app.RedTab = uitab(app.TabGroup);
            app.RedTab.Title = 'Red';
            app.ImagingInformationLabel_6 = uilabel(app.RedTab);
            app.ImagingInformationLabel_6.Text = 'Imaging Information';
            app.ExposureEditField_2Label_5 = uilabel(app.RedTab);
            app.ExposureEditField_2Label_5.Text = 'Exposure (s)';
            app.ExposureRed = uieditfield(app.RedTab, 'numeric');
            app.ExposureRed.Value = 1;
            app.FilterCubeLabel_5 = uilabel(app.RedTab);
            app.FilterCubeLabel_5.Text = 'Filter Cube';
            app.FilterCubeRed = uidropdown(app.RedTab);
            app.FilterCubeRed.Items = {'Empty', 'Analyzer', 'CFP-YFP-mCherry (Ex. 440-508-575)', 'DAPI-FITC-CY3-CY5 (Ex. 395-470-555-640)', 'Penta-Cube DO NOT USE', 'Empty'};
            app.FilterCubeRed.Placeholder = 'CFP-YFP-mCherry (Ex. 40-508-575)';
            app.FilterCubeRed.Value = 'CFP-YFP-mCherry (Ex. 440-508-575)';
            app.SaveSettingsButton = uibutton(app.UIFigure, 'state');
            app.SaveSettingsButton.ValueChangedFcn = createCallbackFcn(app, @SaveSettingsButtonValueChanged, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.NOWAVELENGTHSSELECTEDLabel = uilabel(app.UIFigure);
            app.NOWAVELENGTHSSELECTEDLabel.Text = 'NO WAVELENGTHS SELECTED ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = advanced_laser_settings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end