classdef SingleCellGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Hyperlink                       matlab.ui.control.Hyperlink
        CellSortingAppPanel             matlab.ui.container.Panel
        StartSortingButton              matlab.ui.control.StateButton
        CornersPanel                    matlab.ui.container.Panel
        LoadPreviousButton              matlab.ui.control.Button
        GotoCornerButton                matlab.ui.control.Button
        SaveCornerButton                matlab.ui.control.Button
        ChipPositionsButtonGroup        matlab.ui.container.ButtonGroup
        AdvancedButton                  matlab.ui.control.Button
        bottomleftCheckBox              matlab.ui.control.CheckBox
        toprightCheckBox                matlab.ui.control.CheckBox
        topleftCheckBox                 matlab.ui.control.CheckBox
        MicroscopeControlsPanel         matlab.ui.container.Panel
        FilterCubeDropDown              matlab.ui.control.DropDown
        FilterCubeDropDownLabel         matlab.ui.control.Label
        ZStepmSpinner                   matlab.ui.control.Spinner
        ZStepmSpinnerLabel              matlab.ui.control.Label
        PositionofMicroscopemPanel      matlab.ui.container.Panel
        ZPosition                       matlab.ui.control.NumericEditField
        ZPositionLabelScope             matlab.ui.control.Label
        UpdateCurrentPositionButtonScope  matlab.ui.control.Button
        ZdownpbuttonScope               matlab.ui.control.Button
        ZuppbuttonScope                 matlab.ui.control.Button
        MicroscopeMovementLabel         matlab.ui.control.Label
        MainControlPanel                matlab.ui.container.Panel
        ChipIDEditField                 matlab.ui.control.NumericEditField
        ChipIDEditFieldLabel            matlab.ui.control.Label
        AquisitionPatternDropDown       matlab.ui.control.DropDown
        AquisitionPatternDropDownLabel  matlab.ui.control.Label
        QuitButton                      matlab.ui.control.Button
        PauseButton                     matlab.ui.control.Button
        StartImagingButton              matlab.ui.control.Button
        systemreadyStatus               matlab.ui.control.Label
        StatusLabel                     matlab.ui.control.Label
        OutputDirectoryLabel            matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        LiveviewControlPanel            matlab.ui.container.Panel
        GridSnapButton                  matlab.ui.control.StateButton
        GridLinesCheckBox               matlab.ui.control.CheckBox
        GaindbEditField                 matlab.ui.control.NumericEditField
        GaindbEditFieldLabel            matlab.ui.control.Label
        OpenLiveViewButton              matlab.ui.control.StateButton
        ExposureTimesEditField          matlab.ui.control.NumericEditField
        ExposureTimesEditFieldLabel     matlab.ui.control.Label
        StageControlsPanel              matlab.ui.container.Panel
        PositionofStagemPanel           matlab.ui.container.Panel
        YPosition                       matlab.ui.control.NumericEditField
        XPosition                       matlab.ui.control.NumericEditField
        YPositionLabel                  matlab.ui.control.Label
        XPositionLabel                  matlab.ui.control.Label
        UpdateCurrentPositionButton     matlab.ui.control.Button
        XYStepmSpinner                  matlab.ui.control.Spinner
        XYStepmSpinnerLabel             matlab.ui.control.Label
        XYPlaneStageMovementLabel       matlab.ui.control.Label
        XYLeftbutton                    matlab.ui.control.Button
        XYRightbutton                   matlab.ui.control.Button
        XYDownbutton                    matlab.ui.control.Button
        XYUpbutton                      matlab.ui.control.Button
        LaserControlPanel               matlab.ui.container.Panel
        CYANGREENSlider                 matlab.ui.control.Slider
        REDSlider                       matlab.ui.control.Slider
        GREENSlider                     matlab.ui.control.Slider
        TEALSlider                      matlab.ui.control.Slider
        CYANSlider                      matlab.ui.control.Slider
        BLUESlider                      matlab.ui.control.Slider
        UVSlider                        matlab.ui.control.Slider
        CYANGREENEditField              matlab.ui.control.NumericEditField
        CYANGREENEditFieldLabel         matlab.ui.control.Label
        OFFButtonCG                     matlab.ui.control.StateButton
        UVEditField                     matlab.ui.control.NumericEditField
        UVEditFieldLabel                matlab.ui.control.Label
        CheckUV                         matlab.ui.control.CheckBox
        UVLabel                         matlab.ui.control.Label
        OFFButtonUV                     matlab.ui.control.StateButton
        TEALEditField                   matlab.ui.control.NumericEditField
        TEALEditFieldLabel              matlab.ui.control.Label
        CheckTeal                       matlab.ui.control.CheckBox
        TealLabel                       matlab.ui.control.Label
        OFFButtonTeal                   matlab.ui.control.StateButton
        REDEditField                    matlab.ui.control.NumericEditField
        REDEditFieldLabel               matlab.ui.control.Label
        CheckRed                        matlab.ui.control.CheckBox
        RedLabel                        matlab.ui.control.Label
        OFFButtonRed                    matlab.ui.control.StateButton
        SettingsforImagingButton        matlab.ui.control.StateButton
        AcquireLabel                    matlab.ui.control.Label
        CheckGreen                      matlab.ui.control.CheckBox
        CheckCyan                       matlab.ui.control.CheckBox
        CheckBlue                       matlab.ui.control.CheckBox
        GREENEditField                  matlab.ui.control.NumericEditField
        GREENEditFieldLabel             matlab.ui.control.Label
        CYANEditField                   matlab.ui.control.NumericEditField
        CYANEditFieldLabel              matlab.ui.control.Label
        BLUEEditField                   matlab.ui.control.NumericEditField
        BLUEEditFieldLabel              matlab.ui.control.Label
        GreenLabel                      matlab.ui.control.Label
        CyanLabel                       matlab.ui.control.Label
        BlueLabel                       matlab.ui.control.Label
        Power0255Label                  matlab.ui.control.Label
        OFFButtonGreen                  matlab.ui.control.StateButton
        OFFButtonCyan                   matlab.ui.control.StateButton
        OFFButtonBlue                   matlab.ui.control.StateButton
        ONOFFLabel                      matlab.ui.control.Label
        ConnectionPanel                 matlab.ui.container.Panel
        DisconnectButtonCamera          matlab.ui.control.Button
        ConnecttoCameraButton           matlab.ui.control.StateButton
        cameraconnectionLabel           matlab.ui.control.Label
        CameraStatusLabel               matlab.ui.control.Label
        DisconnectButtonLaser           matlab.ui.control.Button
        DisconnectButtonStage           matlab.ui.control.Button
        DisconnectButtonMicroscope      matlab.ui.control.Button
        ConnecttoLaserButton            matlab.ui.control.StateButton
        laserconnectionLabel            matlab.ui.control.Label
        LaserStatusLabel                matlab.ui.control.Label
        ConnecttoStageButton            matlab.ui.control.StateButton
        stageconnectionLabel            matlab.ui.control.Label
        StageStatusLabel                matlab.ui.control.Label
        MicroscopecoonectionLabel       matlab.ui.control.Label
        MicroscopeStatusLabel           matlab.ui.control.Label
        ConnecttoMicroscopeButton       matlab.ui.control.Button
    end

    properties (Access = public) %these are the global variables that can be accessed through the app structure
       % Description
       ti = 'None'; %microscope
       vidobj = 'None'; %camera 
       stage = 'None';
       StagePosition = [0,0]; % Initial Position as [X,Y]
       ScopePosition = 0; 
       stageSpeed = 3; % this is just a general speed the maximum is 7.5 but I wouldnt mess around with it to much
       laser = 'None'; 

       %these variables are what the corner positions will be stored in
       topleft = [0,0]; 
       topright = [0,0];
       bottomleft = [0,0];
       %this is thescope's Z position I think it should be better to have
       %the stage store all the XYZ information and then the scope to just
       %have Z
       tl_scope = 0;
       tr_scope = 0;
       bl_scope = 0;
       
       %this is to store the tile we want to image during aquisition 
       imagingtile = [0,0];
       AquisitionPattern = "snake";
       advancedsettingsApp;
       CyanExposure = 0.1; % Pretty self explanitory
       GreenExposure= 0.1;
       BlueExposure= 0.1;
       UVExposure= 0.1;
       TealExposure= 0.1;
       RedExposure= 0.1;
       filtercubeposition = 3;
       PauseAcquisition = false;
       
       % Filter Cubes for each Wavelength
       CyanFC
       GreenFC
       BlueFC
       UVFC
       TealFC
       RedFC

       % Just the GUI
       AdvancedChipMovementApp;

       %Saving Info
       dname = '';

       sorting_app;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %run('init.m'); %this calls a function that appends all the
            
            %CHANGING THE VISIBILITY OF PANELS

            % app.StageControlsPanel.Visible = 'off';
            % app.LaserControlPanel.Visible = 'off';
            % app.LiveviewControlPanel.Visible = 'off'; 
            % app.MainControlPanel.Visible = 'off'; 
            % app.MicroscopeControlsPanel.Visible = 'off';
            % app.CornersPanel.Visible = 'off';

            movegui(app.UIFigure, "center");

            %file paths of the matlab Callback functions 
            addpath(fullfile(pwd, 'acquisition'));
            addpath(fullfile(pwd, 'connection'));
            addpath(fullfile(pwd, 'stage'));
            addpath(fullfile(pwd, 'lasers'));
            addpath(fullfile(pwd,'GUI'));

        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            disconnectFromStage(app);
            disconnectFromLaser(app);
            delete(app);
        end

        % Value changed function: OpenLiveViewButton
        function OpenLiveViewButtonValueChanged(app, event)

            src = getselectedsource(app.vidobj);
            % src.ExposureTimeControl = 'normal';
            % src.Exposure = app.ExposureTimesEditField.Value% to get from ms to seconds since exposure time is in seconds 
            src.Gain = app.GaindbEditField.Value;
            src.Shutter = app.ExposureTimesEditField.Value;
            if app.GridLinesCheckBox.Value
                resolution = app.vidobj.VideoResolution;
                colors = app.vidobj.NumberOfBands;
                himage = image(zeros(resolution(2), resolution(1), colors));
                setappdata(himage,"UpdatePreviewWindowFcn",@updateFrame)
                preview(app.vidobj,himage);
            else
                preview(app.vidobj); % comment this out when we try to test the squares drawn on
            end
        end

        % Button pushed function: ConnecttoMicroscopeButton
        function ConnecttoMicroscopeButtonPushed(app, event)
            app.MicroscopecoonectionLabel.FontColor = "#EDB120";
            app.MicroscopecoonectionLabel.Text = "Connecting..." ;
            
            connect2scope(app);  
            %
            %
            %app.ti = microscope; 
            app.MicroscopeControlsPanel.Visible = 'on';
            %checkconnectionStatus(app);


        end

        % Value changed function: ConnecttoStageButton
        function ConnecttoStageButtonValueChanged(app, event)
            %value = app.ConnecttoStageButton.Value;
            app.stageconnectionLabel.FontColor = "#EDB120";
            app.stageconnectionLabel.Text = "Connecting..." ;
            connect2stage(app,'COM4'); % not completed maybe just add the comport and test to see if that works          


            app.StageControlsPanel.Visible = 'on';
            app.CornersPanel.Visible = 'on'; 
            % UpdateCurrentPositionButtonPushed(app);
            % checkconnectionStatus(app);


        end

        % Value changed function: ConnecttoLaserButton
        function ConnecttoLaserButtonValueChanged(app, event)
            %value = app.ConnecttoLaserButton.Value;
            app.laserconnectionLabel.FontColor = "#EDB120";
            app.laserconnectionLabel.Text = "Connecting..." ;
            connect2lasers(app);
            %
            %
            %
            %
            app.LaserControlPanel.Visible = 'on';
            % checkconnectionStatus(app);
            
        end

        % Value changed function: ConnecttoCameraButton
        function ConnecttoCameraButtonValueChanged(app, event)
            %value = app.ConnecttoCameraButton.Value;
            app.cameraconnectionLabel.FontColor = "#EDB120";
            app.cameraconnectionLabel.Text = "Connecting..." ;
            
            connect2camera(app); % to debug use imaqhwinfo which lists the available image aqcuisition devices 
            %
            %
            %
            %
            app.LiveviewControlPanel.Visible = 'on'; 
            app.MainControlPanel.Visible = 'on'; %this is here because I am not to sure when else we should enable this window to be accesed 
            % checkconnectionStatus(app);
            
        end

        % Button pushed function: DisconnectButtonMicroscope
        function DisconnectButtonMicroscopePushed(app, event)
            %for this one add the disconection function call 
            % disconnectFromeScope(app);
        end

        % Button pushed function: DisconnectButtonStage
        function DisconnectButtonStagePushed(app, event)
            % add the disconnect function call 
            
            disconnectFromStage(app);

        end

        % Button pushed function: DisconnectButtonLaser
        function DisconnectButtonLaserPushed(app, event)
            %add the disconnect function call
            disableAll(app);
            disconnectFromLaser(app);

        end

        % Button pushed function: DisconnectButtonCamera
        function DisconnectButtonCameraPushed(app, event)
            %add the disconnect function call 
           
            % disconnectFromCamera(app); 
        end

        % Value changed function: GridSnapButton
        function GridSnapButtonValueChanged(app, event)
            % closepreview(app.vidobj);
            % Capture an image using the provided video object
            inputImage = getsnapshot(app.vidobj);
            
            % Get the size of the input image
            [rows, cols, ~] = size(inputImage);
        
            % Ensure the image can be evenly split into a 2x3 grid
            if mod(rows, 2) ~= 0 || mod(cols, 3) ~= 0
                error('Image size is not suitable for splitting into a 2x3 grid.');
            end
        
            % Calculate the size of each portion
            portionRows = rows / 2;
            portionCols = cols / 3;
        
            % Draw horizontal grid lines
            for i = 1:2
                inputImage(round(i * portionRows), :, :) = 255; % White line
            end
        
            % Draw vertical grid lines
            for j = 1:3
                inputImage(:, round(j * portionCols), :) = 255; % White line
            end
        
            % Display the image with grid
            figure;
            imshow(inputImage);
            title('Image with Grid');
            % Simply close the figure to break the loop in the OpenLiveViewButton function
            %close(hfigure);
            
        end

        % Button pushed function: XYLeftbutton
        function XYLeftbuttonButtonPushed(app, event)
            %one thing I am uncertain about is if the distance passed is
            %how much it moves from its current postion or where to move to
            
            %tenths of microns is what you are moving the stage hense why
            %we have a step size of 10 on the spinner 

            moveStageX(app,(app.XYStepmSpinner.Value));
            % getCurrentPosition(app);
            % app.XPositionLabel.Text = sprintf('X-Position : %.2f ', app.StagePosition(1));

        end

        % Button pushed function: XYRightbutton
        function XYRightbuttonButtonPushed(app, event)
            moveStageX(app,-1*(app.XYStepmSpinner.Value)); 

            %tenths of microns is what you are moving the stage hense why
            %we have a step size of 10 on the spinner 
            % getCurrentPosition(app);
            % app.XPositionLabel.Text = sprintf('X-Position : %.2f ', app.StagePosition(1));
        end

        % Button pushed function: XYDownbutton
        function XYDownbuttonButtonPushed(app, event)
            %tenths of microns is what you are moving the stage hense why
            %we have a step size of 10 on the spinner 
            moveStageY(app,(app.XYStepmSpinner.Value));
            % getCurrentPosition(app);
            % app.YPositionLabel.Text = sprintf('Y-Position: %.2f ', app.StagePosition(2));
        end

        % Button pushed function: XYUpbutton
        function XYUpbuttonButtonPushed(app, event)
            %tenths of microns is what you are moving the stage hense why
            %we have a step size of 10 on the spinner 
            moveStageY(app,-1*(app.XYStepmSpinner.Value));
            % getCurrentPosition(app);
            % app.YPositionLabel.Text = sprintf('Y-Position: %.2f ', app.StagePosition(2));
        end

        % Button pushed function: SaveCornerButton
        function SaveCornerButtonPushed(app, event)
            %First we need to make sure that one one check box is clicked 
            if app.bottomleftCheckBox.Value == 1 && app.toprightCheckBox.Value == 0 && app.topleftCheckBox.Value == 0
                % Bottom Left is selected
                getCurrentPosition(app);
                app.bottomleft = app.StagePosition;
                app.bl_scope = app.ScopePosition;
                cornerselectionStatus(app);
            elseif app.toprightCheckBox.Value == 1 && app.bottomleftCheckBox.Value == 0 && app.topleftCheckBox.Value == 0
                % Top Right is selected
                getCurrentPosition(app);
                app.topright = app.StagePosition;
                app.tr_scope = app.ScopePosition;
                cornerselectionStatus(app);
            elseif app.topleftCheckBox.Value == 1 && app.bottomleftCheckBox.Value == 0 && app.toprightCheckBox.Value == 0
                % Top Left is selected
                getCurrentPosition(app);
                app.topleft = app.StagePosition;
                app.tl_scope = app.ScopePosition;
                cornerselectionStatus(app);
            else
                disp("Check your Corner Selection!");
            end

        end

        % Button pushed function: GotoCornerButton
        function GotoCornerButtonPushed(app, event)
            %First we need to make sure that one one check box is clicked 
            if app.bottomleftCheckBox.Value == 1 && app.toprightCheckBox.Value == 0 && app.topleftCheckBox.Value == 0
                % Bottom Left is selected
                moveStage(app,app.bottomleft);
                moveScopeAbsZ(app,app.bl_scope);
                UpdateCurrentPositionButtonPushed(app);
            elseif app.toprightCheckBox.Value == 1 && app.bottomleftCheckBox.Value == 0 && app.topleftCheckBox.Value == 0
                % Top Right is selected
                moveStage(app,app.topright);
                moveScopeAbsZ(app,app.tr_scope);
                UpdateCurrentPositionButtonPushed(app);
            elseif app.topleftCheckBox.Value == 1 && app.bottomleftCheckBox.Value == 0 && app.toprightCheckBox.Value == 0
                % Top Left is selected
                moveStage(app,app.topleft);
                moveScopeAbsZ(app,app.tl_scope);
                UpdateCurrentPositionButtonPushed(app);
            else
                disp("Check your Corner Selection!");
            end
        end

        % Button pushed function: UpdateCurrentPositionButton
        function UpdateCurrentPositionButtonPushed(app, event)
            getCurrentPosition(app);
            app.XPosition.Value =  app.StagePosition(1);
            app.YPosition.Value = app.StagePosition(2);
            
        end

        % Value changed function: OFFButtonBlue
        function OFFButtonBlueValueChanged(app, event)
            if app.OFFButtonBlue.Text == "OFF"
                app.OFFButtonBlue.Text = "ON";
                enableBlue(app);
                app.BLUEEditFieldValueChanged();
            else 
                app.OFFButtonBlue.Text = "OFF";
                disableAll(app);
            end
        end

        % Value changed function: OFFButtonCyan
        function OFFButtonCyanValueChanged(app, event)
          if app.OFFButtonCyan.Text == "OFF"
                app.OFFButtonCyan.Text = "ON";
                cyanOnCmd = sscanf('4F 7B 50', '%2X'); % hex values for serial communication
                fwrite(app.laser, cyanOnCmd, 'uint8');
                % app.CYANEditFieldValueChanged();
          else 
                app.OFFButtonCyan.Text = "OFF";
                disableAll(app);
          end 
            
        end

        % Value changed function: OFFButtonGreen
        function OFFButtonGreenValueChanged(app, event)
          if app.OFFButtonGreen.Text == "OFF"
                app.OFFButtonGreen.Text = "ON";
                greenOnCmd = sscanf('4F 7D 50', '%2X'); % hex values for serial communication
                fwrite(app.laser, greenOnCmd, 'uint8');
                % app.GREENEditFieldValueChanged();
          else 
                app.OFFButtonGreen.Text = "OFF";
                disableAll(app);
          end 
            
            
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            % Toggle between pausing and unpausing the acquisition
            app.PauseAcquisition = ~app.PauseAcquisition;
        
            % Update the button text based on the current pause state
            if app.PauseAcquisition
                app.PauseButton.Text = 'Unpause'; % Change text to "Unpause"
            else
                app.PauseButton.Text = 'Pause'; % Change text to "Pause"
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.dname = uigetdir;
            % disp(app.dname)
            app.BrowseButton.Text = app.dname;
            app.BrowseButton.FontSize = 10;
            app.BrowseButton.FontColor = [0,0,1];
            
        end

        % Value changed function: BLUEEditField
        function BLUEEditFieldValueChanged(app, event)
            value = app.BLUEEditField.Value;
            % bluePowerDec = value;
            powerBlue(app,value);
            % bluePowerHex = dec2hex(255-bluePowerDec, 2); %convert intensity to a hex value
            % powerBlue = sscanf(['53 1A 03 01 F', bluePowerHex, '0 50'], '%2X');
            % fwrite(app.laser, powerBlue, 'uint8');
        end

        % Value changed function: CYANEditField
        function CYANEditFieldValueChanged(app, event)
            value = app.CYANEditField.Value;
            app.CYANSlider.Value = value;
            
        end

        % Value changed function: GREENEditField
        function GREENEditFieldValueChanged(app, event)
            value = app.GREENEditField.Value;
            app.GREENSlider.Value = value;
        end

        % Button pushed function: StartImagingButton
        function StartImagingButtonPushed(app, event)
            % need to have a check status to make sure that aquisiton can
            % be run. Current idea is to make a tally and once the tally
            % hits a certan point the status updates to ready to run or
            % something like that since text values cannot have callbacks
            % themselves 
            
            % GridSnapButtonValueChanged(app); %closes live view if it not already closed
             % Save the current tile positions to the MAT file
            tl = app.topleft;
            tr = app.topright;
            bl = app.bottomleft;
            tls = app.tl_scope;
            trs = app.tr_scope;
            bls = app.bl_scope;
        
            save('corner_coordinates.mat', 'tl', 'tr', 'bl', 'tls', 'trs', 'bls');
           
            % File path
            filePath = fullfile(app.dname, 'settings.txt');
            
            % Open file for writing
            fileID = fopen(filePath, 'w');
            
            % Write variable values with labels
            fprintf(fileID, 'Top Left Stage Cooridinates: %s\n', mat2str(app.topleft));
            fprintf(fileID, 'Top Right Stage Cooridinates: %s\n', mat2str(app.topright));
            fprintf(fileID, 'Bottom Left Stage Cooridinates: %s\n', mat2str(app.bottomleft));
            
            fprintf(fileID, 'Top Left Scope Z: %f\n', app.tl_scope);
            fprintf(fileID, 'Top Right Scope Z: %f\n', app.tr_scope);
            fprintf(fileID, 'Bottom Left Scope Z: %f\n', app.bl_scope);
                      
            fprintf(fileID, 'Cyan Exposure: %f\n', app.CyanExposure);
            fprintf(fileID, 'Green Exposure: %f\n', app.GreenExposure);
            fprintf(fileID, 'Blue Exposure: %f\n', app.BlueExposure);
            fprintf(fileID, 'UV Exposure: %f\n', app.UVExposure);
            fprintf(fileID, 'Teal Exposure: %f\n', app.TealExposure);
            fprintf(fileID, 'Red Exposure: %f\n', app.RedExposure);


            fprintf(fileID, 'Cyan Power: %f\n', app.CYANEditField.Value);
            fprintf(fileID, 'Green Power: %f\n', app.GREENEditField.Value);
            fprintf(fileID, 'Blue Power: %f\n', app.BLUEEditField.Value);
            fprintf(fileID, 'UV Power: %f\n', app.UVEditField.Value);
            fprintf(fileID, 'Teal Power: %f\n', app.TEALEditField.Value);
            fprintf(fileID, 'Red Powere: %f\n', app.REDEditField.Value);           
            % Write filter cube information 
            fprintf(fileID, 'Cyan Filter Cube: %s\n', app.CyanFC);
            fprintf(fileID, 'Green Filter Cube: %s\n', app.GreenFC);
            fprintf(fileID, 'Blue Filter Cube: %s\n', app.BlueFC);
            fprintf(fileID, 'UV Filter Cube: %s\n', app.UVFC);
            fprintf(fileID, 'Teal Filter Cube: %s\n', app.TealFC);
            fprintf(fileID, 'Red Filter Cube: %s\n', app.RedFC);
            
            % Close the file
            fclose(fileID);


            %this is my current thinking on how to do the main 
            aquisition(app);

            chipID = app.ChipIDEditField.Value;
            
            originalFilePath = 'SmartChipData_TEMPLATE.xlsx';
            % Generate the file name with .xls extension
            % Generate the file name with .xlsx extension for copy A
            
            fileNameA = sprintf('SmartChipData_A%06iA.xlsx', chipID);
            % Generate the file name with .xlsx extension for copy B
            fileNameB = sprintf('SmartChipData_A%06iB.xlsx', chipID);
            
            % Construct the full file paths
            filePathA = fullfile(app.dname, fileNameA);
            filePathB = fullfile(app.dname, fileNameB);
            
            % Specify the new file paths (destinations) for copy A and copy B
            newFilePathA = filePathA;
            newFilePathB = filePathB;
            
            % Copy the file to the new locations for copy A and copy B
            copyfile(originalFilePath, newFilePathA);
            copyfile(originalFilePath, newFilePathB);
            
        end

        % Button pushed function: UpdateCurrentPositionButtonScope
        function UpdateCurrentPositionButtonScopePushed(app, event)
            getCurrentPosition(app);
            app.ZPosition.Value = app.ScopePosition;
        end

        % Button pushed function: ZuppbuttonScope
        function ZuppbuttonScopeButtonPushed(app, event)
            moveScopeZ(app, app.ZStepmSpinner.Value);
        end

        % Button pushed function: ZdownpbuttonScope
        function ZdownpbuttonScopeButtonPushed(app, event)
            moveScopeZ(app, -1*app.ZStepmSpinner.Value);
        end

        % Button pushed function: LoadPreviousButton
        function LoadPreviousButtonPushed(app, event)
            if exist('corner_coordinates.mat', 'file') == 2
                loadedData = load('corner_coordinates.mat');
                % Check if the file is not empty
                if ~isempty(fieldnames(loadedData))
                    % Access variables from the loaded structure
                    app.topleft = loadedData.tl;
                    app.topright = loadedData.tr;
                    app.bottomleft = loadedData.bl;
                    app.tl_scope = loadedData.tls;
                    app.tr_scope = loadedData.trs;
                    app.bl_scope = loadedData.bls;
                else
                    disp('MAT file is empty.');
                end
            else
                disp('MAT file does not exist.');
            end
        end

        % Value changed function: ExposureTimesEditField
        function ExposureTimesEditFieldValueChanged(app, event)
           % exposure is set when the liveview is opened
        end

        % Value changed function: SettingsforImagingButton
        function SettingsforImagingButtonValueChanged(app, event)
            % value = app.SettingsforImagingButton.Value;
            app.advancedsettingsApp = advanced_laser_settings(app);
            waitfor(app.advancedsettingsApp);
            % disp(app.CyanExposure);
            % disp(app.BlueExposure);
            % disp(app.GreenExposure);
            % disp(app.UVExposure);
            % disp(app.TealExposure);
            % disp(app.RedExposure);
            % disp(app.UVFC)
            app.systemreadyStatus.FontColor = '#77AC30';
            app.systemreadyStatus.Text = 'System is Ready';
            
        end

        % Value changed function: OFFButtonRed
        function OFFButtonRedValueChanged(app, event)
          if app.OFFButtonRed.Text == "OFF"
                app.OFFButtonRed.Text = "ON";
                redOnCmd = sscanf('4F 7E 50', '%2X'); % hex values for serial communication
                fwrite(app.laser, redOnCmd, 'uint8');
                app.REDEditFieldValueChanged();
          else 
                app.OFFButtonRed.Text = "OFF";
                
                disableAll(app);
          end            
        end

        % Value changed function: REDEditField
        function REDEditFieldValueChanged(app, event)
            value = app.REDEditField.Value;
            app.REDSlider.Value = value;
        end

        % Value changed function: OFFButtonTeal
        function OFFButtonTealValueChanged(app, event)
          if app.OFFButtonTeal.Text == "OFF"
                app.OFFButtonTeal.Text = "ON";
                tealOnCmd = sscanf('4F 3F 50', '%2X'); % hex values for serial communication
                fwrite(app.laser, tealOnCmd, 'uint8');
                app.TEALEditFieldValueChanged();
          else 
                app.OFFButtonTeal.Text = "OFF";
                disableAll(app);
          end
            
        end

        % Value changed function: OFFButtonUV
        function OFFButtonUVValueChanged(app, event)
            if app.OFFButtonUV.Text == "OFF"
                    app.OFFButtonUV.Text = "ON";
                    UVOnCmd = sscanf('4F 77 50', '%2X'); % hex values for serial communication
                    fwrite(app.laser, UVOnCmd, 'uint8');
                    app.UVEditFieldValueChanged();
            else 
                    app.OFFButtonUV.Text = "OFF";
                    disableAll(app);
            end
        end

        % Value changed function: TEALEditField
        function TEALEditFieldValueChanged(app, event)
            value = round(app.TEALEditField.Value);
            app.TEALSlider.Value = value;
        end

        % Value changed function: UVEditField
        function UVEditFieldValueChanged(app, event)
            value = app.UVEditField.Value;
            app.UVSlider.Value = value;
           
        end

        % Value changed function: CheckTeal
        function CheckTealValueChanged(app, event)
            % value = app.CheckTeal.Value;
            
        end

        % Value changed function: CheckUV
        function CheckUVValueChanged(app, event)
            % value = app.CheckUV.Value;
            
        end

        % Value changed function: FilterCubeDropDown
        function FilterCubeDropDownValueChanged(app, event)
            value = app.FilterCubeDropDown.Value;
            changeFilterCube(app, value);
        end

        % Button pushed function: AdvancedButton
        function AdvancedButtonPushed(app, event)
            app.AdvancedChipMovementApp = advanced_chip_movement(app);
            waitfor(app.AdvancedChipMovementApp);
        end

        % Value changed function: StartSortingButton
        function StartSortingButtonValueChanged(app, event)
            app.sorting_app = SingleCellSorting_faster(app);
            waitfor(app.sorting_app);
            
        end

        % Value changed function: OFFButtonCG
        function OFFButtonCGValueChanged(app, event)
            if app.OFFButtonCG.Text == "OFF"
                    app.OFFButtonCG.Text = "ON";
                    greenAndCyanOnCmd = sscanf('4F 79 50', '%2X'); % Enable Green and Cyan (bit 1 and bit 2)
                    fwrite(app.laser, greenAndCyanOnCmd, 'uint8');
                    app.CYANGREENEditFieldValueChanged();
            else 
                    app.OFFButtonCG.Text = "OFF";
                    disableAll(app);
            end
            
        end

        % Value changed function: CYANGREENEditField
        function CYANGREENEditFieldValueChanged(app, event)
            greenAndCyanPowerDec = app.CYANGREENEditField.Value; % Maximum intensity
            app.CYANGREENSlider.Value = greenAndCyanPowerDec;
        end

        % Value changed function: CYANSlider
        function CYANSliderValueChanged(app, event)
            value = round(app.CYANSlider.Value);
            app.CYANEditField.Value = value;
            cyanPowerDec = value;
            cyanPowerHex = dec2hex(255-cyanPowerDec, 2);
            powerCyanCmd = sscanf(['53 18 03 02 F', cyanPowerHex, '0 50'], '%2X');
            fwrite(app.laser, powerCyanCmd, 'uint8');
            
        end

        % Value changed function: UVSlider
        function UVSliderValueChanged(app, event)
            value = round(app.UVSlider.Value);
            app.UVEditField.Value = value;
            UVPowerDec = value;
            UVPowerHex = dec2hex(255-UVPowerDec, 2);
            powerUV = sscanf(['53 18 03 01 F', UVPowerHex, '0 50'], '%2X');
            fwrite(app.laser, powerUV, 'uint8');
        end

        % Value changing function: UVSlider
        function UVSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UVEditField.Value = round(changingValue);
            
        end

        % Value changed function: BLUESlider
        function BLUESliderValueChanged(app, event)
            value = round(app.BLUESlider.Value);
            app.BLUEEditField.Value = value;
            powerBlue(app,value);


        end

        % Value changing function: CYANSlider
        function CYANSliderValueChanging(app, event)
            changingValue = event.Value;
            app.CYANEditField.Value = round(changingValue);
        end

        % Value changing function: BLUESlider
        function BLUESliderValueChanging(app, event)
            changingValue = event.Value;
            app.BLUEEditField.Value = round(changingValue);
        end

        % Value changed function: TEALSlider
        function TEALSliderValueChanged(app, event)
            value = round(app.TEALSlider.Value);
            app.TEALEditField.Value = value;
            tealPowerDec = value;            
            tealPowerHex = dec2hex(255-tealPowerDec, 2);
            powerTealCmd = sscanf(['53 1A 03 02 F', tealPowerHex, '0 50'], '%2X');
            fwrite(app.laser, powerTealCmd, 'uint8');
        end

        % Value changing function: TEALSlider
        function TEALSliderValueChanging(app, event)
            changingValue = event.Value;
            app.TEALEditField.Value = round(changingValue);
        end

        % Value changed function: GREENSlider
        function GREENSliderValueChanged(app, event)
            value = round(app.GREENSlider.Value);
            greenPowerDec = value;
            greenPowerHex = dec2hex(255-greenPowerDec, 2);
            powerGreenCmd = sscanf(['53 18 03 04 F', greenPowerHex, '0 50'], '%2X');
            fwrite(app.laser, powerGreenCmd, 'uint8'); 
        end

        % Value changing function: GREENSlider
        function GREENSliderValueChanging(app, event)
            changingValue = event.Value;
            app.GREENEditField.Value = round(changingValue);
        end

        % Value changed function: REDSlider
        function REDSliderValueChanged(app, event)
            value = round(app.REDSlider.Value);
            redPowerDec = value;
            redPowerHex = dec2hex(255-redPowerDec, 2);
            powerRedCmd =  sscanf(['53 18 03 08 F', redPowerHex, '0 50'], '%2X');
            fwrite(app.laser,powerRedCmd,"uint8");
            
        end

        % Value changing function: REDSlider
        function REDSliderValueChanging(app, event)
            changingValue = event.Value;
            app.REDEditField.Value = round(changingValue);
        end

        % Value changed function: CYANGREENSlider
        function CYANGREENSliderValueChanged(app, event)
            greenAndCyanPowerDec = round(app.CYANGREENSlider.Value);
            greenAndCyanPowerHex = dec2hex(255 - greenAndCyanPowerDec, 2);
            powerGreenAndCyanCmd = sscanf(['53 18 03 06 F', greenAndCyanPowerHex, '0 50'], '%2X'); % Setting intensity for both Green and Cyan
            fwrite(app.laser, powerGreenAndCyanCmd, 'uint8');
        end

        % Value changing function: CYANGREENSlider
        function CYANGREENSliderValueChanging(app, event)
            changingValue = event.Value;
            app.CYANGREENEditField.Value = round(changingValue);
        end

        % Clicked callback: AquisitionPatternDropDown
        function AquisitionPatternDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Value changed function: AquisitionPatternDropDown
        function AquisitionPatternDropDownValueChanged(app, event)
            value = app.AquisitionPatternDropDown.Value;
            app.AquisitionPattern = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnectionPanel = uipanel(app.UIFigure);
            app.ConnectionPanel.Title = 'Connection';
            app.ConnecttoMicroscopeButton = uibutton(app.ConnectionPanel, 'push');
            app.ConnecttoMicroscopeButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoMicroscopeButtonPushed, true);
            app.ConnecttoMicroscopeButton.Text = 'Connect to Microscope';
            app.MicroscopeStatusLabel = uilabel(app.ConnectionPanel);
            app.MicroscopeStatusLabel.Text = 'Microscope Status: ';
            app.MicroscopecoonectionLabel = uilabel(app.ConnectionPanel);
            app.MicroscopecoonectionLabel.Text = 'Not Connected';
            app.StageStatusLabel = uilabel(app.ConnectionPanel);
            app.StageStatusLabel.Text = 'Stage Status: ';
            app.stageconnectionLabel = uilabel(app.ConnectionPanel);
            app.stageconnectionLabel.Text = 'Not Connected';
            app.ConnecttoStageButton = uibutton(app.ConnectionPanel, 'state');
            app.ConnecttoStageButton.ValueChangedFcn = createCallbackFcn(app, @ConnecttoStageButtonValueChanged, true);
            app.ConnecttoStageButton.Text = 'Connect to Stage';
            app.LaserStatusLabel = uilabel(app.ConnectionPanel);
            app.LaserStatusLabel.Text = 'Laser Status: ';
            app.laserconnectionLabel = uilabel(app.ConnectionPanel);
            app.laserconnectionLabel.Text = 'Not Connected';
            app.ConnecttoLaserButton = uibutton(app.ConnectionPanel, 'state');
            app.ConnecttoLaserButton.ValueChangedFcn = createCallbackFcn(app, @ConnecttoLaserButtonValueChanged, true);
            app.ConnecttoLaserButton.Text = 'Connect to Laser';
            app.DisconnectButtonMicroscope = uibutton(app.ConnectionPanel, 'push');
            app.DisconnectButtonMicroscope.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonMicroscopePushed, true);
            app.DisconnectButtonMicroscope.Text = 'Disconnect';
            app.DisconnectButtonStage = uibutton(app.ConnectionPanel, 'push');
            app.DisconnectButtonStage.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonStagePushed, true);
            app.DisconnectButtonStage.Text = 'Disconnect';
            app.DisconnectButtonLaser = uibutton(app.ConnectionPanel, 'push');
            app.DisconnectButtonLaser.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonLaserPushed, true);
            app.DisconnectButtonLaser.Text = 'Disconnect';
            app.CameraStatusLabel = uilabel(app.ConnectionPanel);
            app.CameraStatusLabel.Text = 'Camera Status: ';
            app.cameraconnectionLabel = uilabel(app.ConnectionPanel);
            app.cameraconnectionLabel.Text = 'Not Connected';
            app.ConnecttoCameraButton = uibutton(app.ConnectionPanel, 'state');
            app.ConnecttoCameraButton.ValueChangedFcn = createCallbackFcn(app, @ConnecttoCameraButtonValueChanged, true);
            app.ConnecttoCameraButton.Text = 'Connect to Camera';
            app.DisconnectButtonCamera = uibutton(app.ConnectionPanel, 'push');
            app.DisconnectButtonCamera.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonCameraPushed, true);
            app.DisconnectButtonCamera.Text = 'Disconnect';
            app.LaserControlPanel = uipanel(app.UIFigure);
            app.LaserControlPanel.Title = 'Laser Control ';
            app.ONOFFLabel = uilabel(app.LaserControlPanel);
            app.ONOFFLabel.Text = 'ON/OFF';
            app.OFFButtonBlue = uibutton(app.LaserControlPanel, 'state');
            app.OFFButtonBlue.ValueChangedFcn = createCallbackFcn(app, @OFFButtonBlueValueChanged, true);
            app.OFFButtonBlue.Text = 'OFF';
            app.OFFButtonCyan = uibutton(app.LaserControlPanel, 'state');
            app.OFFButtonCyan.ValueChangedFcn = createCallbackFcn(app, @OFFButtonCyanValueChanged, true);
            app.OFFButtonCyan.Text = 'OFF';
            app.OFFButtonGreen = uibutton(app.LaserControlPanel, 'state');
            app.OFFButtonGreen.ValueChangedFcn = createCallbackFcn(app, @OFFButtonGreenValueChanged, true);
            app.OFFButtonGreen.Text = 'OFF';
            app.Power0255Label = uilabel(app.LaserControlPanel);
            app.Power0255Label.Text = 'Power           (0-255)';
            app.BlueLabel = uilabel(app.LaserControlPanel);
            app.BlueLabel.Text = 'Blue';
            app.CyanLabel = uilabel(app.LaserControlPanel);
            app.CyanLabel.Text = 'Cyan';
            app.GreenLabel = uilabel(app.LaserControlPanel);
            app.GreenLabel.Text = 'Green';
            app.BLUEEditFieldLabel = uilabel(app.LaserControlPanel);
            app.BLUEEditFieldLabel.Text = 'BLUE';
            app.BLUEEditField = uieditfield(app.LaserControlPanel, 'numeric');
            app.BLUEEditField.Limits = [0 255];
            app.BLUEEditField.ValueChangedFcn = createCallbackFcn(app, @BLUEEditFieldValueChanged, true);
            app.BLUEEditField.Value = 4;
            app.CYANEditFieldLabel = uilabel(app.LaserControlPanel);
            app.CYANEditFieldLabel.Text = 'CYAN';
            app.CYANEditField = uieditfield(app.LaserControlPanel, 'numeric');
            app.CYANEditField.Limits = [0 255];
            app.CYANEditField.ValueChangedFcn = createCallbackFcn(app, @CYANEditFieldValueChanged, true);
            app.CYANEditField.Value = 255;
            app.GREENEditFieldLabel = uilabel(app.LaserControlPanel);
            app.GREENEditFieldLabel.Text = 'GREEN';
            app.GREENEditField = uieditfield(app.LaserControlPanel, 'numeric');
            app.GREENEditField.Limits = [0 255];
            app.GREENEditField.ValueChangedFcn = createCallbackFcn(app, @GREENEditFieldValueChanged, true);
            app.GREENEditField.Value = 255;
            app.CheckBlue = uicheckbox(app.LaserControlPanel);
            app.CheckBlue.Text = '';
            app.CheckCyan = uicheckbox(app.LaserControlPanel);
            app.CheckCyan.Text = '';
            app.CheckGreen = uicheckbox(app.LaserControlPanel);
            app.CheckGreen.Text = '';
            app.AcquireLabel = uilabel(app.LaserControlPanel);
            app.AcquireLabel.Text = 'Acquire: ';
            app.SettingsforImagingButton = uibutton(app.LaserControlPanel, 'state');
            app.SettingsforImagingButton.ValueChangedFcn = createCallbackFcn(app, @SettingsforImagingButtonValueChanged, true);
            app.SettingsforImagingButton.Text = 'Settings for Imaging';
            app.OFFButtonRed = uibutton(app.LaserControlPanel, 'state');
            app.OFFButtonRed.ValueChangedFcn = createCallbackFcn(app, @OFFButtonRedValueChanged, true);
            app.OFFButtonRed.Text = 'OFF';
            app.RedLabel = uilabel(app.LaserControlPanel);
            app.RedLabel.Text = 'Red';
            app.CheckRed = uicheckbox(app.LaserControlPanel);
            app.CheckRed.Text = '';
            app.REDEditFieldLabel = uilabel(app.LaserControlPanel);
            app.REDEditFieldLabel.Text = 'RED';
            app.REDEditField = uieditfield(app.LaserControlPanel, 'numeric');
            app.REDEditField.Limits = [0 255];
            app.REDEditField.ValueChangedFcn = createCallbackFcn(app, @REDEditFieldValueChanged, true);
            app.OFFButtonTeal = uibutton(app.LaserControlPanel, 'state');
            app.OFFButtonTeal.ValueChangedFcn = createCallbackFcn(app, @OFFButtonTealValueChanged, true);
            app.OFFButtonTeal.Text = 'OFF';
            app.TealLabel = uilabel(app.LaserControlPanel);
            app.TealLabel.Text = 'Teal';
            app.CheckTeal = uicheckbox(app.LaserControlPanel);
            app.CheckTeal.ValueChangedFcn = createCallbackFcn(app, @CheckTealValueChanged, true);
            app.CheckTeal.Text = '';
            app.TEALEditFieldLabel = uilabel(app.LaserControlPanel);
            app.TEALEditFieldLabel.Text = 'TEAL';
            app.TEALEditField = uieditfield(app.LaserControlPanel, 'numeric');
            app.TEALEditField.Limits = [0 255];
            app.TEALEditField.ValueChangedFcn = createCallbackFcn(app, @TEALEditFieldValueChanged, true);
            app.OFFButtonUV = uibutton(app.LaserControlPanel, 'state');
            app.OFFButtonUV.ValueChangedFcn = createCallbackFcn(app, @OFFButtonUVValueChanged, true);
            app.OFFButtonUV.Text = 'OFF';
            app.UVLabel = uilabel(app.LaserControlPanel);
            app.UVLabel.Text = 'UV';
            app.CheckUV = uicheckbox(app.LaserControlPanel);
            app.CheckUV.ValueChangedFcn = createCallbackFcn(app, @CheckUVValueChanged, true);
            app.CheckUV.Text = '';
            app.UVEditFieldLabel = uilabel(app.LaserControlPanel);
            app.UVEditFieldLabel.Text = 'UV';
            app.UVEditField = uieditfield(app.LaserControlPanel, 'numeric');
            app.UVEditField.Limits = [0 255];
            app.UVEditField.ValueChangedFcn = createCallbackFcn(app, @UVEditFieldValueChanged, true);
            app.OFFButtonCG = uibutton(app.LaserControlPanel, 'state');
            app.OFFButtonCG.ValueChangedFcn = createCallbackFcn(app, @OFFButtonCGValueChanged, true);
            app.OFFButtonCG.Text = 'OFF';
            app.CYANGREENEditFieldLabel = uilabel(app.LaserControlPanel);
            app.CYANGREENEditFieldLabel.Text = 'CYAN & GREEN';
            app.CYANGREENEditField = uieditfield(app.LaserControlPanel, 'numeric');
            app.CYANGREENEditField.Limits = [0 255];
            app.CYANGREENEditField.ValueChangedFcn = createCallbackFcn(app, @CYANGREENEditFieldValueChanged, true);
            app.CYANGREENEditField.Value = 255;
            app.UVSlider = uislider(app.LaserControlPanel);
            app.UVSlider.Limits = [0 255];
            app.UVSlider.ValueChangedFcn = createCallbackFcn(app, @UVSliderValueChanged, true);
            app.UVSlider.ValueChangingFcn = createCallbackFcn(app, @UVSliderValueChanging, true);
            app.BLUESlider = uislider(app.LaserControlPanel);
            app.BLUESlider.Limits = [0 255];
            app.BLUESlider.ValueChangedFcn = createCallbackFcn(app, @BLUESliderValueChanged, true);
            app.BLUESlider.ValueChangingFcn = createCallbackFcn(app, @BLUESliderValueChanging, true);
            app.BLUESlider.Value = 4;
            app.CYANSlider = uislider(app.LaserControlPanel);
            app.CYANSlider.Limits = [0 255];
            app.CYANSlider.ValueChangedFcn = createCallbackFcn(app, @CYANSliderValueChanged, true);
            app.CYANSlider.ValueChangingFcn = createCallbackFcn(app, @CYANSliderValueChanging, true);
            app.CYANSlider.Value = 255;
            app.TEALSlider = uislider(app.LaserControlPanel);
            app.TEALSlider.Limits = [0 255];
            app.TEALSlider.ValueChangedFcn = createCallbackFcn(app, @TEALSliderValueChanged, true);
            app.TEALSlider.ValueChangingFcn = createCallbackFcn(app, @TEALSliderValueChanging, true);
            app.GREENSlider = uislider(app.LaserControlPanel);
            app.GREENSlider.Limits = [0 255];
            app.GREENSlider.ValueChangedFcn = createCallbackFcn(app, @GREENSliderValueChanged, true);
            app.GREENSlider.ValueChangingFcn = createCallbackFcn(app, @GREENSliderValueChanging, true);
            app.GREENSlider.Value = 255;
            app.REDSlider = uislider(app.LaserControlPanel);
            app.REDSlider.Limits = [0 255];
            app.REDSlider.ValueChangedFcn = createCallbackFcn(app, @REDSliderValueChanged, true);
            app.REDSlider.ValueChangingFcn = createCallbackFcn(app, @REDSliderValueChanging, true);
            app.CYANGREENSlider = uislider(app.LaserControlPanel);
            app.CYANGREENSlider.Limits = [0 255];
            app.CYANGREENSlider.ValueChangedFcn = createCallbackFcn(app, @CYANGREENSliderValueChanged, true);
            app.CYANGREENSlider.ValueChangingFcn = createCallbackFcn(app, @CYANGREENSliderValueChanging, true);
            app.CYANGREENSlider.Value = 255;
            app.StageControlsPanel = uipanel(app.UIFigure);
            app.StageControlsPanel.Title = 'Stage Controls';
            app.XYUpbutton = uibutton(app.StageControlsPanel, 'push');
            app.XYUpbutton.ButtonPushedFcn = createCallbackFcn(app, @XYUpbuttonButtonPushed, true);
            app.XYUpbutton.Text = '↑';
            app.XYDownbutton = uibutton(app.StageControlsPanel, 'push');
            app.XYDownbutton.ButtonPushedFcn = createCallbackFcn(app, @XYDownbuttonButtonPushed, true);
            app.XYDownbutton.Text = '↓';
            app.XYRightbutton = uibutton(app.StageControlsPanel, 'push');
            app.XYRightbutton.ButtonPushedFcn = createCallbackFcn(app, @XYRightbuttonButtonPushed, true);
            app.XYRightbutton.Text = '→';
            app.XYLeftbutton = uibutton(app.StageControlsPanel, 'push');
            app.XYLeftbutton.ButtonPushedFcn = createCallbackFcn(app, @XYLeftbuttonButtonPushed, true);
            app.XYLeftbutton.Text = '←';
            app.XYPlaneStageMovementLabel = uilabel(app.StageControlsPanel);
            app.XYPlaneStageMovementLabel.Text = 'XY Plane Stage Movement';
            app.XYStepmSpinnerLabel = uilabel(app.StageControlsPanel);
            app.XYStepmSpinnerLabel.Text = 'XY Step (µm)';
            app.XYStepmSpinner = uispinner(app.StageControlsPanel);
            app.XYStepmSpinner.Step = 10;
            app.XYStepmSpinner.Limits = [0 Inf];
            app.XYStepmSpinner.Value = 1000;
            app.PositionofStagemPanel = uipanel(app.StageControlsPanel);
            app.PositionofStagemPanel.Title = 'Position of Stage (µm)';
            app.UpdateCurrentPositionButton = uibutton(app.PositionofStagemPanel, 'push');
            app.UpdateCurrentPositionButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateCurrentPositionButtonPushed, true);
            app.UpdateCurrentPositionButton.Text = 'Update Current Position';
            app.XPositionLabel = uilabel(app.PositionofStagemPanel);
            app.XPositionLabel.Text = 'X- Position :';
            app.YPositionLabel = uilabel(app.PositionofStagemPanel);
            app.YPositionLabel.Text = 'Y- Position :';
            app.XPosition = uieditfield(app.PositionofStagemPanel, 'numeric');
            app.YPosition = uieditfield(app.PositionofStagemPanel, 'numeric');
            app.LiveviewControlPanel = uipanel(app.UIFigure);
            app.LiveviewControlPanel.Title = 'Liveview Control';
            app.ExposureTimesEditFieldLabel = uilabel(app.LiveviewControlPanel);
            app.ExposureTimesEditFieldLabel.Text = 'Exposure Time (s)';
            app.ExposureTimesEditField = uieditfield(app.LiveviewControlPanel, 'numeric');
            app.ExposureTimesEditField.Limits = [0.0346899 4.5];
            app.ExposureTimesEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureTimesEditFieldValueChanged, true);
            app.ExposureTimesEditField.Value = 4.5;
            app.OpenLiveViewButton = uibutton(app.LiveviewControlPanel, 'state');
            app.OpenLiveViewButton.ValueChangedFcn = createCallbackFcn(app, @OpenLiveViewButtonValueChanged, true);
            app.OpenLiveViewButton.Text = 'Open Live View ';
            app.GaindbEditFieldLabel = uilabel(app.LiveviewControlPanel);
            app.GaindbEditFieldLabel.Text = 'Gain (db)';
            app.GaindbEditField = uieditfield(app.LiveviewControlPanel, 'numeric');
            app.GaindbEditField.Limits = [0 23];
            app.GaindbEditField.Value = 22;
            app.GridLinesCheckBox = uicheckbox(app.LiveviewControlPanel);
            app.GridLinesCheckBox.Text = 'Grid Lines';
            app.GridSnapButton = uibutton(app.LiveviewControlPanel, 'state');
            app.GridSnapButton.ValueChangedFcn = createCallbackFcn(app, @GridSnapButtonValueChanged, true);
            app.GridSnapButton.Text = 'Grid Snap';
            app.MainControlPanel = uipanel(app.UIFigure);
            app.MainControlPanel.Title = 'Main Control';
            app.BrowseButton = uibutton(app.MainControlPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.OutputDirectoryLabel = uilabel(app.MainControlPanel);
            app.OutputDirectoryLabel.Text = 'Output Directory';
            app.StatusLabel = uilabel(app.MainControlPanel);
            app.StatusLabel.Text = 'Status:';
            app.systemreadyStatus = uilabel(app.MainControlPanel);
            app.systemreadyStatus.Text = 'Corners Needed...';
            app.StartImagingButton = uibutton(app.MainControlPanel, 'push');
            app.StartImagingButton.ButtonPushedFcn = createCallbackFcn(app, @StartImagingButtonPushed, true);
            app.StartImagingButton.Text = 'Start Imaging';
            app.PauseButton = uibutton(app.MainControlPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.QuitButton = uibutton(app.MainControlPanel, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.AquisitionPatternDropDownLabel = uilabel(app.MainControlPanel);
            app.AquisitionPatternDropDownLabel.Text = 'Aquisition Pattern';
            app.AquisitionPatternDropDown = uidropdown(app.MainControlPanel);
            app.AquisitionPatternDropDown.Items = {'snake', 'edge'};
            app.AquisitionPatternDropDown.ValueChangedFcn = createCallbackFcn(app, @AquisitionPatternDropDownValueChanged, true);
            app.AquisitionPatternDropDown.ClickedFcn = createCallbackFcn(app, @AquisitionPatternDropDownClicked, true);
            app.AquisitionPatternDropDown.Value = 'snake';
            app.ChipIDEditFieldLabel = uilabel(app.MainControlPanel);
            app.ChipIDEditFieldLabel.Text = 'Chip ID';
            app.ChipIDEditField = uieditfield(app.MainControlPanel, 'numeric');
            app.ChipIDEditField.ValueDisplayFormat = '%.0f';
            app.MicroscopeControlsPanel = uipanel(app.UIFigure);
            app.MicroscopeControlsPanel.Title = 'Microscope Controls';
            app.MicroscopeMovementLabel = uilabel(app.MicroscopeControlsPanel);
            app.MicroscopeMovementLabel.Text = 'Microscope Movement';
            app.ZuppbuttonScope = uibutton(app.MicroscopeControlsPanel, 'push');
            app.ZuppbuttonScope.ButtonPushedFcn = createCallbackFcn(app, @ZuppbuttonScopeButtonPushed, true);
            app.ZuppbuttonScope.Text = '↑';
            app.ZdownpbuttonScope = uibutton(app.MicroscopeControlsPanel, 'push');
            app.ZdownpbuttonScope.ButtonPushedFcn = createCallbackFcn(app, @ZdownpbuttonScopeButtonPushed, true);
            app.ZdownpbuttonScope.Text = '↓';
            app.PositionofMicroscopemPanel = uipanel(app.MicroscopeControlsPanel);
            app.PositionofMicroscopemPanel.Title = 'Position of Microscope (µm)';
            app.UpdateCurrentPositionButtonScope = uibutton(app.PositionofMicroscopemPanel, 'push');
            app.UpdateCurrentPositionButtonScope.ButtonPushedFcn = createCallbackFcn(app, @UpdateCurrentPositionButtonScopePushed, true);
            app.UpdateCurrentPositionButtonScope.Text = 'Update Current Position';
            app.ZPositionLabelScope = uilabel(app.PositionofMicroscopemPanel);
            app.ZPositionLabelScope.Text = 'Z- Position :';
            app.ZPosition = uieditfield(app.PositionofMicroscopemPanel, 'numeric');
            app.ZStepmSpinnerLabel = uilabel(app.MicroscopeControlsPanel);
            app.ZStepmSpinnerLabel.Text = 'Z  Step (µm)';
            app.ZStepmSpinner = uispinner(app.MicroscopeControlsPanel);
            app.ZStepmSpinner.Limits = [0 Inf];
            app.ZStepmSpinner.Value = 100;
            app.FilterCubeDropDownLabel = uilabel(app.MicroscopeControlsPanel);
            app.FilterCubeDropDownLabel.Text = 'Filter Cube';
            app.FilterCubeDropDown = uidropdown(app.MicroscopeControlsPanel);
            app.FilterCubeDropDown.Items = {'Empty', 'Analyzer', 'CFP-YFP-mCherry (Ex. 440-508-575)', 'DAPI-FITC-CY3-CY5 (Ex. 395-470-555-640)', 'Penta-Cube DO NOT USE', 'Empty'};
            app.FilterCubeDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterCubeDropDownValueChanged, true);
            app.FilterCubeDropDown.Value = 'CFP-YFP-mCherry (Ex. 440-508-575)';
            app.CornersPanel = uipanel(app.UIFigure);
            app.CornersPanel.Title = 'Corners';
            app.ChipPositionsButtonGroup = uibuttongroup(app.CornersPanel);
            app.ChipPositionsButtonGroup.Title = 'Chip Positions ';
            app.topleftCheckBox = uicheckbox(app.ChipPositionsButtonGroup);
            app.topleftCheckBox.Text = 'topleft';
            app.toprightCheckBox = uicheckbox(app.ChipPositionsButtonGroup);
            app.toprightCheckBox.Text = 'topright';
            app.bottomleftCheckBox = uicheckbox(app.ChipPositionsButtonGroup);
            app.bottomleftCheckBox.Text = 'bottomleft';
            app.AdvancedButton = uibutton(app.ChipPositionsButtonGroup, 'push');
            app.AdvancedButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedButtonPushed, true);
            app.AdvancedButton.Text = 'Advanced';
            app.SaveCornerButton = uibutton(app.CornersPanel, 'push');
            app.SaveCornerButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCornerButtonPushed, true);
            app.SaveCornerButton.Text = 'Save Corner';
            app.GotoCornerButton = uibutton(app.CornersPanel, 'push');
            app.GotoCornerButton.ButtonPushedFcn = createCallbackFcn(app, @GotoCornerButtonPushed, true);
            app.GotoCornerButton.Text = 'Go to Corner';
            app.LoadPreviousButton = uibutton(app.CornersPanel, 'push');
            app.LoadPreviousButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPreviousButtonPushed, true);
            app.LoadPreviousButton.Text = 'Load Previous';
            app.CellSortingAppPanel = uipanel(app.UIFigure);
            app.CellSortingAppPanel.Title = 'Cell Sorting App ';
            app.StartSortingButton = uibutton(app.CellSortingAppPanel, 'state');
            app.StartSortingButton.ValueChangedFcn = createCallbackFcn(app, @StartSortingButtonValueChanged, true);
            app.StartSortingButton.Text = 'Start Sorting';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://molonc.atlassian.net/wiki/spaces/SOFT/pages/1983315969/Single+Cell+GUI+and+new+Controls';
            app.Hyperlink.Text = 'HELP!!!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SingleCellGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end