classdef advanced_laser_settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SaveSettingsButton          matlab.ui.control.StateButton
        TabGroup                    matlab.ui.container.TabGroup
        UVTab                       matlab.ui.container.Tab
        NOWAVELENGTHSSELECTEDLabel  matlab.ui.control.Label
        ExposureUV                  matlab.ui.control.NumericEditField
        ExposureEditField_2Label_4  matlab.ui.control.Label
        ImagingInformationLabel_5   matlab.ui.control.Label
        BlueTab                     matlab.ui.container.Tab
        ExposureBlue                matlab.ui.control.NumericEditField
        ExposureEditField_2Label_3  matlab.ui.control.Label
        ImagingInformationLabel_4   matlab.ui.control.Label
        CyanTab                     matlab.ui.container.Tab
        ImagingInformationLabel     matlab.ui.control.Label
        ExposureCyan                matlab.ui.control.NumericEditField
        ExposuresLabel              matlab.ui.control.Label
        TealTab                     matlab.ui.container.Tab
        ExposureTeal                matlab.ui.control.NumericEditField
        ExposureEditField_2Label_2  matlab.ui.control.Label
        ImagingInformationLabel_3   matlab.ui.control.Label
        GreenTab                    matlab.ui.container.Tab
        ExposureEditFieldGreen      matlab.ui.control.NumericEditField
        ExposuresLabel_2            matlab.ui.control.Label
        ImagingInformationLabel_2   matlab.ui.control.Label
        RedTab                      matlab.ui.container.Tab
        ExposureRed                 matlab.ui.control.NumericEditField
        ExposureEditField_2Label_5  matlab.ui.control.Label
        ImagingInformationLabel_6   matlab.ui.control.Label
    end

    
    properties (Access = public)
        mainapp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.NOWAVELENGTHSSELECTEDLabel.Visible = "off";
            movegui(app.UIFigure,'center');
            app.mainapp = mainapp;
            if app.mainapp.CheckRed.Value
                app.TabGroup.SelectedTab = app.RedTab;
            elseif app.mainapp.CheckCyan.Value
                app.TabGroup.SelectedTab = app.CyanTab;
            elseif app.mainapp.CheckTeal.Value
                app.TabGroup.SelectedTab = app.TealTab;
            elseif app.mainapp.CheckGreen.Value
                app.TabGroup.SelectedTab = app.GreenTab;
            elseif app.mainapp.CheckBlue.Value
                app.TabGroup.SelectedTab = app.BlueTab;
            elseif app.mainapp.CheckUV.Value
                app.TabGroup.SelectedTab = app.UVTab;
            end
            if app.mainapp.CheckRed.Value == 0 && app.mainapp.CheckCyan.Value == 0 && app.mainapp.CheckTeal.Value == 0 && app.mainapp.CheckGreen.Value == 0 && app.mainapp.CheckBlue.Value == 0 && app.mainapp.CheckUV.Value == 0
                app.TabGroup.Visible ="off";
                app.NOWAVELENGTHSSELECTEDLabel.Visible = "on";
                disp("Check Selections in the Main App!");
            end
        end

        % Value changed function: SaveSettingsButton
        function SaveSettingsButtonValueChanged(app, event)
            % value = app.SaveSettingsButton.Value;
            app.mainapp.CyanExposure = app.ExposureCyan.Value;
            app.mainapp.GreenExposure = app.ExposureEditFieldGreen.Value;
            delete(app)
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            % Look up the value of the paired checkbox            
            % Each "case" is the title of a Tab and contains the pair checkbox.
            switch selectedTab.Title
                case 'UV'
                    checkboxValue = app.mainapp.CheckUV.Value;
                case 'Blue'
                    checkboxValue = app.mainapp.CheckBlue.Value;
                case 'Green'
                    checkboxValue = app.mainapp.CheckGreen.Value;
                case 'Teal'
                    checkboxValue = app.mainapp.CheckTeal.Value;
                case 'Cyan'
                    checkboxValue = app.mainapp.CheckCyan.Value;
                case 'Red'
                    checkboxValue = app.mainapp.CheckRed.Value;
                otherwise
                    error('Unknown tab: %s.', selectedTab.Title)
            end
            
            % If paired checkbox is checked, return to the previously selected tab
            if ~checkboxValue
                app.TabGroup.SelectedTab = event.OldValue;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.UVTab = uitab(app.TabGroup);
            app.UVTab.Title = 'UV';
            app.ImagingInformationLabel_5 = uilabel(app.UVTab);
            app.ImagingInformationLabel_5.Text = 'Imaging Information';
            app.ExposureEditField_2Label_4 = uilabel(app.UVTab);
            app.ExposureEditField_2Label_4.Text = 'Exposure (s)';
            app.ExposureUV = uieditfield(app.UVTab, 'numeric');
            app.ExposureUV.Value = 1;
            app.NOWAVELENGTHSSELECTEDLabel = uilabel(app.UVTab);
            app.NOWAVELENGTHSSELECTEDLabel.Text = 'NO WAVELENGTHS SELECTED ';
            app.BlueTab = uitab(app.TabGroup);
            app.BlueTab.Title = 'Blue';
            app.ImagingInformationLabel_4 = uilabel(app.BlueTab);
            app.ImagingInformationLabel_4.Text = 'Imaging Information';
            app.ExposureEditField_2Label_3 = uilabel(app.BlueTab);
            app.ExposureEditField_2Label_3.Text = 'Exposure (s)';
            app.ExposureBlue = uieditfield(app.BlueTab, 'numeric');
            app.ExposureBlue.Value = 1;
            app.CyanTab = uitab(app.TabGroup);
            app.CyanTab.Title = 'Cyan';
            app.ExposuresLabel = uilabel(app.CyanTab);
            app.ExposuresLabel.Text = 'Exposure (s)';
            app.ExposureCyan = uieditfield(app.CyanTab, 'numeric');
            app.ExposureCyan.Value = 1;
            app.ImagingInformationLabel = uilabel(app.CyanTab);
            app.ImagingInformationLabel.Text = 'Imaging Information';
            app.TealTab = uitab(app.TabGroup);
            app.TealTab.Title = 'Teal';
            app.ImagingInformationLabel_3 = uilabel(app.TealTab);
            app.ImagingInformationLabel_3.Text = 'Imaging Information';
            app.ExposureEditField_2Label_2 = uilabel(app.TealTab);
            app.ExposureEditField_2Label_2.Text = 'Exposure (s)';
            app.ExposureTeal = uieditfield(app.TealTab, 'numeric');
            app.ExposureTeal.Value = 1;
            app.GreenTab = uitab(app.TabGroup);
            app.GreenTab.Title = 'Green';
            app.ImagingInformationLabel_2 = uilabel(app.GreenTab);
            app.ImagingInformationLabel_2.Text = 'Imaging Information';
            app.ExposuresLabel_2 = uilabel(app.GreenTab);
            app.ExposuresLabel_2.Text = 'Exposure (s)';
            app.ExposureEditFieldGreen = uieditfield(app.GreenTab, 'numeric');
            app.ExposureEditFieldGreen.Value = 1;
            app.RedTab = uitab(app.TabGroup);
            app.RedTab.Title = 'Red';
            app.ImagingInformationLabel_6 = uilabel(app.RedTab);
            app.ImagingInformationLabel_6.Text = 'Imaging Information';
            app.ExposureEditField_2Label_5 = uilabel(app.RedTab);
            app.ExposureEditField_2Label_5.Text = 'Exposure (s)';
            app.ExposureRed = uieditfield(app.RedTab, 'numeric');
            app.ExposureRed.Value = 1;
            app.SaveSettingsButton = uibutton(app.UIFigure, 'state');
            app.SaveSettingsButton.ValueChangedFcn = createCallbackFcn(app, @SaveSettingsButtonValueChanged, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = advanced_laser_settings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end