classdef SingleCellGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CornersPanel                  matlab.ui.container.Panel
        LoadPreviousButton            matlab.ui.control.Button
        GotoCornerButton              matlab.ui.control.Button
        SaveCornerButton              matlab.ui.control.Button
        ChipPositionsButtonGroup      matlab.ui.container.ButtonGroup
        bottomleftCheckBox            matlab.ui.control.CheckBox
        toprightCheckBox              matlab.ui.control.CheckBox
        topleftCheckBox               matlab.ui.control.CheckBox
        MicroscopeControlsPanel       matlab.ui.container.Panel
        ZStepSpinner                  matlab.ui.control.Spinner
        ZStepSpinnerLabel             matlab.ui.control.Label
        PositionofMicroscopePanel     matlab.ui.container.Panel
        ZPositionLabelScope           matlab.ui.control.Label
        UpdateCurrentPositionButtonScope  matlab.ui.control.Button
        ZdownpbuttonScope             matlab.ui.control.Button
        ZuppbuttonScope               matlab.ui.control.Button
        MicroscopeMovementLabel       matlab.ui.control.Label
        MainControlPanel              matlab.ui.container.Panel
        ChipIDEditField               matlab.ui.control.EditField
        ChipIDEditFieldLabel          matlab.ui.control.Label
        QuitButton                    matlab.ui.control.Button
        PauseButton                   matlab.ui.control.Button
        StartImagingButton            matlab.ui.control.Button
        systemreadyStatus             matlab.ui.control.Label
        StatusLabel                   matlab.ui.control.Label
        OutputDirectoryLabel          matlab.ui.control.Label
        BrowseButton                  matlab.ui.control.Button
        CameraControlPanel            matlab.ui.container.Panel
        CloseLiveViewButton           matlab.ui.control.StateButton
        GainDropDown                  matlab.ui.control.DropDown
        GainDropDownLabel             matlab.ui.control.Label
        BinningDropDown               matlab.ui.control.DropDown
        BinningDropDownLabel          matlab.ui.control.Label
        OpenLiveViewButton            matlab.ui.control.StateButton
        ExposureTimemsEditField       matlab.ui.control.NumericEditField
        ExposureTimemsEditFieldLabel  matlab.ui.control.Label
        StageControlsPanel            matlab.ui.container.Panel
        PositionofStagemPanel         matlab.ui.container.Panel
        ZPositionLabel                matlab.ui.control.Label
        YPositionLabel                matlab.ui.control.Label
        XPositionLabel                matlab.ui.control.Label
        UpdateCurrentPositionButton   matlab.ui.control.Button
        Zdownpbutton                  matlab.ui.control.Button
        Zuppbutton                    matlab.ui.control.Button
        XYStepmSpinner                matlab.ui.control.Spinner
        XYStepmSpinnerLabel           matlab.ui.control.Label
        ZStepmSpinner                 matlab.ui.control.Spinner
        ZStepmSpinnerLabel            matlab.ui.control.Label
        ZStageMovementLabel           matlab.ui.control.Label
        XYPlaneStageMovementLabel     matlab.ui.control.Label
        XYLeftbutton                  matlab.ui.control.Button
        XYRightbutton                 matlab.ui.control.Button
        XYDownbutton                  matlab.ui.control.Button
        XYUpbutton                    matlab.ui.control.Button
        LaserControlPanel             matlab.ui.container.Panel
        AcquireLabel                  matlab.ui.control.Label
        CheckGreen                    matlab.ui.control.CheckBox
        CheckCyan                     matlab.ui.control.CheckBox
        CheckBlue                     matlab.ui.control.CheckBox
        GREENEditField                matlab.ui.control.NumericEditField
        GREENEditFieldLabel           matlab.ui.control.Label
        CYANEditField                 matlab.ui.control.NumericEditField
        CYANEditFieldLabel            matlab.ui.control.Label
        BLUEEditField                 matlab.ui.control.NumericEditField
        BLUEEditFieldLabel            matlab.ui.control.Label
        GreenLabel                    matlab.ui.control.Label
        CyanLabel                     matlab.ui.control.Label
        BLUELabel                     matlab.ui.control.Label
        PowerLabel                    matlab.ui.control.Label
        OFFButtonGreen                matlab.ui.control.StateButton
        OFFButtonCyan                 matlab.ui.control.StateButton
        OFFButtonBlue                 matlab.ui.control.StateButton
        ONOFFLabel                    matlab.ui.control.Label
        ConnectionPanel               matlab.ui.container.Panel
        DisconnectButtonCamera        matlab.ui.control.Button
        ConnecttoCameraButton         matlab.ui.control.StateButton
        cameraconnectionLabel         matlab.ui.control.Label
        CameraStatusLabel             matlab.ui.control.Label
        DisconnectButtonLaser         matlab.ui.control.Button
        DisconnectButtonStage         matlab.ui.control.Button
        DisconnectButtonMicroscope    matlab.ui.control.Button
        ConnecttoLaserButton          matlab.ui.control.StateButton
        laserconnectionLabel          matlab.ui.control.Label
        LaserStatusLabel              matlab.ui.control.Label
        ConnecttoStageButton          matlab.ui.control.StateButton
        stageconnectionLabel          matlab.ui.control.Label
        StageStatusLabel              matlab.ui.control.Label
        MicroscopecoonectionLabel     matlab.ui.control.Label
        MicroscopeStatusLabel         matlab.ui.control.Label
        ConnecttoMicroscopeButton     matlab.ui.control.Button
    end

    properties (Access = public) %these are the global variables that can be accessed through the app structure
       % Description
       ti = 'None'; %microscope
       vidobj = 'None'; %camera 
       stage = 'None';
       StagePosition = [0, 0, 0]; % Initial Position as [X,Y,Z]
       ScopePosition = 0; 
       stageSpeed = 3; % this is just a general speed the maximum is 7.5 but I wouldnt mess around with it to much
       laser = 'None'; 

       %these variables are what the corner positions will be stored in
       topleft = [0,0,0]; 
       topright = [0,0,0];
       bottomleft = [0,0,0];
       %this is thescope's Z position I think it should be better to have
       %the stage store all the XYZ information and then the scope to just
       %have Z
       tl_scope = 0;
       tr_scope = 0;
       bl_scope = 0;
       
       %this is to store the tile we want to image during aquisition 
       imagingtile = [0,0,0];
       
       PauseAcquisition = false;

       %Saving Info
       dname = '';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %run('init.m'); %this calls a function that appends all the
            
            %CHANGING THE VISIBILITY OF PANELS
            %app.StageControlsPanel.Visible = 'off';
            %app.LaserControlPanel.Visible = 'off';
            %app.CameraControlPanel.Visible = 'off'; 
            %app.MainControlPanel.Visible = 'off'; 
            %app.MicroscopeControlsPanel.Visible = 'off';
            %app.CornersPanel.Visible = 'off';
            movegui(app.UIFigure, "center");

            %file paths of the matlab Callback functions 
            addpath(fullfile(pwd, 'acquisition'));
            addpath(fullfile(pwd, 'connection'));
            addpath(fullfile(pwd, 'stage'));
            addpath(fullfile(pwd, 'lasers'));

        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: OpenLiveViewButton
        function OpenLiveViewButtonValueChanged(app, event)

            src = getselectedsource(app.vidobj);
            src.ExposureTimeControl = 'normal';
            src.ExposureTime = (app.ExposureTimemsEditField.Value)/100;% to get from ms to seconds since exposure time is in seconds 
            preview(app.vidobj); % comment this out when we try to test the squares drawn on 
            
            % Get the live image
            
            % Get the selected source and set ExposureTimeControl

            % src = getselectedsource(app.vidobj);
            % src.ExposureTimeControl = 'normal';
            % 
            % % Create a figure with UIAxes to display the live image
            % hFigure = uifigure('Name', 'Live Image with Squares');
            % hAxes = uiaxes('Parent', hFigure, 'Position', [10, 10, 400, 300], 'Tag', 'UIAxes1');
            % 
            % % Continuous update loop
            % while ishandle(hFigure)
            %     % Get the live image
            %     liveImage = getsnapshot(app.vidobj);
            %     src.ExposureTime = (app.ExposureTimemsEditField.Value) / 100; % Convert from ms to seconds
            % 
            %     % Add squares to indicate cell alignment
            %     [rows, cols, ~] = size(liveImage);
            %     portionRows = rows / 3;
            %     portionCols = cols / 2;
            % 
            %     for i = 1:3
            %         for j = 1:2
            %             % Calculate square coordinates
            %             startRowSquare = (i - 1) * portionRows + 1;
            %             endRowSquare = i * portionRows;
            %             startColSquare = (j - 1) * portionCols + 1;
            %             endColSquare = j * portionCols;
            % 
            %             % Draw a square on the live image using rectangle
            %             rectangle(hAxes, 'Position', [startColSquare, startRowSquare, portionCols, portionRows], 'EdgeColor', 'r', 'LineWidth', 2);
            %         end
            %     end
            % 
            %     % Display the live image with squares
            %     imshow(liveImage, 'Parent', hAxes);
            % 
            %     % Optional: Pause to control the update rate
            %     pause(0.1); % Adjust as needed
            % end
            % 
            % % Close the live preview when the loop breaks
            % closepreview(app.vidobj);


        end

        % Button pushed function: ConnecttoMicroscopeButton
        function ConnecttoMicroscopeButtonPushed(app, event)
            app.MicroscopecoonectionLabel.FontColor = "#EDB120";
            app.MicroscopecoonectionLabel.Text = "Connecting..." ;
            pause(2);
            connect2scope(app); 
            UpdateCurrentPositionButtonScopePushed(app); 
            %microscope = actxserver('Nikon.Ti.Microscope');
            %code to connect to the scope 
            %
            %
            %
            %app.ti = microscope; 
            app.MicroscopeControlsPanel.Visible = 'on';
            checkconnectionStatus(app);


        end

        % Value changed function: ConnecttoStageButton
        function ConnecttoStageButtonValueChanged(app, event)
            %value = app.ConnecttoStageButton.Value;
            app.stageconnectionLabel.FontColor = "#EDB120";
            app.stageconnectionLabel.Text = "Connecting..." ;
            connect2stage(app,getConfig('STAGE_PORT')); % not completed maybe just add the comport and test to see if that works
            pause(4)
            %
            %
            %
            %
            
            getCurrentPosition(app);
            %add callback to update the postions maybe after turning the
            %visibility on
            app.StageControlsPanel.Visible = 'on';
            app.CornersPanel.Visible = 'on'; 
            UpdateCurrentPositionButtonPushed(app);
            checkconnectionStatus(app);
            setSpeed(app); 


        end

        % Value changed function: ConnecttoLaserButton
        function ConnecttoLaserButtonValueChanged(app, event)
            %value = app.ConnecttoLaserButton.Value;
            app.laserconnectionLabel.FontColor = "#EDB120";
            app.laserconnectionLabel.Text = "Connecting..." ;
            connect2lasers(app);
            %
            %
            %
            %
            app.LaserControlPanel.Visible = 'on';
            checkconnectionStatus(app);
            
        end

        % Value changed function: ConnecttoCameraButton
        function ConnecttoCameraButtonValueChanged(app, event)
            %value = app.ConnecttoCameraButton.Value;
            app.cameraconnectionLabel.FontColor = "#EDB120";
            app.cameraconnectionLabel.Text = "Connecting..." ;
            pause(2);
            connect2camera(app); % to debug use imaqhwinfo which lists the available image aqcuisition devices 
            %
            %
            %
            %
            app.CameraControlPanel.Visible = 'on'; 
            app.MainControlPanel.Visible = 'on'; %this is here because I am not to sure when else we should enable this window to be accesed 
            checkconnectionStatus(app);
            
        end

        % Button pushed function: DisconnectButtonMicroscope
        function DisconnectButtonMicroscopePushed(app, event)
            %for this one add the disconection function call 
            disconnectFromeScope(app);
        end

        % Button pushed function: DisconnectButtonStage
        function DisconnectButtonStagePushed(app, event)
            % add the disconnect function call 
            pause(2);
            disconnectFromStage(app);

        end

        % Button pushed function: DisconnectButtonLaser
        function DisconnectButtonLaserPushed(app, event)
            %add the disconnect function call 
            disconnectFromLaser(app);

        end

        % Button pushed function: DisconnectButtonCamera
        function DisconnectButtonCameraPushed(app, event)
            %add the disconnect function call 
            pause(2);
            disconnectFromCamera(app); 
        end

        % Value changed function: CloseLiveViewButton
        function CloseLiveViewButtonValueChanged(app, event)
            closepreview(app.vidobj);

            % Simply close the figure to break the loop in the OpenLiveViewButton function
            %close(hfigure);
            
        end

        % Button pushed function: XYLeftbutton
        function XYLeftbuttonButtonPushed(app, event)
            %one thing I am uncertain about is if the distance passed is
            %how much it moves from its current postion or where to move to
            
            %tenths of microns is what you are moving the stage hense why
            %we have a step size of 10 on the spinner 

            moveStageX(app,-1*(app.XYStepmSpinner.Value)); %left isnegative in this referenc frame
            getCurrentPosition(app);
            app.XPositionLabel.Text = sprintf('X-Position : %.2f ', app.StagePosition(1));

        end

        % Button pushed function: XYRightbutton
        function XYRightbuttonButtonPushed(app, event)
            moveStageX(app,(app.XYStepmSpinner.Value)); % right is positive 

            %tenths of microns is what you are moving the stage hense why
            %we have a step size of 10 on the spinner 

            getCurrentPosition(app);
            app.XPositionLabel.Text = sprintf('X-Position : %.2f ', app.StagePosition(1));
        end

        % Button pushed function: XYDownbutton
        function XYDownbuttonButtonPushed(app, event)
            %tenths of microns is what you are moving the stage hense why
            %we have a step size of 10 on the spinner 
            moveStageY(app,-1*(app.XYStepmSpinner.Value));
            getCurrentPosition(app);
            app.YPositionLabel.Text = sprintf('Y-Position: %.2f ', app.StagePosition(2));
        end

        % Button pushed function: XYUpbutton
        function XYUpbuttonButtonPushed(app, event)
            %tenths of microns is what you are moving the stage hense why
            %we have a step size of 10 on the spinner 
            moveStageY(app,(app.XYStepmSpinner.Value));
            getCurrentPosition(app);
            app.YPositionLabel.Text = sprintf('Y-Position: %.2f ', app.StagePosition(2));
        end

        % Button pushed function: Zuppbutton
        function ZuppbuttonButtonPushed(app, event)
            %tenths of microns is what you are moving the stage hense why
            %we have a step size of 10 on the spinner 
            moveStageZ(app,(app.ZStepmSpinner.Value));
            getCurrentPosition(app);
            app.ZPositionLabel.Text = sprintf('Z-Position : %.2f ', app.StagePosition(3));
        end

        % Button pushed function: Zdownpbutton
        function ZdownpbuttonButtonPushed(app, event)
            %tenths of microns is what you are moving the stage hense why
            %we have a step size of 10 on the spinner 
            moveStageZ(app,-1*(app.ZStepmSpinner.Value));
            getCurrentPosition(app);
            app.ZPositionLabel.Text = sprintf('Z-Position : %.2f ', app.StagePosition(3));
        end

        % Button pushed function: SaveCornerButton
        function SaveCornerButtonPushed(app, event)
            %First we need to make sure that one one check box is clicked 
            if app.bottomleftCheckBox.Value == 1 && app.toprightCheckBox.Value == 0 && app.topleftCheckBox.Value == 0
                % Bottom Left is selected
                getCurrentPosition(app);
                app.bottomleft = app.StagePosition;
                app.bl_scope = app.ScopePosition;
                setConfig('bottomleft',app.bottomleft);
                cornerselectionStatus(app);
            elseif app.toprightCheckBox.Value == 1 && app.bottomleftCheckBox.Value == 0 && app.topleftCheckBox.Value == 0
                % Top Right is selected
                getCurrentPosition(app);
                app.topright = app.StagePosition;
                app.tr_scope = app.ScopePosition;
                setConfig('topright',app.topright);
                cornerselectionStatus(app);            
            elseif app.topleftCheckBox.Value == 1 && app.bottomleftCheckBox.Value == 0 && app.toprightCheckBox.Value == 0
                % Top Left is selected
                getCurrentPosition(app);
                app.topleft = app.StagePosition;
                app.tl_scope = app.ScopePosition;
                setConfig('topleft',app.topleft);
                cornerselectionStatus(app);
            else
                disp("Check your Corner Selection!");
            end

        end

        % Button pushed function: GotoCornerButton
        function GotoCornerButtonPushed(app, event)
            %First we need to make sure that one one check box is clicked 
            if app.bottomleftCheckBox.Value == 1 && app.toprightCheckBox.Value == 0 && app.topleftCheckBox.Value == 0
                % Bottom Left is selected
                moveStage(app,app.bottomleft);
                moveScopeAbsZ(app,app.bl_scope);
                UpdateCurrentPositionButtonPushed(app);
            elseif app.toprightCheckBox.Value == 1 && app.bottomleftCheckBox.Value == 0 && app.topleftCheckBox.Value == 0
                % Top Right is selected
                moveStage(app,app.topright);
                moveScopeAbsZ(app,app.tr_scope);
                UpdateCurrentPositionButtonPushed(app);
            elseif app.topleftCheckBox.Value == 1 && app.bottomleftCheckBox.Value == 0 && app.toprightCheckBox.Value == 0
                % Top Left is selected
                moveStage(app,app.topleft);
                moveScopeAbsZ(app,app.tl_scope);
                UpdateCurrentPositionButtonPushed(app);
            else
                disp("Check your Corner Selection!");
            end
        end

        % Button pushed function: UpdateCurrentPositionButton
        function UpdateCurrentPositionButtonPushed(app, event)
            getCurrentPosition(app);
            app.XPositionLabel.Text = sprintf('X-Position : %.2f ', app.StagePosition(1));
            app.YPositionLabel.Text = sprintf('Y-Position: %.2f ', app.StagePosition(2));
            app.ZPositionLabel.Text = sprintf('Z-Position : %.2f ', app.StagePosition(3));
        end

        % Value changed function: OFFButtonBlue
        function OFFButtonBlueValueChanged(app, event)
            if app.OFFButtonBlue.Text == "OFF"
                app.OFFButtonBlue.Text = "ON";
                blueOnCmd = sscanf('4F 5F 50', '%2X');% hex values for serial communication
                fwrite(app.laser, blueOnCmd, 'uint8');
            else 
                app.OFFButtonBlue.Text = "OFF";
                disableAll(app);
            end
        end

        % Value changed function: OFFButtonCyan
        function OFFButtonCyanValueChanged(app, event)
          if app.OFFButtonCyan.Text == "OFF"
                app.OFFButtonCyan.Text = "ON";
                cyanOnCmd = sscanf('4F 7B 50', '%2X'); % hex values for serial communication
                fwrite(app.laser, cyanOnCmd, 'uint8');
          else 
                app.OFFButtonCyan.Text = "OFF";
                disableAll(app);
          end 
            
        end

        % Value changed function: OFFButtonGreen
        function OFFButtonGreenValueChanged(app, event)
          if app.OFFButtonGreen.Text == "OFF"
                app.OFFButtonGreen.Text = "ON";
                greenOnCmd = sscanf('4F 7D 50', '%2X'); % hex values for serial communication
                fwrite(app.laser, greenOnCmd, 'uint8');
          else 
                app.OFFButtonGreen.Text = "OFF";
                disableAll(app);
          end 
            
            
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            % Toggle between pausing and unpausing the acquisition
            app.PauseAcquisition = ~app.PauseAcquisition;
        
            % Update the button text based on the current pause state
            if app.PauseAcquisition
                app.PauseButton.Text = 'Unpause'; % Change text to "Unpause"
            else
                app.PauseButton.Text = 'Pause'; % Change text to "Pause"
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.dname = uigetdir;
            disp(app.dname)
            app.BrowseButton.Text = app.dname;
            app.BrowseButton.FontSize = 10;
            app.BrowseButton = [0,0,1];
        end

        % Value changed function: BLUEEditField
        function BLUEEditFieldValueChanged(app, event)
            value = app.BLUEEditField.Value;
            bluePowerDec = value;
            bluePowerHex = dec2hex(255-bluePowerDec, 2); %convert intensity to a hex value
            powerBlue = sscanf(['53 1A 03 01 F', bluePowerHex, '0 50'], '%2X');
            fwrite(app.laser, powerBlue, 'uint8');
        end

        % Value changed function: CYANEditField
        function CYANEditFieldValueChanged(app, event)
            value = app.CYANEditField.Value;
            cyanPowerDec = value;
            cyanPowerHex = dec2hex(255-cyanPowerDec, 2);
            powerCyanCmd = sscanf(['53 18 03 02 F', cyanPowerHex, '0 50'], '%2X');
            fwrite(app.laser, powerCyanCmd, 'uint8');
            
        end

        % Value changed function: GREENEditField
        function GREENEditFieldValueChanged(app, event)
            value = app.GREENEditField.Value;
            greenPowerDec = value;
            greenPowerHex = dec2hex(255-greenPowerDec, 2);
            powerGreenCmd = sscanf(['53 18 03 04 F', greenPowerHex, '0 50'], '%2X');
            fwrite(app.laser, powerGreenCmd, 'uint8'); 
        end

        % Button pushed function: StartImagingButton
        function StartImagingButtonPushed(app, event)
            % need to have a check status to make sure that aquisiton can
            % be run. Current idea is to make a tally and once the tally
            % hits a certan point the status updates to ready to run or
            % something like that since text values cannot have callbacks
            % themselves 
            
            CloseLiveViewButtonValueChanged(app); %closes live view if it not already closed
             % Save the current tile positions to the MAT file
            tl = app.topleft;
            tr = app.topright;
            bl = app.bottomleft;
            tls = app.tl_scope;
            trs = app.tr_scope;
            bls = app.bl_scope;
        
            save('corner_coordinates.mat', 'tl', 'tr', 'bl', 'tls', 'trs', 'bls');
            %this is my current thinking on how to do the main 
            aquisition(app); 
        end

        % Button pushed function: UpdateCurrentPositionButtonScope
        function UpdateCurrentPositionButtonScopePushed(app, event)
            getCurrentPostion(app);
            app.ZPositionLabelScope.Text = sprintf('Z-Position: %.2f ', app.ScopePosition);
        end

        % Button pushed function: ZuppbuttonScope
        function ZuppbuttonScopeButtonPushed(app, event)
            moveScopeZ(app, app.ZStepSpinner.Value);
            UpdateCurrentPositionButtonScopePushed(app);
        end

        % Button pushed function: ZdownpbuttonScope
        function ZdownpbuttonScopeButtonPushed(app, event)
            moveScopeZ(app, -1*app.ZStepSpinner.Value);
            UpdateCurrentPositionButtonScopePushed(app);
        end

        % Button pushed function: LoadPreviousButton
        function LoadPreviousButtonPushed(app, event)
            if exist('corner_coordinates.mat', 'file') == 2
                loadedData = load('corner_coordinates.mat');
                % Check if the file is not empty
                if ~isempty(fieldnames(loadedData))
                    % Access variables from the loaded structure
                    app.topleft = loadedData.tl;
                    app.topright = loadedData.tr;
                    app.bottomleft = loadedData.bl;
                    app.tl_scope = loadedData.tls;
                    app.tr_scope = loadedData.trs;
                    app.bl_scope = loadedData.bls;
                else
                    disp('MAT file is empty.');
                end
            else
                disp('MAT file does not exist.');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnectionPanel = uipanel(app.UIFigure);
            app.ConnectionPanel.Title = 'Connection';
            app.ConnecttoMicroscopeButton = uibutton(app.ConnectionPanel, 'push');
            app.ConnecttoMicroscopeButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoMicroscopeButtonPushed, true);
            app.ConnecttoMicroscopeButton.Text = 'Connect to Microscope';
            app.MicroscopeStatusLabel = uilabel(app.ConnectionPanel);
            app.MicroscopeStatusLabel.Text = 'Microscope Status: ';
            app.MicroscopecoonectionLabel = uilabel(app.ConnectionPanel);
            app.MicroscopecoonectionLabel.Text = 'Not Connected';
            app.StageStatusLabel = uilabel(app.ConnectionPanel);
            app.StageStatusLabel.Text = 'Stage Status: ';
            app.stageconnectionLabel = uilabel(app.ConnectionPanel);
            app.stageconnectionLabel.Text = 'Not Connected';
            app.ConnecttoStageButton = uibutton(app.ConnectionPanel, 'state');
            app.ConnecttoStageButton.ValueChangedFcn = createCallbackFcn(app, @ConnecttoStageButtonValueChanged, true);
            app.ConnecttoStageButton.Text = 'Connect to Stage';
            app.LaserStatusLabel = uilabel(app.ConnectionPanel);
            app.LaserStatusLabel.Text = 'Laser Status: ';
            app.laserconnectionLabel = uilabel(app.ConnectionPanel);
            app.laserconnectionLabel.Text = 'Not Connected';
            app.ConnecttoLaserButton = uibutton(app.ConnectionPanel, 'state');
            app.ConnecttoLaserButton.ValueChangedFcn = createCallbackFcn(app, @ConnecttoLaserButtonValueChanged, true);
            app.ConnecttoLaserButton.Text = 'Connect to Laser';
            app.DisconnectButtonMicroscope = uibutton(app.ConnectionPanel, 'push');
            app.DisconnectButtonMicroscope.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonMicroscopePushed, true);
            app.DisconnectButtonMicroscope.Text = 'Disconnect';
            app.DisconnectButtonStage = uibutton(app.ConnectionPanel, 'push');
            app.DisconnectButtonStage.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonStagePushed, true);
            app.DisconnectButtonStage.Text = 'Disconnect';
            app.DisconnectButtonLaser = uibutton(app.ConnectionPanel, 'push');
            app.DisconnectButtonLaser.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonLaserPushed, true);
            app.DisconnectButtonLaser.Text = 'Disconnect';
            app.CameraStatusLabel = uilabel(app.ConnectionPanel);
            app.CameraStatusLabel.Text = 'Camera Status: ';
            app.cameraconnectionLabel = uilabel(app.ConnectionPanel);
            app.cameraconnectionLabel.Text = 'Not Connected';
            app.ConnecttoCameraButton = uibutton(app.ConnectionPanel, 'state');
            app.ConnecttoCameraButton.ValueChangedFcn = createCallbackFcn(app, @ConnecttoCameraButtonValueChanged, true);
            app.ConnecttoCameraButton.Text = 'Connect to Camera';
            app.DisconnectButtonCamera = uibutton(app.ConnectionPanel, 'push');
            app.DisconnectButtonCamera.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonCameraPushed, true);
            app.DisconnectButtonCamera.Text = 'Disconnect';
            app.LaserControlPanel = uipanel(app.UIFigure);
            app.LaserControlPanel.Title = 'Laser Control ';
            app.ONOFFLabel = uilabel(app.LaserControlPanel);
            app.ONOFFLabel.Text = 'ON/OFF';
            app.OFFButtonBlue = uibutton(app.LaserControlPanel, 'state');
            app.OFFButtonBlue.ValueChangedFcn = createCallbackFcn(app, @OFFButtonBlueValueChanged, true);
            app.OFFButtonBlue.Text = 'OFF';
            app.OFFButtonCyan = uibutton(app.LaserControlPanel, 'state');
            app.OFFButtonCyan.ValueChangedFcn = createCallbackFcn(app, @OFFButtonCyanValueChanged, true);
            app.OFFButtonCyan.Text = 'OFF';
            app.OFFButtonGreen = uibutton(app.LaserControlPanel, 'state');
            app.OFFButtonGreen.ValueChangedFcn = createCallbackFcn(app, @OFFButtonGreenValueChanged, true);
            app.OFFButtonGreen.Text = 'OFF';
            app.PowerLabel = uilabel(app.LaserControlPanel);
            app.PowerLabel.Text = 'Power (%)';
            app.BLUELabel = uilabel(app.LaserControlPanel);
            app.BLUELabel.Text = 'BLUE';
            app.CyanLabel = uilabel(app.LaserControlPanel);
            app.CyanLabel.Text = 'Cyan';
            app.GreenLabel = uilabel(app.LaserControlPanel);
            app.GreenLabel.Text = 'Green';
            app.BLUEEditFieldLabel = uilabel(app.LaserControlPanel);
            app.BLUEEditFieldLabel.Text = 'BLUE';
            app.BLUEEditField = uieditfield(app.LaserControlPanel, 'numeric');
            app.BLUEEditField.ValueChangedFcn = createCallbackFcn(app, @BLUEEditFieldValueChanged, true);
            app.CYANEditFieldLabel = uilabel(app.LaserControlPanel);
            app.CYANEditFieldLabel.Text = 'CYAN';
            app.CYANEditField = uieditfield(app.LaserControlPanel, 'numeric');
            app.CYANEditField.ValueChangedFcn = createCallbackFcn(app, @CYANEditFieldValueChanged, true);
            app.GREENEditFieldLabel = uilabel(app.LaserControlPanel);
            app.GREENEditFieldLabel.Text = 'GREEN';
            app.GREENEditField = uieditfield(app.LaserControlPanel, 'numeric');
            app.GREENEditField.ValueChangedFcn = createCallbackFcn(app, @GREENEditFieldValueChanged, true);
            app.CheckBlue = uicheckbox(app.LaserControlPanel);
            app.CheckBlue.Text = '';
            app.CheckCyan = uicheckbox(app.LaserControlPanel);
            app.CheckCyan.Text = '';
            app.CheckGreen = uicheckbox(app.LaserControlPanel);
            app.CheckGreen.Text = '';
            app.AcquireLabel = uilabel(app.LaserControlPanel);
            app.AcquireLabel.Text = 'Acquire: ';
            app.StageControlsPanel = uipanel(app.UIFigure);
            app.StageControlsPanel.Title = 'Stage Controls';
            app.XYUpbutton = uibutton(app.StageControlsPanel, 'push');
            app.XYUpbutton.ButtonPushedFcn = createCallbackFcn(app, @XYUpbuttonButtonPushed, true);
            app.XYUpbutton.Text = '↑';
            app.XYDownbutton = uibutton(app.StageControlsPanel, 'push');
            app.XYDownbutton.ButtonPushedFcn = createCallbackFcn(app, @XYDownbuttonButtonPushed, true);
            app.XYDownbutton.Text = '↓';
            app.XYRightbutton = uibutton(app.StageControlsPanel, 'push');
            app.XYRightbutton.ButtonPushedFcn = createCallbackFcn(app, @XYRightbuttonButtonPushed, true);
            app.XYRightbutton.Text = '→';
            app.XYLeftbutton = uibutton(app.StageControlsPanel, 'push');
            app.XYLeftbutton.ButtonPushedFcn = createCallbackFcn(app, @XYLeftbuttonButtonPushed, true);
            app.XYLeftbutton.Text = '←';
            app.XYPlaneStageMovementLabel = uilabel(app.StageControlsPanel);
            app.XYPlaneStageMovementLabel.Text = 'XY Plane Stage Movement';
            app.ZStageMovementLabel = uilabel(app.StageControlsPanel);
            app.ZStageMovementLabel.Text = 'Z Stage Movement';
            app.ZStepmSpinnerLabel = uilabel(app.StageControlsPanel);
            app.ZStepmSpinnerLabel.Text = 'Z Step (µm)';
            app.ZStepmSpinner = uispinner(app.StageControlsPanel);
            app.ZStepmSpinner.Step = 10;
            app.ZStepmSpinner.Value = 10;
            app.XYStepmSpinnerLabel = uilabel(app.StageControlsPanel);
            app.XYStepmSpinnerLabel.Text = 'XY Step (µm)';
            app.XYStepmSpinner = uispinner(app.StageControlsPanel);
            app.XYStepmSpinner.Step = 10;
            app.XYStepmSpinner.Value = 10;
            app.Zuppbutton = uibutton(app.StageControlsPanel, 'push');
            app.Zuppbutton.ButtonPushedFcn = createCallbackFcn(app, @ZuppbuttonButtonPushed, true);
            app.Zuppbutton.Text = '↑';
            app.Zdownpbutton = uibutton(app.StageControlsPanel, 'push');
            app.Zdownpbutton.ButtonPushedFcn = createCallbackFcn(app, @ZdownpbuttonButtonPushed, true);
            app.Zdownpbutton.Text = '↓';
            app.PositionofStagemPanel = uipanel(app.StageControlsPanel);
            app.PositionofStagemPanel.Title = 'Position of Stage (µm)';
            app.UpdateCurrentPositionButton = uibutton(app.PositionofStagemPanel, 'push');
            app.UpdateCurrentPositionButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateCurrentPositionButtonPushed, true);
            app.UpdateCurrentPositionButton.Text = 'Update Current Position';
            app.XPositionLabel = uilabel(app.PositionofStagemPanel);
            app.XPositionLabel.Text = 'X- Position :';
            app.YPositionLabel = uilabel(app.PositionofStagemPanel);
            app.YPositionLabel.Text = 'Y- Position :';
            app.ZPositionLabel = uilabel(app.PositionofStagemPanel);
            app.ZPositionLabel.Text = 'Z- Position :';
            app.CameraControlPanel = uipanel(app.UIFigure);
            app.CameraControlPanel.Title = 'Camera Control';
            app.ExposureTimemsEditFieldLabel = uilabel(app.CameraControlPanel);
            app.ExposureTimemsEditFieldLabel.Text = 'Exposure Time (ms)';
            app.ExposureTimemsEditField = uieditfield(app.CameraControlPanel, 'numeric');
            app.ExposureTimemsEditField.Value = 1;
            app.OpenLiveViewButton = uibutton(app.CameraControlPanel, 'state');
            app.OpenLiveViewButton.ValueChangedFcn = createCallbackFcn(app, @OpenLiveViewButtonValueChanged, true);
            app.OpenLiveViewButton.Text = 'Open Live View ';
            app.BinningDropDownLabel = uilabel(app.CameraControlPanel);
            app.BinningDropDownLabel.Text = 'Binning';
            app.BinningDropDown = uidropdown(app.CameraControlPanel);
            app.BinningDropDown.Items = {'1x1', 'TBD'};
            app.BinningDropDown.Value = '1x1';
            app.GainDropDownLabel = uilabel(app.CameraControlPanel);
            app.GainDropDownLabel.Text = 'Gain';
            app.GainDropDown = uidropdown(app.CameraControlPanel);
            app.GainDropDown.Items = {'0', 'TBD'};
            app.GainDropDown.Value = '0';
            app.CloseLiveViewButton = uibutton(app.CameraControlPanel, 'state');
            app.CloseLiveViewButton.ValueChangedFcn = createCallbackFcn(app, @CloseLiveViewButtonValueChanged, true);
            app.CloseLiveViewButton.Text = 'Close Live View ';
            app.MainControlPanel = uipanel(app.UIFigure);
            app.MainControlPanel.Title = 'Main Control';
            app.BrowseButton = uibutton(app.MainControlPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.OutputDirectoryLabel = uilabel(app.MainControlPanel);
            app.OutputDirectoryLabel.Text = 'Output Directory';
            app.StatusLabel = uilabel(app.MainControlPanel);
            app.StatusLabel.Text = 'Status:';
            app.systemreadyStatus = uilabel(app.MainControlPanel);
            app.systemreadyStatus.Text = 'Waiting for instruments to connect ...';
            app.StartImagingButton = uibutton(app.MainControlPanel, 'push');
            app.StartImagingButton.ButtonPushedFcn = createCallbackFcn(app, @StartImagingButtonPushed, true);
            app.StartImagingButton.Text = 'Start Imaging';
            app.PauseButton = uibutton(app.MainControlPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.QuitButton = uibutton(app.MainControlPanel, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.ChipIDEditFieldLabel = uilabel(app.MainControlPanel);
            app.ChipIDEditFieldLabel.Text = 'Chip ID';
            app.ChipIDEditField = uieditfield(app.MainControlPanel, 'text');
            app.MicroscopeControlsPanel = uipanel(app.UIFigure);
            app.MicroscopeControlsPanel.Title = 'Microscope Controls';
            app.MicroscopeMovementLabel = uilabel(app.MicroscopeControlsPanel);
            app.MicroscopeMovementLabel.Text = 'Microscope Movement';
            app.ZuppbuttonScope = uibutton(app.MicroscopeControlsPanel, 'push');
            app.ZuppbuttonScope.ButtonPushedFcn = createCallbackFcn(app, @ZuppbuttonScopeButtonPushed, true);
            app.ZuppbuttonScope.Text = '↑';
            app.ZdownpbuttonScope = uibutton(app.MicroscopeControlsPanel, 'push');
            app.ZdownpbuttonScope.ButtonPushedFcn = createCallbackFcn(app, @ZdownpbuttonScopeButtonPushed, true);
            app.ZdownpbuttonScope.Text = '↓';
            app.PositionofMicroscopePanel = uipanel(app.MicroscopeControlsPanel);
            app.PositionofMicroscopePanel.Title = 'Position of Microscope';
            app.UpdateCurrentPositionButtonScope = uibutton(app.PositionofMicroscopePanel, 'push');
            app.UpdateCurrentPositionButtonScope.ButtonPushedFcn = createCallbackFcn(app, @UpdateCurrentPositionButtonScopePushed, true);
            app.UpdateCurrentPositionButtonScope.Text = 'Update Current Position';
            app.ZPositionLabelScope = uilabel(app.PositionofMicroscopePanel);
            app.ZPositionLabelScope.Text = 'Z- Position :';
            app.ZStepSpinnerLabel = uilabel(app.MicroscopeControlsPanel);
            app.ZStepSpinnerLabel.Text = 'Z  Step';
            app.ZStepSpinner = uispinner(app.MicroscopeControlsPanel);
            app.ZStepSpinner.Value = 1;
            app.CornersPanel = uipanel(app.UIFigure);
            app.CornersPanel.Title = 'Corners';
            app.ChipPositionsButtonGroup = uibuttongroup(app.CornersPanel);
            app.ChipPositionsButtonGroup.Title = 'Chip Positions ';
            app.topleftCheckBox = uicheckbox(app.ChipPositionsButtonGroup);
            app.topleftCheckBox.Text = 'topleft';
            app.toprightCheckBox = uicheckbox(app.ChipPositionsButtonGroup);
            app.toprightCheckBox.Text = 'topright';
            app.bottomleftCheckBox = uicheckbox(app.ChipPositionsButtonGroup);
            app.bottomleftCheckBox.Text = 'bottomleft';
            app.SaveCornerButton = uibutton(app.CornersPanel, 'push');
            app.SaveCornerButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCornerButtonPushed, true);
            app.SaveCornerButton.Text = 'Save Corner';
            app.GotoCornerButton = uibutton(app.CornersPanel, 'push');
            app.GotoCornerButton.ButtonPushedFcn = createCallbackFcn(app, @GotoCornerButtonPushed, true);
            app.GotoCornerButton.Text = 'Go to Corner';
            app.LoadPreviousButton = uibutton(app.CornersPanel, 'push');
            app.LoadPreviousButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPreviousButtonPushed, true);
            app.LoadPreviousButton.Text = 'Load Previous';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SingleCellGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end