classdef SingleCellSorting_faster < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AscendingCheckBox              matlab.ui.control.CheckBox
        BrightnessLabel_2              matlab.ui.control.Label
        BrightnessLabel_3              matlab.ui.control.Label
        SortButton                     matlab.ui.control.Button
        SortRowsUsingDropDown          matlab.ui.control.DropDown
        SortRowsUsingDropDownLabel     matlab.ui.control.Label
        LoadDataButton                 matlab.ui.control.StateButton
        SaveButton                     matlab.ui.control.StateButton
        Hyperlink                      matlab.ui.control.Hyperlink
        TileviewButton                 matlab.ui.control.Button
        ProcessAllButton               matlab.ui.control.Button
        VisualizeSettingsPanel         matlab.ui.container.Panel
        ViewingLabel                   matlab.ui.control.Label
        ColLabel                       matlab.ui.control.Label
        RowLabel                       matlab.ui.control.Label
        EditField_13                   matlab.ui.control.NumericEditField
        EditField_12                   matlab.ui.control.NumericEditField
        xLabel                         matlab.ui.control.Label
        CelltoviewCol                  matlab.ui.control.NumericEditField
        CelltoviewRow                  matlab.ui.control.NumericEditField
        CelltoviewEditFieldLabel       matlab.ui.control.Label
        ViewSettingsButton             matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        ImageDisplaySettingsPanel      matlab.ui.container.Panel
        EditField_8                    matlab.ui.control.NumericEditField
        GreenContrastAdjustmentSlider  matlab.ui.control.Slider
        GreenContrastAdjustmentSliderLabel  matlab.ui.control.Label
        CyanContrastAdjustmentSlider   matlab.ui.control.Slider
        CyanimageindexLabel            matlab.ui.control.Label
        EditField_7                    matlab.ui.control.NumericEditField
        DirectoryPanel                 matlab.ui.container.Panel
        dropdown                       matlab.ui.control.DropDown
        XLabel                         matlab.ui.control.Label
        EditField_11                   matlab.ui.control.NumericEditField
        NumberofwellsLabel             matlab.ui.control.Label
        EditField_10                   matlab.ui.control.NumericEditField
        ALabel                         matlab.ui.control.Label
        ChipIDEditField                matlab.ui.control.NumericEditField
        ChipIDLabel                    matlab.ui.control.Label
        SelectFolderButton             matlab.ui.control.Button
        FoldernameEditField            matlab.ui.control.EditField
        FoldernameEditFieldLabel       matlab.ui.control.Label
        CellSortingThreshholdingPanel  matlab.ui.container.Panel
        EndRowEditField                matlab.ui.control.NumericEditField
        EndRowEditFieldLabel           matlab.ui.control.Label
        StartRowEditField              matlab.ui.control.NumericEditField
        StartRowEditFieldLabel         matlab.ui.control.Label
        UpdateThreshholdsButton        matlab.ui.control.Button
        ch2Label                       matlab.ui.control.Label
        ch2Label_2                     matlab.ui.control.Label
        ch1Label                       matlab.ui.control.Label
        CH1_MinLabel                   matlab.ui.control.Label
        CH2_MinLabel                   matlab.ui.control.Label
        CH2_MaxLabel                   matlab.ui.control.Label
        Area_minSlider                 matlab.ui.control.Slider
        Green_maxSliderLabel_2         matlab.ui.control.Label
        EditField_6                    matlab.ui.control.NumericEditField
        Area_maxSlider                 matlab.ui.control.Slider
        Area_maxSliderLabel            matlab.ui.control.Label
        EditField_5                    matlab.ui.control.NumericEditField
        Green_maxSlider                matlab.ui.control.Slider
        EditField_3                    matlab.ui.control.NumericEditField
        Green_minSlider                matlab.ui.control.Slider
        EditField_2                    matlab.ui.control.NumericEditField
        Cyan_minSlider                 matlab.ui.control.Slider
        EditField                      matlab.ui.control.NumericEditField
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        greenimage=[]
        cyanimage=[]
        folder_location=[]
        image_properties=strings(1)
        num_detected=2;
        status= strings(1);
        excelnumber=2;
        for_Display=strings(1);
        Region_Meta_Data=strings(1)
        features = string(1);
        mainapp;
        tile_viewAPP;
        Tile_view_ImageAPP
        cellStatus;
        enMatrix;
        expinfo
        Ch1
        Ch2
        loadeddata = false;
        obj_revised = strings(1);
        num_revised = 2;
        result_filepath;
        library_id = " ";
        selection = [];
        mod = '';

    end
    
    methods (Access = public)
        
        function clickedonCell(app,row,col)
            app.excelnumber = app.enMatrix(row,col);
            fullFileName_Cyan = char(app.status(app.excelnumber,5));
            
            fullFileName_Green = char(app.status(app.excelnumber,6));
            % Code to Display the Image that has been clicked on 
            % rownumber = row;
            % columnnumber = col;
            % foldername_Cyan = sprintf('C%02i', columnnumber); % specifing the foldername based on column 
            % filename_Cyan = sprintf('R%02i_C%02i_0000_00_Cyan.tif', rownumber, columnnumber); % specifing the imagename based on row and column  
            % fullFileName_Cyan = fullfile(app.folder_location, '\Cyan\S0000\', foldername_Cyan, filename_Cyan); % the image folder and name
            Cyan_Image = imread(fullFileName_Cyan);

            Cyan_adaptive_T = adaptthresh(Cyan_Image, 0.025);
            BW_cyan = imbinarize(Cyan_Image, Cyan_adaptive_T);
            
            signal_cyan = BW_cyan .* double(Cyan_Image); % use to find intensity percentiles 
            
            Cyan_BW_signals = signal_cyan > app.Cyan_minSlider.Value;
            Cyan_BW_signals = bwareaopen(Cyan_BW_signals, round(app.Area_minSlider.Value));

            
            % Initialize variables to store min and max intensity values within the specified area range
                       
            % Find connected components in the cyan binary image
            CC_cyan = bwconncomp(Cyan_BW_signals);
            stats_cyan = regionprops(CC_cyan, 'Area', 'BoundingBox','Centroid');
            app.obj_revised(app.num_revised,4) = -1;
            app.obj_revised(app.num_revised,5) = -1;
            if numel(stats_cyan) > 0
                centroid = stats_cyan(1).Centroid;
                centroidX = centroid(1);
                centroidY = centroid(2);
                app.obj_revised(app.num_revised,4) = centroidX;
                app.obj_revised(app.num_revised,5) = centroidY;
            end

            imshow(Cyan_Image * app.CyanContrastAdjustmentSlider.Value, 'Parent', app.UIAxes);
            app.cyanimage = Cyan_Image;
            % max_intenisty_Cyan = max(max(Cyan_Image));
            % app.for_Display(app.excelnumber, 4) = max_intenisty_Cyan;
    
            % filename_Green = sprintf('R%02i_C%02i_0000_01_Green.tif', rownumber, columnnumber);
            % foldername_Green = sprintf('C%02i', columnnumber);
            % fullFileName_Green = fullfile(app.folder_location, '\Green\S0000\', foldername_Green, filename_Green);
            Green_Image = imread(fullFileName_Green);
            imshow(Green_Image * app.GreenContrastAdjustmentSlider.Value, 'Parent', app.UIAxes_2);

            falseImg = zeros([size(Cyan_Image) 3]);
            falseImg(:,:,3) = 0;
            falseImg(:,:,2) = im2double(Cyan_Image) * app.EditField_7.Value;
            falseImg(:,:,1) = im2double(Green_Image) * app.EditField_8.Value;
            imshow(falseImg,"Parent",app.UIAxes_3);
            % max_intenisty_Green = max(max(Green_Image));
            % app.for_Display(app.excelnumber, 5) = max_intenisty_Green;
            app.greenimage = Green_Image;

            % Code to change the excel value 
            tableRow = find(str2double(app.UITable.Data(:,1)) == row & str2double(app.UITable.Data(:,2)) == col); 
            scroll(app.UITable,"Row",tableRow);
            app.EditField_13.Value = col;
            app.EditField_12.Value = row;
            
             app.CelltoviewRow.Value = row;
            app.CelltoviewCol.Value = col;
            app.ViewSettingsButtonPushed();
           
        end
function smartchipdata(app)
    fileName = 'SmartChipPicks.txt';
    filePath = fullfile(app.result_filepath, fileName); % Specify the desired path to save the file

    FileName_excel = sprintf('SmartChipData_A%06i%s.xlsx', app.ChipIDEditField.Value, app.dropdown.Value);
    fullFileName_excel = fullfile(app.folder_location, FileName_excel);
    t = readtable(fullFileName_excel, 'Sheet','Selected_Spots'); % storing the Selected_Spots excel
    names = table2cell(t);
    
    % Open the file for writing
    fileID = fopen(filePath, 'w','n', 'windows-1252'); % Eastern European Encoder I assume it doesn't make a difference but this works 
    result_header = readtable(fullFileName_excel, 'Sheet','Result_Header',"Range","A1:A22");
    result_header = table2cell(result_header);
    
    % Write the header information
    fprintf(fileID, 'Comment:\t\t\t\r\n');
    for i = 1:size(result_header, 1)
        line = result_header{i, 1};
        if line(end) == ':'
            fprintf(fileID, '%s \t\t\t\r\n', line);
        else
            fprintf(fileID, '%s\t\t\t\r\n', line);
        end
    end
    fprintf(fileID, '[0, 0, 0]\t\t\t\r\n');

    % Close the file
    fclose(fileID);
    
    % Open the file for appending
    fileID = fopen(filePath, 'a');

    % Write the data using a parfor loop
    for rownumber = 1:72 % should start at 1 since the other app does that 
        for columnnumber_counter = 1:72
            m = strfind(names(rownumber + 1, columnnumber_counter + 1), '~');
            if ~isequal(m{1}, 1)
                fprintf(fileID, '%d/%d\t1A1,\t47,\r\n', rownumber, columnnumber_counter);
            else
                fprintf(fileID, '%d/%d\t\t\t\r\n', rownumber, columnnumber_counter);
            end
        end
    end

    % Close the file
    fclose(fileID);
    
    % Copy the file to a new location
    newFileName = strrep(fileName, '.txt', '.fld');
    newFilePath = fullfile(app.result_filepath, newFileName);
    copyfile(filePath, newFilePath);
    
    % % Delete the original .txt file
    delete(filePath);
end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin < 2
                mainapp = [];  % or any default value you prefer
            else
                mainapp = varargin{1};
            end
            app.LoadDataButton.Visible = 'off';
            app.ProcessAllButton.Visible = 'off';
            app.TileviewButton.Visible = "off";
            app.VisualizeSettingsPanel.Visible = "off";
            app.ImageDisplaySettingsPanel.Visible = "off";
            app.CellSortingThreshholdingPanel.Visible = "off";
            app.SaveButton.Visible = "off";
            app.UpdateThreshholdsButton.Visible = 'off';
            % Rest of your startupFcn code goes here
            
            % Example: move the UI figure to the center of the screen
            movegui(app.UIFigure, "center");
            app.UITable.ColumnEditable(6) = true;
            % Assign the mainapp to app property
            app.mainapp = mainapp; 
            
            % If mainapp is provided, set folder location based on its property
            if ~isempty(mainapp)
                app.mainapp = mainapp;
                
                app.ChipIDEditField.Value = app.mainapp.ChipIDEditField.Value;
                % app.FoldernameEditField.Value = app.folder_location;
                % FileName_excel = sprintf('SmartChipData_A%06i%s.xlsx', app.ChipIDEditField.Value, app.dropdown.Value);
                % fullFileName_excel = fullfile(app.folder_location, FileName_excel);
                % expirementinfo = readtable(fullFileName_excel,"Sheet","SmartChip_Info", "Range","A1:B6");
                % app.expinfo = table2cell(expirementinfo);
                % ch1 = string(app.expinfo(3,2));
                % ch2 = string(app.expinfo(4,2));
                % ch1 = erase(ch1,'\S0000');
                % ch2 = erase(ch2,'\S0000');
                % app.ch1Label.Text = ch1;
                % app.ch2Label.Text = ch2;
                % app.ch2Label_2.Text = ch2;
                % app.Ch1 = ch1;    
                % app.Ch2 = ch2;
                % app.LoadDataButton.Visible = 'on';
                % app.ProcessAllButton.Visible = 'on';
                % app.TileviewButton.Visible = 'on';
                % app.VisualizeSettingsPanel.Visible = 'on';
                % app.ImageDisplaySettingsPanel.Visible = 'on';
                % app.CellSortingThreshholdingPanel.Visible = 'on';
            end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
   
        
            folderlocation = uigetdir(pwd, 'Select a folder'); % selecting folder
            app.folder_location=folderlocation;
             app.FoldernameEditField.Value=folderlocation;
           
            figure(app.UIFigure);

            if ~isequal(app.ChipIDEditField.Value,0)
                FileName_excel = sprintf('SmartChipData_A%06i%s.xlsx', app.ChipIDEditField.Value, app.dropdown.Value);
                fullFileName_excel = fullfile(app.folder_location, FileName_excel);
                expirementinfo = readtable(fullFileName_excel,"Sheet","SmartChip_Info", "Range","A1:B6");
                app.expinfo = table2cell(expirementinfo);
                ch1 = string(app.expinfo(3,2));
                ch2 = string(app.expinfo(4,2));
                ch1 = erase(ch1,'\S0000');
                ch2 = erase(ch2,'\S0000');
                app.ch1Label.Text = ch1;
                app.ch2Label.Text = ch2;
                app.ch2Label_2.Text = ch2;
                app.Ch1 = ch1;    
                app.Ch2 = ch2;
                app.result_filepath = string(app.expinfo(5,2));
                app.result_filepath = fullfile(app.folder_location, app.result_filepath);
                if ~isfolder(app.result_filepath)
                    mkdir(app.result_filepath);
                end
                app.LoadDataButton.Visible = 'on';
                app.ProcessAllButton.Visible = 'on';
                app.TileviewButton.Visible = 'on';
                app.VisualizeSettingsPanel.Visible = 'on';
                app.ImageDisplaySettingsPanel.Visible = 'on';
                app.CellSortingThreshholdingPanel.Visible = 'on';

            end
          

        end

        % Value changed function: FoldernameEditField
        function FoldernameEditFieldValueChanged(app, event)
              properties (Access = public)   
            value = app.FoldernameEditField.Value;
 
        end

        % Callback function: Cyan_minSlider, Cyan_minSlider
        function MinCyanSliderValueChanged(app, event)
            value = app.Cyan_minSlider.Value;
            app.EditField.Value=app.Cyan_minSlider.Value;
            figure(app.UIFigure);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            if app.EditField.Value >= app.Cyan_minSlider.Limits(1) && app.EditField.Value <= app.Cyan_minSlider.Limits(2)
                app.Cyan_minSlider.Value = app.EditField.Value;
            else  
                app.EditField.Value = app.Cyan_minSlider.Value;
            end
          
        end

        % Value changed function: Green_minSlider
        function Green_minSliderValueChanged(app, event)
            value = app.Green_minSlider.Value;
            app.EditField_2.Value=app.Green_minSlider.Value;
            figure(app.UIFigure);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            if app.EditField_2.Value >= app.Green_minSlider.Limits(1) && app.EditField_2.Value <= app.Green_minSlider.Limits(2)
                app.Green_minSlider.Value = app.EditField_2.Value;
            else
                app.EditField_2.Value = app.Green_minSlider.Value;
            end
            
        end

        % Value changed function: Green_maxSlider
        function Green_maxSliderValueChanged(app, event)
            value = app.Green_maxSlider.Value;
            app.EditField_3.Value=app.Green_maxSlider.Value;
            figure(app.UIFigure);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            if app.EditField_3.Value >= app.Green_maxSlider.Limits(1) && app.EditField_3.Value <= app.Green_maxSlider.Limits(2)
                app.Green_maxSlider.Value = app.EditField_3.Value;
            else
                app.EditField_3.Value = app.Green_maxSlider.Value;
            end            
        end

        % Value changed function: Area_minSlider
        function Area_minSliderValueChanged(app, event)
            value = app.Area_minSlider.Value;
            app.EditField_6.Value=app.Area_minSlider.Value;
            figure(app.UIFigure);

        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            if app.EditField_6.Value >= app.Area_minSlider.Limits(1) && app.EditField_6.Value <= app.Area_minSlider.Limits(2)
                app.Area_minSlider.Value = app.EditField_6.Value;
            else
                app.EditField_6.Value = app.Area_minSlider.Value;
            end            
        end

        % Value changed function: Area_maxSlider
        function Area_maxSliderValueChanged(app, event)
            value = app.Area_maxSlider.Value;
             app.EditField_5.Value=app.Area_maxSlider.Value;
            figure(app.UIFigure);
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            if app.EditField_5.Value >= app.Area_maxSlider.Limits(1) && app.EditField_5.Value <= app.Area_maxSlider.Limits(2)
                app.Area_maxSlider.Value = app.EditField_5.Value;
            else
                app.EditField_5.Value = app.Area_maxSlider.Value;
            end            
        end

        % Value changed function: ChipIDEditField
        function ChipIDEditFieldValueChanged(app, event)
            
            app.library_id = app.ChipIDEditField.Value;
            
        end

        % Value changed function: CyanContrastAdjustmentSlider
        function CyanContrastAdjustmentSliderValueChanged(app, event)
            value = app.CyanContrastAdjustmentSlider.Value;
            app.EditField_7.Value=app.CyanContrastAdjustmentSlider.Value;
            
            imshow(app.cyanimage*app.CyanContrastAdjustmentSlider.Value,'Parent', app.UIAxes)
            
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            app.CyanContrastAdjustmentSlider.Value = app.EditField_7.Value;
            
        end

        % Value changed function: GreenContrastAdjustmentSlider
        function GreenContrastAdjustmentSliderValueChanged(app, event)
         value = app.GreenContrastAdjustmentSlider.Value;
            app.EditField_8.Value=app.GreenContrastAdjustmentSlider.Value;
            figure(app.UIFigure);
            imshow(app.greenimage*app.GreenContrastAdjustmentSlider.Value,'Parent', app.UIAxes_2)
            
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            app.GreenContrastAdjustmentSlider.Value =  app.EditField_8.Value;
            
        end

        % Button pushed function: ProcessAllButton
        function ProcessAllButtonPushed(app, event)
app.TileviewButton.Visible = "off";
app.ProcessAllButton.Text = 'Processing';
pause(.5);         
FileName_excel = sprintf('SmartChipData_A%06i%s.xlsx', app.ChipIDEditField.Value, app.dropdown.Value);
fullFileName_excel = fullfile(app.folder_location, FileName_excel);
t = readtable(fullFileName_excel, 'Sheet','Selected_Spots'); % storing the Selected_Spots excel
names = table2cell(t);

app.Region_Meta_Data = readtable(fullFileName_excel, 'Sheet', 'Region_Meta_Data'); % storing the Primer_Tags excel
Primer_Tags = readtable(fullFileName_excel, 'Sheet','Primer_Tags');
Primernames = table2cell(Primer_Tags);

ch1 = string(app.expinfo(3,2));
ch2 = string(app.expinfo(4,2));
ch1 = erase(ch1,'\S0000');
ch2 = erase(ch2,'\S0000');
app.status(1, 1:23) = {'Sample', 'Row', 'Column', 'Img_Col', 'File_Ch1', 'File_Ch2', 'Fld_Section', 'Fld_Index', 'Num_Live', ...
    'Num_Dead', 'Num_Other', 'Rev_Live', 'Rev_Dead', 'Rev_Other', 'Rev_Class', 'Condition', 'Index_I7', ...
    'Primer_I7', 'Index_I5', 'Primer_I5', 'Pick_Met', 'Spot_Well', 'Num_Drops'};
app.image_properties(1,1:15) = {'Sample','Row','Column','X_Center','Y_Center','ObjClass','Obj_ID','Area1','Intensity1','AspectRatio1','Extent1','Area2','Intensity2','AspectRatio2','Extent2'};
app.features(1,1:38) = {'Sample','Row','Column','ObjNum','ObjLink','X_Center','Y_Center','X_Start','Y_Start','Width','Height','ObjClass','Area_Ch1','Intensity_Min_Ch1','Intensity_Q1_Ch1','Intensity_Median_Ch1','Intensity_Q3_Ch1','Intensity_Max_Ch1','Intensity_95Diff_Ch1','Intensity_Total_Ch1','Area_Ch2','Intensity_Min_Ch2','Intensity_Q1_Ch2','Intensity_Median_Ch2','Intensity_Q3_Ch2','Intensity_Max_Ch2','Intensity_95Diff_Ch2','Intensity_Total_Ch2','Intensity_Mean_Ch1','Centroid_X_Ch1','Centroid_Y_Ch1','Aspect_Ratio_Ch1','Extent_Ch1','Intensity_Mean_Ch2','Centroid_X_Ch2','Centroid_Y_Ch2','Aspect_Ratio_Ch2','Extent_Ch2'};
app.obj_revised(1,1:6) = {'Sample','Row','Column','X_Center','Y_Center','ObjClass'};
% Counter for the array index


expControls = readtable(fullFileName_excel,"Sheet","SmartChip_Info", "Range","B51:B56");
expControls = table2cell(expControls); 

Cyan_not_detected = false;
Cyan_Detected = false;

excelnumberMatrix = zeros(72,72);
app.excelnumber = 2;
app.num_detected=2;
for rownumber = 1:app.EditField_10.Value % should start at 1 since the other app does that 

    for columnnumber_counter = 1:app.EditField_11.Value
        columnnumber = 73 - columnnumber_counter;
        
        app.status(app.excelnumber, 9:11) = -1; % mark cell as empty to start
        app.status(app.excelnumber, 15) = {'Auto'};
        app.status(app.excelnumber, 17:20) = Primernames(app.excelnumber - 1, 3:6);
        m = strfind(names(rownumber + 1, columnnumber_counter + 1), '~'); % if this current code doesnt work try only adding to the col and not the row since matlab removes the first row assuming it is just titles 
        app.status(app.excelnumber, 23) = 0;
        

        %read images
        if isequal(ch1, 'Cyan')
            foldername_Cyan = sprintf('C%02i', columnnumber); % specifing the foldername based on column 
            filename_Cyan = sprintf('R%02i_C%02i_0000_00_Cyan.tif', rownumber, columnnumber); % specifing the imagename based on row and column  
            fullFileName_Cyan = fullfile(app.folder_location, '\Cyan\S0000\', foldername_Cyan, filename_Cyan); % the image folder and name
            Cyan_Image = imread(fullFileName_Cyan);
        else
            error('Invalid wavelength');
        end
        % imshow(Cyan_Image * app.CyanContrastAdjustmentSlider.Value, 'Parent', app.UIAxes);
        max_intenisty_Cyan = max(max(Cyan_Image));
        app.for_Display(app.excelnumber, 4) = max_intenisty_Cyan;

        if  isequal(ch2, 'Green')
            filename_Green = sprintf('R%02i_C%02i_0000_01_Green.tif', rownumber, columnnumber);
            foldername_Green = sprintf('C%02i', columnnumber);
            fullFileName_Green = fullfile(app.folder_location, '\Green\S0000\', foldername_Green, filename_Green);
            Green_Image = imread(fullFileName_Green);
             else
            error('Invalid wavelength');
        end

        % imshow(Green_Image * app.GreenContrastAdjustmentSlider.Value, 'Parent', app.UIAxes_2);
        max_intenisty_Green = max(max(Green_Image));
        app.for_Display(app.excelnumber, 5) = max_intenisty_Green;
        
        if ~isequal(m{1}, 1) % whatdoes this do?  m is the indexes of '~'
            

            num_regions_C = 0; 
            num_regions_G = 0;
            app.EditField_13.Value = columnnumber_counter;
            app.EditField_12.Value = rownumber;
            Green_Median = 0;
            
            % Assume not detected initially


            % signal_cyan = imbinarize(Cyan_Image) .* double(Cyan_Image); % use to find intensity percentiles 
           

            Cyan_BW_signals = Cyan_Image> app.Cyan_minSlider.Value;
            Cyan_BW_signals = bwareaopen(Cyan_BW_signals, round(app.Area_minSlider.Value));
            valid_regions_cyan = 0; 
            cyan_masked= Cyan_BW_signals.*double(Cyan_Image);

            if max(Cyan_BW_signals, [], "all") == 1 % finding the object in binarized image 

              
                Cyan_Mean = mean(cyan_masked(cyan_masked>0)); % calculating the average intensity 
                Cyan_Median = median(cyan_masked(cyan_masked>0));

                % THIS IS ALL FOR OBJ_SELECTED
                Cyan_Prop = regionprops(Cyan_BW_signals, "Area", "Centroid", "EquivDiameter", "MajorAxisLength", "MinorAxisLength","BoundingBox","Circularity");
                                
                num_regions_C = length(Cyan_Prop);
                % index_num_detected = app.num_detected;
                if num_regions_C==1
                    % Extract properties for the current region
                    area_cyan = Cyan_Prop.Area;
                    centroid_cyan = Cyan_Prop.Centroid;
                    major_axis_length_cyan = Cyan_Prop.MajorAxisLength;
                    minor_axis_length_cyan = Cyan_Prop.MinorAxisLength;
                    equiv_diameter_cyan = Cyan_Prop.EquivDiameter;
                    extent_Cyan = Cyan_Prop.Circularity;
                    boundingBox = Cyan_Prop.BoundingBox;
                    % Filter based on area
                    % if app.Area_minSlider.Value < area_cyan && area_cyan < app.Area_maxSlider.Value
                        % Update app.image_properties with the properties of the current region
                        app.image_properties(app.num_detected, 1) = app.excelnumber-2;
                        app.image_properties(app.num_detected, 2) = rownumber;
                        app.image_properties(app.num_detected, 3) = columnnumber_counter;

                       
                        app.image_properties(app.num_detected, 4) = centroid_cyan(1); % X center
                        app.image_properties(app.num_detected, 5) = centroid_cyan(2); % Y Center
                        
                        app.image_properties(app.num_detected, 7) = valid_regions_cyan + 1; % obj ID 
                        app.image_properties(app.num_detected, 8) = area_cyan; % area
                        app.image_properties(app.num_detected, 9) = Cyan_Median;
                        app.image_properties(app.num_detected, 10) = major_axis_length_cyan/minor_axis_length_cyan;
                        app.image_properties(app.num_detected, 11) = extent_Cyan;
                        
                        % Update Obj_Features

                        % Non Channel Specific 
                        app.features(app.num_detected, 1) = app.excelnumber-2;
                        app.features(app.num_detected, 2) = rownumber;
                        app.features(app.num_detected, 3) =columnnumber_counter;
                        app.features(app.num_detected, 4) = valid_regions_cyan + 1; 
                        app.features(app.num_detected, 5) = valid_regions_cyan + 1; % ObjLink Not sure what it is yet 
                        app.features(app.num_detected, 6) = centroid_cyan(1); % X centroid
                        app.features(app.num_detected, 7) = centroid_cyan(2); % Y centroid
                        app.features(app.num_detected, 8) = boundingBox(1);
                        app.features(app.num_detected, 9) = boundingBox(2);
                        app.features(app.num_detected, 10) = boundingBox(3);
                        app.features(app.num_detected, 11) = boundingBox(4);
                        % Chanel Specific
                        app.features(app.num_detected, 13) = area_cyan;
                        app.features(app.num_detected, 14) = min(cyan_masked(cyan_masked>0));
                        app.features(app.num_detected, 15) = prctile(cyan_masked(cyan_masked>0), 25);
                        app.features(app.num_detected, 16) = Cyan_Median;
                        app.features(app.num_detected, 17) = prctile(cyan_masked(cyan_masked>0), 75);
                        app.features(app.num_detected, 18) = max(cyan_masked(cyan_masked>0));
                        app.features(app.num_detected, 19) = prctile(cyan_masked(cyan_masked>0), 95);
                        app.features(app.num_detected, 20) = sum(cyan_masked(cyan_masked>0));
                        app.features(app.num_detected, 21:38) =-1;
                        % Chanel specific cluster #2
                        app.features(app.num_detected, 29) =  Cyan_Mean;
                        app.features(app.num_detected, 30) = centroid_cyan(1);
                        app.features(app.num_detected, 31) =  centroid_cyan(2);
                        app.features(app.num_detected, 32) = major_axis_length_cyan/minor_axis_length_cyan;
                        app.features(app.num_detected, 33) = extent_Cyan;
                        % valid_regions_cyan = valid_regions_cyan + 1; 
                        % % Increment num_detected


                    % end
                    
            if prctile(cyan_masked(cyan_masked>0), 95)> app.Cyan_minSlider.Value
                Cyan_Detected = true;
                Cyan_not_detected = false;
            else

                Cyan_not_detected = true;
                Cyan_Detected = false;
            end
            end
            else
                Cyan_Mean = 0; % if no object was found finding the mean value
                % app.image_properties(app.num_detected, 8:11) = -1;
                Cyan_not_detected = true;
                Cyan_Detected = false;
            end


            %%%% Processing green image

            Green_BW_signals = Green_Image > app.Green_minSlider.Value;
            Green_BW_signals = bwareaopen(Green_BW_signals,  round(app.Area_minSlider.Value));

            signal_green = Green_BW_signals .* double(Green_Image); % reconstruct the object from image 
           
            valid_regions_green = 0;

            if max(Green_BW_signals, [], "all") == 1 % finding the object in binirized image 

                Green_Mean = mean(signal_green(signal_green>0)); % calculating the average intensity 
                Green_Median = median(signal_green(signal_green>0));
                Green_Prop = regionprops(Green_BW_signals, "Area", "Centroid", "EquivDiameter", "MajorAxisLength", "MinorAxisLength", "Circularity","BoundingBox");

                num_regions_G = length(Green_Prop);
                
                if num_regions_G ==1
                    % Extract properties for the current region
                    area_green = Green_Prop.Area;
                    centroid_green = Green_Prop.Centroid;
                    major_axis_length_green = Green_Prop.MajorAxisLength;
                    minor_axis_length_green = Green_Prop.MinorAxisLength;
                    equiv_diameter_green = Green_Prop.EquivDiameter;
                    extent_green = Green_Prop.Circularity;
                    boundingBox = Green_Prop.BoundingBox;
                    % Filter based on area
                    if app.Area_minSlider.Value < area_green && area_green < app.Area_maxSlider.Value
                        if num_regions_C~=1 
       
                        app.features(app.num_detected, 1) = app.excelnumber-2;
                        app.features(app.num_detected, 2) = rownumber;
                        app.features(app.num_detected, 3) =columnnumber_counter;
                        app.features(app.num_detected, 4) = valid_regions_cyan + 1; 
                        app.features(app.num_detected, 5) = valid_regions_cyan + 1; 

                        app.features(app.num_detected,6:21) = -1;
                        Cyan_Detected = false;
                        Cyan_not_detected = true;
                       
                        end
             

                        % Update app.image_properties with the properties of the current region
                        app.image_properties(app.num_detected, 1) = app.excelnumber-2;
                        app.image_properties(app.num_detected, 2) = rownumber;
                        app.image_properties(app.num_detected, 3) = columnnumber_counter;
                                               
                        app.image_properties(app.num_detected,12) = area_green; % area
                        app.image_properties(app.num_detected, 13) = Green_Median;
                        app.image_properties(app.num_detected, 14) = major_axis_length_green/minor_axis_length_green;
                        app.image_properties(app.num_detected, 15) = extent_green;
                        
                        % Update Obj_Features
                        
                        app.features(app.num_detected, 21) = area_green;
                        app.features(app.num_detected, 22) = min(signal_green(Green_BW_signals));
                        app.features(app.num_detected, 23) = prctile(signal_green(Green_BW_signals), 25);
                        app.features(app.num_detected, 24) = Green_Median;
                        app.features(app.num_detected, 25) = prctile(signal_green(Green_BW_signals), 75);
                        app.features(app.num_detected, 26) = max(signal_green(Green_BW_signals));
                        app.features(app.num_detected, 27) = prctile(signal_green(Green_BW_signals), 95);
                        app.features(app.num_detected, 28) = sum(signal_green(Green_BW_signals));
                       
                        % Cluster #2 Ch2
                        app.features(app.num_detected, 34) =  Green_Mean;
                        app.features(app.num_detected, 35) = centroid_green(1);
                        app.features(app.num_detected, 36) =  centroid_green(2);
                        app.features(app.num_detected, 37) = major_axis_length_green/minor_axis_length_green;
                        app.features(app.num_detected, 38) = extent_green;

                        % valid_regions_green = valid_regions_green + 1; 
                        % Increment num_detected
                        % if valid_regions_cyan < 1 |i > valid_regions_cyan
                        %     app.image_properties(app.num_detected, 4) = centroid_green(1); % X center
                        %     app.image_properties(app.num_detected, 5) = centroid_green(2); % Y Center
                        % 
                        %     app.image_properties(app.num_detected, 7) = valid_regions_green;
                        %     app.image_properties(app.num_detected, 8:11) = -1; % Fill with -1's initially
                        % 
                        %     % Non - Channel Specific Info 
                        %     app.features(app.num_detected, 1) = app.excelnumber-2;
                        %     app.features(app.num_detected, 2) = rownumber;
                        %     app.features(app.num_detected, 3) =columnnumber_counter;
                        %     app.features(app.num_detected, 4) = valid_regions_green; 
                        %     app.features(app.num_detected, 5) = valid_regions_green; % ObjLink Not sure what it is yet 
                        %     app.features(app.num_detected, 6) = centroid_green(1); % X centroid
                        %     app.features(app.num_detected, 7) = centroid_green(2); % Y centroid
                        %     app.features(app.num_detected, 8) = boundingBox(1);
                        %     app.features(app.num_detected, 9) = boundingBox(2);
                        %     app.features(app.num_detected, 10) = boundingBox(3);
                        %     app.features(app.num_detected, 11) = boundingBox(4);
                        % 
                        % 
                        % end
              
                    end
                else 
                    Green_Median=0;
                    
                end
   
            else     
          
                Green_Mean = 0; % if no object was found finding the mean value
                % app.image_properties(app.num_detected, 12:15) = -1; % Fill with -1's initially
            end
%% Changed to median from max 
            max_Green_intensity = Green_Median;
            app.for_Display(app.excelnumber, 5) = max_Green_intensity;
            if max_Green_intensity < app.Green_maxSlider.Value && max_Green_intensity > app.Green_minSlider.Value
                Green_Detected_live = true;
                Green_not_detected = false;
                Green_Detected_dead = false;
            elseif max_Green_intensity > app.Green_maxSlider.Value
                Green_Detected_live = false;
                Green_not_detected = false;
                Green_Detected_dead = true;
            else
                Green_Detected_live = false;
                Green_not_detected = true;
                Green_Detected_dead = false;
            end

                     
            if Cyan_Detected == 1 & Green_Detected_live == 1 | Green_not_detected == 1 & Cyan_Detected == 1
                app.for_Display(app.excelnumber, 6) = "C1";
                app.status(app.excelnumber, 21) = "C1"; %live
                app.status(app.excelnumber, 22) = "1A1";
                app.status(app.excelnumber, 23) = 47;
                app.status(app.excelnumber, 9:10) = 0;
                app.status(app.excelnumber, 11) = 1;
                app.image_properties(app.num_detected, 6) = 1;
                % app.features(app.num_detected, 12) = 1;
            elseif  Cyan_not_detected == 1 & Green_not_detected == 1
                disp(m{1});
                app.status(app.excelnumber, 21) = "C3"; % empty
                app.for_Display(app.excelnumber, 6) = "C3";
                app.status(app.excelnumber, 9) = 1;
                app.status(app.excelnumber, 10:11) = 0;
                if ~isequal(max(valid_regions_green, valid_regions_cyan), 0)
                    app.image_properties(app.num_detected, 6) = 0;
                    % app.features(app.num_detected, 12) = 0;
                end 

            elseif Cyan_Detected == 1 & Green_Detected_dead == 1 | Cyan_not_detected == 1 & Green_Detected_dead == 1 | Cyan_not_detected == 1 & Green_Detected_live == 1
                app.status(app.excelnumber, 21) = "C2"; %dead
                app.status(app.excelnumber, 22) = "1A1";
                app.status(app.excelnumber, 23) = 47;
                app.for_Display(app.excelnumber, 6) = "C2";
                app.status(app.excelnumber, 9, 11) = 0;
                app.status(app.excelnumber, 10) = 1;
                app.image_properties(app.num_detected, 6) = -1;
                % app.features(app.num_detected, 12) = -1;
            end

            
            % for i = 1:(max(valid_regions_cyan, valid_regions_green)) 
            %     if valid_regions_cyan  < i
            %         % Fill Cyan-related properties with -1's
            %         app.image_properties(app.num_detected + i -1, 8:11) = -1;
            %         app.features(app.num_detected+ i -1, 13:20) = -1;
            %         app.features(app.num_detected+ i -1, 29:33) = -1;
            %         app.image_properties(app.num_detected + i-1, 6) = 0;
            %         app.features(app.num_detected+ i-1, 12) = 0;
            %     end
            %     if valid_regions_green  < i
            %         % Fill Green-related properties with -1's
            %         app.image_properties(app.num_detected + i-1, 12:15) = -1;
            %         app.features(app.num_detected+ i -1, 21:28) = -1;
            %         app.features(app.num_detected+ i -1, 34:38) = -1;
            %         app.image_properties(app.num_detected + i-1, 6) = 0;
            %         app.features(app.num_detected+ i-1, 12) = 0;
            %     end
            % 
            % end
            if num_regions_G==1|num_regions_C==1
                 app.num_detected=app.num_detected+1;
            end
 
        end
        
        excelnumberMatrix(rownumber,columnnumber_counter) = app.excelnumber;
        app.for_Display(app.excelnumber, 2) = columnnumber_counter;
        app.for_Display(app.excelnumber, 1) = rownumber;
        app.for_Display(app.excelnumber, 3) = names(rownumber + 1, columnnumber_counter + 1);
        app.status(app.excelnumber, 1) = app.excelnumber - 2;
        app.status(app.excelnumber, 2) = rownumber;
        app.status(app.excelnumber, 3) = columnnumber_counter;
        app.status(app.excelnumber, 4) = columnnumber;
        app.status(app.excelnumber, 5) = fullFileName_Cyan; %Ch1
        app.status(app.excelnumber, 6) = fullFileName_Green; %Ch2
        app.status(app.excelnumber, 7) = {'[0,0,0]'};
        app.status(app.excelnumber, 8) = {sprintf('%d/%d', rownumber, columnnumber_counter)};

        app.status(app.excelnumber, 12:14) = -1;
        app.status(app.excelnumber, 16) = names(rownumber + 1, columnnumber_counter + 1);
        
        app.excelnumber = app.excelnumber + 1;
        
        app.UITable.Data = app.for_Display;

        % pause(.05) % Pause between each image analysis takes roughly 5160 X 
    end
end


app.enMatrix = excelnumberMatrix;
app.ProcessAllButton.Text = 'Process all';

app.TileviewButton.Visible = "on";
app.SaveButton.Visible = "on";
app.UpdateThreshholdsButton.Visible = 'on';
%   xlswrite(fullFileName_excel_Results, app.status);
%  xlswrite(fullfile(app.folder_location, "results"), app.image_properties,'Obj_Selected');
%  xlswrite(fullfile(app.folder_location, "results"), app.Region_Meta_Data,'Region_Meta_Data');
        end

        % Button pushed function: TileviewButton
        function TileviewButtonPushed(app, event)
            app.Tile_view_ImageAPP=tile_view(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
                       
            editedRow = event.Indices(1);% Starts at 2 not one hence + 1 
           
            editedCol = event.Indices(2);
            if editedCol == 6  % Assuming statusColumnIndex is the index of 'Status' column
                % Get the edited status value
                newStatus = event.NewData;
                app.status(editedRow, 15) = {newStatus};
                if ~strcmp(newStatus,'C3')
                    app.status(editedRow, 21) = newStatus;
                    app.status(editedRow, 22) = "1A1";
                    app.status(editedRow, 23) = 47;
                    app.status(editedRow, 9:10) = 0;
                    app.status(editedRow, 11) = 1;
                    if strcmp(newStatus,'C1')
                        app.obj_revised(app.num_revised,6) = 1;
                    else
                         app.obj_revised(app.num_revised,6) = -1;
                    end
                else
                    app.status(editedRow, 21) = newStatus; % empty
                    app.status(editedRow, 9) = 1;
                    app.status(editedRow, 10:11) = 0;
                    app.obj_revised(app.num_revised,6) = 0;
                end
                app.obj_revised(app.num_revised,1) = app.status(editedRow, 1);
                app.obj_revised(app.num_revised,2) = app.status(editedRow, 2);
                app.obj_revised(app.num_revised,3) = app.status(editedRow, 3);
                % disp(app.prop_numM(str2double(app.status(editedRow, 2)),str2double(app.status(editedRow, 3))));

                app.num_revised = app.num_revised + 1;
           end
            
        end

        % Value changed function: SaveButton
        function SaveButtonValueChanged(app, event)
            
            smartchipdata(app);
            FileName_excel_Results = sprintf('SmartChipData_A%06i%s_Results.xlsx', app.ChipIDEditField.Value, app.dropdown.Value); % string(app.expinfo(5,2)); <---this is the result we are looking for 
            fullFileName_excel_Results = fullfile(app.result_filepath, FileName_excel_Results);
            if isempty(app.obj_revised(app.num_revised-1, 1))
                app.obj_revised(app.num_revised, :) = [];    
            end
                       
            % 
            % summary_table = array2table(app.status);
            % writetable(summary_table, fullFileName_excel_Results, 'Sheet', 'summary');
            
            writematrix(app.status, fullFileName_excel_Results, 'Sheet', 'Summary');
            writematrix(app.image_properties, fullFileName_excel_Results, 'Sheet', 'Obj_Selected');
            writematrix(app.obj_revised, fullFileName_excel_Results, 'Sheet', 'Obj_Revised');
            writematrix(app.features, fullFileName_excel_Results, 'Sheet', 'Obj_Features');
            if ~app.loadeddata    
                writetable(app.Region_Meta_Data, fullFileName_excel_Results, 'Sheet', 'Region_Meta_Data');
            end
        end

        % Button pushed function: ViewSettingsButton
        function ViewSettingsButtonPushed(app, event)
            ch1 = string(app.expinfo(3,2));
            ch2 = string(app.expinfo(4,2));
            ch1 = erase(ch1,'\S0000');
            ch2 = erase(ch2,'\S0000');
            rownumber = app.CelltoviewRow.Value;
            columnnumber = 73-app.CelltoviewCol.Value;
            median_intensity_cyan = 0;
            median_intensity_green = 0; 
            if isequal(ch1, 'Cyan')
                foldername_Cyan = sprintf('C%02i', columnnumber); % specifing the foldername based on column 
                filename_Cyan = sprintf('R%02i_C%02i_0000_00_Cyan.tif', rownumber, columnnumber); % specifing the imagename based on row and column  
                fullFileName_Cyan = fullfile(app.folder_location, '\Cyan\S0000\', foldername_Cyan, filename_Cyan); % the image folder and name
                Cyan_Image = imread(fullFileName_Cyan);
            elseif isequal(ch1, 'Green')
                foldername_Cyan = sprintf('C%02i', columnnumber);
                filename_Cyan = sprintf('R%02i_C%02i_0000_01_Green.tif', rownumber, columnnumber);
                fullFileName_Cyan = fullfile(app.folder_location, '\Green\S0000\', foldername_Cyan, filename_Cyan);
                Cyan_Image = imread(fullFileName_Cyan);
            elseif isequal(ch1, 'UV')
                foldername_Cyan = sprintf('C%02i', columnnumber);
                filename_Cyan = sprintf('R%02i_C%02i_0000_02_UV.tif', rownumber, columnnumber);
                fullFileName_Cyan = fullfile(app.folder_location, '\UV\S0000\', foldername_Cyan, filename_Cyan);
                Cyan_Image = imread(fullFileName_Cyan);
            elseif isequal(ch1, 'Blue')
                foldername_Cyan = sprintf('C%02i', columnnumber);
                filename_Cyan = sprintf('R%02i_C%02i_0000_03_Blue.tif', rownumber, columnnumber);
                fullFileName_Cyan = fullfile(app.folder_location, '\Blue\S0000\', foldername_Cyan, filename_Cyan);
                Cyan_Image = imread(fullFileName_Cyan);
            elseif isequal(ch1, 'Teal')
                foldername_Cyan = sprintf('C%02i', columnnumber);
                filename_Cyan = sprintf('R%02i_C%02i_0000_04_Teal.tif', rownumber, columnnumber);
                fullFileName_Cyan = fullfile(app.folder_location, '\Teal\S0000\', foldername_Cyan, filename_Cyan);
                Cyan_Image = imread(fullFileName_Cyan);
            elseif isequal(ch1, 'Red')
                foldername_Cyan = sprintf('C%02i', columnnumber);
                filename_Cyan = sprintf('R%02i_C%02i_0000_05_Red.tif', rownumber, columnnumber);
                fullFileName_Cyan = fullfile(app.folder_location, '\Red\S0000\', foldername_Cyan, filename_Cyan);
                Cyan_Image = imread(fullFileName_Cyan);
            else
                error('Invalid wavelength');
            end
            Cyan_Image = (Cyan_Image);
            Cyan_adaptive_T = adaptthresh(Cyan_Image, 0.025);
            BW_cyan = imbinarize(Cyan_Image, Cyan_adaptive_T);
            
            signal_cyan = BW_cyan .* double(Cyan_Image); % use to find intensity percentiles 
            
            Cyan_BW_signals = signal_cyan > app.Cyan_minSlider.Value;
            Cyan_BW_signals = bwareaopen(Cyan_BW_signals, round(app.Area_minSlider.Value));

            area_min = round(app.Area_minSlider.Value); % pixels
            area_max = round(app.Area_maxSlider.Value); % pixels
            
            % Display the cyan image
            subplot(1, 2, 1);
            imshow(Cyan_Image);
            title(ch1);
            
            hold on;
            
            % Initialize variables to store min and max intensity values within the specified area range
            min_intensity_cyan = inf;
            max_intensity_cyan = -inf;
            
            % Find connected components in the cyan binary image
            CC_cyan = bwconncomp(Cyan_BW_signals);
            stats_cyan = regionprops(CC_cyan, 'Area', 'BoundingBox');
            
            % Loop through each cyan region
            for i = 1:numel(stats_cyan)
                area = stats_cyan(i).Area;
                boundingBox = stats_cyan(i).BoundingBox;
                
                % Draw boundary around regions that meet the area criteria
                if area >= area_min && area <= area_max
                    rectangle('Position', [boundingBox(1), boundingBox(2), boundingBox(3), boundingBox(4)], 'EdgeColor', 'r', 'LineWidth', 2);
                    
                    % Calculate intensity statistics within the cyan region
                    region_mask_cyan = false(size(Cyan_Image));
                    region_mask_cyan(round(boundingBox(2)):round(boundingBox(2)+boundingBox(4)-1), ...
                                round(boundingBox(1)):round(boundingBox(1)+boundingBox(3)-1)) = true;
                    region_intensity_cyan = signal_cyan(region_mask_cyan);
                    median_intensity_cyan = median(region_intensity_cyan);
                    % Update min and max cyan intensity values
                    min_intensity_cyan =  prctile(signal_cyan(Cyan_BW_signals), 5);
                    max_intensity_cyan =  max(region_intensity_cyan);
                end
            end
            
            % Display the cyan min and max intensity values
            text(10, 20, sprintf('Cyan 5th Percentile: %d', round(min_intensity_cyan)), 'Color', 'red', 'FontSize', 12);
            text(10, 60, sprintf('Cyan Median Intensity: %d', median_intensity_cyan), 'Color', 'red', 'FontSize', 12);
            text(10, 40, sprintf('Cyan Max Intensity: %d', max_intensity_cyan), 'Color', 'red', 'FontSize', 12);
            % Get the region properties of the connected components
            stats = regionprops(CC_cyan, 'Centroid');
            if numel(stats) > 0 
                % Calculate the centroid coordinates of the first region
                centroid = stats(1).Centroid;
                centroidX = centroid(1);
                centroidY = centroid(2);
                
                % Calculate the dimensions of the area boxes
                area_min_width = sqrt(area_min);
                area_max_width = sqrt(area_max);
                
                % Calculate the positions of the area boxes centered at the centroid
                area_min_x = centroidX - area_min_width / 2;
                area_min_y = centroidY - area_min_width / 2;
                area_max_x = centroidX - area_max_width / 2;
                area_max_y = centroidY - area_max_width / 2;
                
                % Draw the area boxes
                rectangle('Position', [area_min_x, area_min_y, area_min_width, area_min_width], 'EdgeColor', 'g', 'LineWidth', 2);
                rectangle('Position', [area_max_x, area_max_y, area_max_width, area_max_width], 'EdgeColor', 'b', 'LineWidth', 2);
            end 
            hold off;
            
            % Read the green image
            
            if isequal(ch2, 'Cyan')
                filename_Green = sprintf('R%02i_C%02i_0000_00_Cyan.tif', rownumber, columnnumber);
                foldername_Green = sprintf('C%02i', columnnumber);
                fullFileName_Green = fullfile(app.folder_location, '\Cyan\S0000\', foldername_Green, filename_Green);
                Green_Image = imread(fullFileName_Green);
            elseif isequal(ch2, 'Green')
                filename_Green = sprintf('R%02i_C%02i_0000_01_Green.tif', rownumber, columnnumber);
                foldername_Green = sprintf('C%02i', columnnumber);
                fullFileName_Green = fullfile(app.folder_location, '\Green\S0000\', foldername_Green, filename_Green);
                Green_Image = imread(fullFileName_Green);
            elseif isequal(ch2, 'UV')
                filename_Green = sprintf('R%02i_C%02i_0000_02_UV.tif', rownumber, columnnumber);
                foldername_Green = sprintf('C%02i', columnnumber);
                fullFileName_Green = fullfile(app.folder_location, '\UV\S0000\', foldername_Green, filename_Green);
                Green_Image = imread(fullFileName_Green);
            elseif isequal(ch2, 'Blue')
                filename_Green = sprintf('R%02i_C%02i_0000_03_Blue.tif', rownumber, columnnumber);
                foldername_Green = sprintf('C%02i', columnnumber);
                fullFileName_Green = fullfile(app.folder_location, '\Blue\S0000\', foldername_Green, filename_Green);
                Green_Image = imread(fullFileName_Green);
            elseif isequal(ch2, 'Teal')
                filename_Green = sprintf('R%02i_C%02i_0000_04_Teal.tif', rownumber, columnnumber);
                foldername_Green = sprintf('C%02i', columnnumber);
                fullFileName_Green = fullfile(app.folder_location, '\Teal\S0000\', foldername_Green, filename_Green);
                Green_Image = imread(fullFileName_Green);
            elseif isequal(ch2, 'Red')
                filename_Green = sprintf('R%02i_C%02i_0000_05_Red.tif', rownumber, columnnumber);
                foldername_Green = sprintf('C%02i', columnnumber);
                fullFileName_Green = fullfile(app.folder_location, '\Red\S0000\', foldername_Green, filename_Green);
                Green_Image = imread(fullFileName_Green);
            else
                error('Invalid wavelength');
            end
            Green_Image = (Green_Image);
            Green_adaptive_T = adaptthresh(Green_Image, 0.025);
            BW_green = imbinarize(Green_Image, Green_adaptive_T);
            
            signal_green = BW_green .* double(Green_Image); % use to find intensity percentiles 
            
            Green_BW_signals = signal_green > app.Green_minSlider.Value;
            Green_BW_signals = bwareaopen(Green_BW_signals, round(app.Area_minSlider.Value));
            
            % Display the green image
            subplot(1, 2, 2);
            imshow(Green_Image);
            title(ch2);
            
            hold on;
            
            % Initialize variables to store min and max intensity values within the specified area range
            min_intensity_green = inf;
            max_intensity_green = -inf;
            
            % Find connected components in the green binary image
            CC_green = bwconncomp(Green_BW_signals);
            stats_green = regionprops(CC_green, 'Area', 'BoundingBox');
            % disp(numel(stats_green));
            % Loop through each green region
            for i = 1:numel(stats_green)
                area = stats_green(i).Area;
                boundingBox = stats_green(i).BoundingBox;
                
                % Draw boundary around regions that meet the area criteria
                if area >= area_min && area <= area_max
                    rectangle('Position', [boundingBox(1), boundingBox(2), boundingBox(3), boundingBox(4)], 'EdgeColor', 'r', 'LineWidth', 2);
                    
                    % Calculate intensity statistics within the green region
                    region_mask_green = false(size(Green_Image));
                    region_mask_green(round(boundingBox(2)):round(boundingBox(2)+boundingBox(4)-1), ...
                                round(boundingBox(1)):round(boundingBox(1)+boundingBox(3)-1)) = true;
                    region_intensity_green = signal_green(region_mask_green);
                    median_intensity_green = median(signal_green(Green_BW_signals));
                    
                    % Update min and max green intensity values prctile(signal_cyan(Cyan_BW_signals), 5);
                    min_intensity_green = prctile(signal_green(Green_BW_signals),5);
                    max_intensity_green = max(region_intensity_green);
                end
            end
            
            % Display the green min and max intensity values
            text(10, 20, sprintf('Green 5th Percentile: %d', round(min_intensity_green)), 'Color', 'red', 'FontSize', 12);
            text(10, 80, sprintf('Green Median Intensity: %d', median_intensity_green), 'Color', 'red', 'FontSize', 12);
            text(10, 40, sprintf('Green Max Intensity: %d', max_intensity_green), 'Color', 'red', 'FontSize', 12);
            % Display the cyan image
            
            stats = regionprops(CC_green, 'Centroid');
            stats_cyan = regionprops(CC_cyan, 'Centroid');
            if numel(stats) > 0 
                % Calculate the centroid coordinates of the first region
                centroid = stats(1).Centroid;
                centroidX = centroid(1);
                centroidY = centroid(2);
                
                % Calculate the dimensions of the area boxes
                area_min_width = sqrt(area_min);
                area_max_width = sqrt(area_max);
                
                % Calculate the positions of the area boxes centered at the centroid
                area_min_x = centroidX - area_min_width / 2;
                area_min_y = centroidY - area_min_width / 2;
                area_max_x = centroidX - area_max_width / 2;
                area_max_y = centroidY - area_max_width / 2;
                
                % Draw the area boxes
                rectangle('Position', [area_min_x, area_min_y, area_min_width, area_min_width], 'EdgeColor', 'g', 'LineWidth', 2);
                rectangle('Position', [area_max_x, area_max_y, area_max_width, area_max_width], 'EdgeColor', 'b', 'LineWidth', 2);
            elseif numel(stats_cyan) > 0
                centroid = stats_cyan(1).Centroid;
                centroidX = centroid(1);
                centroidY = centroid(2);
                
                % Calculate the dimensions of the area boxes
                area_min_width = sqrt(area_min);
                area_max_width = sqrt(area_max);
                
                % Calculate the positions of the area boxes centered at the centroid
                area_min_x = centroidX - area_min_width / 2;
                area_min_y = centroidY - area_min_width / 2;
                area_max_x = centroidX - area_max_width / 2;
                area_max_y = centroidY - area_max_width / 2;
                
                % Draw the area boxes
                rectangle('Position', [area_min_x, area_min_y, area_min_width, area_min_width], 'EdgeColor', 'g', 'LineWidth', 2);
                rectangle('Position', [area_max_x, area_max_y, area_max_width, area_max_width], 'EdgeColor', 'b', 'LineWidth', 2);
            else
                disp("Cell is Empty try Another");
            end

            hold off;
            sgtitle(["Row" num2str(rownumber) "Col" num2str(columnnumber)]);

        end

        % Value changed function: LoadDataButton
        function LoadDataButtonValueChanged(app, event)
            % value = app.LoadDataButton.Value;
            FileName_excel_Results = sprintf('SmartChipData_A%06i%s_Results.xlsx', app.ChipIDEditField.Value, app.dropdown.Value);
            fullFileName_excel_Results = fullfile(app.result_filepath, FileName_excel_Results);
            
            % app.Region_Meta_Data = readtable(fullFileName_excel, 'Sheet', 'Region_Meta_Data'); % storing the Primer_Tags excel
                        
            app.loadeddata = true; 
            opts = detectImportOptions(fullFileName_excel_Results,'Sheet','Summary','ExpectedNumVariables',23);
            t = readtable(fullFileName_excel_Results, opts, 'Sheet','Summary'); % storing the Selected_Spots excel
            summary = table2cell(t);
            disp(string(summary(3196,:)));
            app.status(1, 1:23) = {'Sample', 'Row', 'Column', 'Img_Col', 'File_Ch1', 'File_Ch2', 'Fld_Section', 'Fld_Index', 'Num_Live', ...
    'Num_Dead', 'Num_Other', 'Rev_Live', 'Rev_Dead', 'Rev_Other', 'Rev_Class', 'Condition', 'Index_I7', ...
    'Primer_I7', 'Index_I5', 'Primer_I5', 'Pick_Met', 'Spot_Well', 'Num_Drops'}; 
            app.image_properties(1,1:15) = {'Sample','Row','Column','X_Center','Y_Center','ObjClass','Obj_ID','Area1','Intensity1','AspectRatio1','Extent1','Area2','Intensity2','AspectRatio2','Extent2'};
            app.features(1,1:38) = {'Sample','Row','Column','ObjNum','ObjLink','X_Center','Y_Center','X_Start','Y_Start','Width','Height','ObjClass','Area_Ch1','Intensity_Min_Ch1','Intensity_Q1_Ch1','Intensity_Median_Ch1','Intensity_Q3_Ch1','Intensity_Max_Ch1','Intensity_95Diff_Ch1','Intensity_Total_Ch1','Area_Ch2','Intensity_Min_Ch2','Intensity_Q1_Ch2','Intensity_Median_Ch2','Intensity_Q3_Ch2','Intensity_Max_Ch2','Intensity_95Diff_Ch2','Intensity_Total_Ch2','Intensity_Mean_Ch1','Centroid_X_Ch1','Centroid_Y_Ch1','Aspect_Ratio_Ch1','Extent_Ch1','Intensity_Mean_Ch2','Centroid_X_Ch2','Centroid_Y_Ch2','Aspect_Ratio_Ch2','Extent_Ch2'};
            app.obj_revised(1,1:6) = {'Sample','Row','Column','X_Center','Y_Center','ObjClass'};
            table = readtable(fullFileName_excel_Results, 'Sheet','Obj_Selected');
            prop = table2cell(table);
            % disp(prop(1,:));
            table = readtable(fullFileName_excel_Results, 'Sheet','Obj_Features');
            feat = table2cell(table);
            % disp(feat(1,:));
            % idx = 1;
            % disp(str2double(summary(1,2)));
            % disp(string(summary(3196,21)));
            btype = false;
            excelnumberMatrix = zeros(72,72);
            for i = 1:app.EditField_10.Value * app.EditField_11.Value

                excelnumberMatrix(str2double(summary(i,2)),str2double(summary(i,3))) = i+1;
                app.for_Display(i+1,1) = summary(i,2);
                app.for_Display(i+1,2) = summary(i,3);
                app.for_Display(i+1,3) = summary(i,16);
                app.status(i+1,1:23) = summary(i,1:23);
                if(i <= size(prop, 1))
                    app.image_properties(i+1,1:15) = prop(i,1:15);
                    app.features(i+1,1:38) = feat(i,1:38);
                end
                app.for_Display(i+1,6) = summary(i,21);
            
                app.for_Display(i+1,4) = "-";
                app.for_Display(i+1,5) = "-";
           
            end

            % disp(excelnumberMatrix)
            app.enMatrix = excelnumberMatrix;
            app.UITable.Data = app.for_Display;
            app.SaveButton.Visible = "on";
            app.UpdateThreshholdsButton.Visible = 'on';
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
    
        end

        % Button pushed function: UpdateThreshholdsButton
        function UpdateThreshholdsButtonPushed(app, event)
                       
            
            % app.UpdateThreshholdsButton.Visible = 'off';
app.TileviewButton.Visible = "off";
app.SaveButton.Visible = "off";
% app.CellSortingThreshholdingPanel.Visible = 'off';


% green_intensity = str2double(app.for_Display(1:end,5)); % green 95 intensity 27 app.features 
% cyan_intensity = str2double(app.for_Display(1:end,4)); %cyan median intensity 16 app.features
% cyan_area = str2double(app.features(1:end,13)); %cyan area
% disp(app.features(1:2,1:end))
% disp(app.status(1:2,1:end))
green_intensity = str2double(app.features(1:end,26)); % green 95 intensity 27 app.features
green_area = str2double(app.features(:,21)); % area green is 21 in app.features
cyan_intensity = str2double(app.features(1:end,19)); %cyan median intensity 16 app.features
cyan_area = str2double(app.features(1:end,13)); %cyan area
%Cyan filtering bright enough and right size
cyan_detected = cyan_intensity >= app.Cyan_minSlider.Value ;
%Green Filtering

live = green_intensity < app.Green_maxSlider.Value & green_intensity > app.Green_minSlider.Value; 
dead = green_intensity > app.Green_maxSlider.Value ;
empty = ~(live | dead);


% Get sample ids of each state
c1 = str2double(app.features(cyan_detected & live | cyan_detected & empty, 1))  ;
c2 = str2double(app.features(cyan_detected & dead | ~cyan_detected & live | ~cyan_detected & dead, 1)) ;
c3=str2double(app.features(~cyan_detected & empty, 1));
% disp(app.features(1:3,:))

% drop na
c1 = c1(~isnan(c1));
c2  = c2(~isnan(c2));
c3 = c3(~isnan(c3));

%samples show which sample id is each state c1 is row index of sample id in status 
[samples_1, c1] = intersect(str2double(app.status(:,1)), c1); 
[samples_2, c2] = intersect(str2double(app.status(:,1)), c2);
[samples_3, c3] = intersect(str2double(app.status(:,1)), c3);
% disp([samples_1 c1])
% disp([samples_2 c2])
% disp([samples_3 c3])

%set values
% app.for_Display(1:end,6) = ' ';
% app.status(1:end,21) = missing;
%DEAD %everything is dead to start
if sum(isnan(c2)) == 0
    % disp('writing dead')
    app.status(c2,21) = 'C2'; 
    app.status(c2,22) = '1A1'; 
    app.status(c2,23) = 47 ;
    app.status(c2,9) = 0 ;
    app.status(c2,11) = 1 ;
    app.status(c2,10) = 0;
end
% app.image_properties(1:end,6) = -1;
%LIVE% Cyan detected and green is not too dark and not bright
if sum(isnan(c1)) == 0
    % disp('writing live')
    app.status(c1 ,21) = 'C1'; 
    app.status(c1 ,22) = '1A1' ;
    app.status(c1 ,23) = 47 ;
    app.status(c1,9:10) = 0;  
    app.status(c1,11) = 1;
end

% app.image_properties(cyan_detected & ~bright & ~dark, 6) = 1;
%NOT There% cyan not detected and green says not there

if sum(isnan(c3)) == 0
        % disp('writing not there')
        app.status(c3,21) = 'C3'; 
        app.status(c3,9) = 1;
        app.status(c3,10:11) = 0; 
end
% disp([size(app.status) size(app.features)])
% disp(['Number na C2' num2str(sum(isnan(c2)))])
% disp(['Size of Selected C2 ' num2str(size(c2))])
% disp(['Size of Selected C1 ' num2str(size(c1))])
% disp(['Size of Selected C3 ' num2str(size(c3))])
% 
% disp(['Size of features ' num2str(size(app.features))])
% disp(['Size of  status ' num2str(size(app.status))])
% disp(['Size of for_Display ' num2str(size(app.for_Display))])
%other vars
app.for_Display(2:end,6) = app.status(2:end,21); 

          
% app.cellStatus = cellStatusArray; % saving it so it can be accessed elsewhere 
app.UITable.Data = app.for_Display;

if isempty(app.enMatrix)
    app.enMatrix = excelnumberMatrix;
    
end

app.TileviewButton.Visible = "on";
app.SaveButton.Visible = "on";
app.UpdateThreshholdsButton.Visible = 'on';


        end

        % Value changed function: EditField_12
        function EditField_12ValueChanged(app, event)
            value = app.EditField_12.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Clicked callback: UITable
        function UITableClicked(app, event)
            displayRow = event.InteractionInformation.DisplayRow; % table starts from second row
            if displayRow >= 2
                chipRow = str2double(app.UITable.Data(displayRow,1));
                chipCol = str2double(app.UITable.Data(displayRow,2));
                app.EditField_12.Value = chipRow;
                app.EditField_13.Value = chipCol;
                col_folder = sprintf('C%02i',chipCol);
                cyanFile = sprintf("R%02i_C%02i_0000_00_Cyan.tif",chipRow,chipCol);
                greenFile = sprintf("R%02i_C%02i_0000_01_Green.tif",chipRow,chipCol);
                ch1 = fullfile(app.folder_location,"Cyan","S0000",col_folder,cyanFile);
                ch2 = fullfile(app.folder_location,"Green","S0000",col_folder,greenFile);

                im1 = imread(ch1);
                im2 = imread(ch2);
                %Read Images
                ch1Img = im2double(im1 * app.EditField_7.Value) ;
                ch2Img = im2double(im2 * app.EditField_8.Value);

                %Stats
                app.BrightnessLabel_3.Text = sprintf("Brightness: Max: %d Min: %d Avg: %d",max(im1(:)), min(im1(:)), mean(im1(:)));
                app.BrightnessLabel_2.Text = sprintf("Brightness: Max: %d Min: %d Avg: %d",max(im2(:)), min(im2(:)), mean(im2(:)));
                %Generate False Color Image
                falseImg = zeros([size(ch1Img) 3]);
                falseImg(:,:,3) = 0;
                falseImg(:,:,1) = ch2Img ;
                falseImg(:,:,2) = ch1Img ;
                imshow(ch1Img, "Parent",app.UIAxes);
                imshow(ch2Img,"Parent",app.UIAxes_2);
                imshow(falseImg,"Parent",app.UIAxes_3);
                disp("done")
            end

        end

        % Button down function: UIAxes_3
        function UIAxes_3ButtonDown(app, event)
            
        end

        % Button pushed function: SortButton
        function SortButtonPushed(app, event)
            uiview = app.for_Display(2:end,:);
            if app.AscendingCheckBox.Value
                direction = 'ascend'
                uiview(:,6) = fillmissing(uiview(:,6),'constant',"z") 
            else
                direction = 'descend';
                uiview(:,6) = fillmissing(uiview(:,6),'constant',"A") % hack to get around missing values being sorted to the top using descending
            end
            switch app.SortRowsUsingDropDown.Value
                case "Condition"
                    disp("Confition")
                    uiview = sortrows(uiview(:,:),6,direction);
                case "Status"
                    disp("Status")
                    uiview = sortrows(uiview(:,:),3,direction);
                    
            end
            uiview(uiview(:,6) == 'A' | uiview(:,6) == 'z',6) = missing;
            app.UITable.Data(2:end,:) = uiview;
        end

        % Value changing function: CyanContrastAdjustmentSlider
        function CyanContrastAdjustmentSliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
            if length(size(selection)) == 2 & size(selection,2) == 2 & min(selection(:,2) == 6) == 1 % if selection is only column 6 ie condition
                app.selection = selection;
            end
            
        end

        % Key press function: UITable
        function UITableKeyPress(app, event)
            key = event.Key;
            if key == "control"
                app.mod = key;
            else
                if ~isempty(app.selection)
                    %seems to double length of index and append 3 8 length
                    %of index times idk why halp workaround is to index the
                    %index with the # rows of the selection
                    status_index = str2double(app.UITable.Data(app.selection,[1 2]));
                    
                    switch key
                        case "1"
                            if app.mod == "control"
                                app.UITable.Data(app.selection,6) = "C1";
                                app.status(status_index(1:size(app.selection,1),:),21) = "C1"; 
                                app.mod = "";
                            end
                        case "2"
                            if app.mod == "control"
                                app.UITable.Data(app.selection,6) = "C2";
                                app.status(status_index(1:size(app.selection,1),:),21) = "C2";
                                app.mod = "";
                            end
                        case "3"
                            if app.mod == "control"
                                app.UITable.Data(app.selection,6) = "C3";
                                app.status(status_index(1:size(app.selection,1),:),21) = "C3";
                                app.mod = "";
                            end                    
                    end
                end    

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Cyan')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Green')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'CH1+2')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.ButtonDownFcn = createCallbackFcn(app, @UIAxes_3ButtonDown, true);
            app.CellSortingThreshholdingPanel = uipanel(app.UIFigure);
            app.CellSortingThreshholdingPanel.Title = 'Cell Sorting Threshholding';
            app.EditField = uieditfield(app.CellSortingThreshholdingPanel, 'numeric');
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 3000;
            app.Cyan_minSlider = uislider(app.CellSortingThreshholdingPanel);
            app.Cyan_minSlider.Limits = [1000 15000];
            app.Cyan_minSlider.MajorTicks = [1000 8000 15000];
            app.Cyan_minSlider.MajorTickLabels = {'1000', '8000', '15000'};
            app.Cyan_minSlider.ValueChangedFcn = createCallbackFcn(app, @MinCyanSliderValueChanged, true);
            app.Cyan_minSlider.ValueChangingFcn = createCallbackFcn(app, @MinCyanSliderValueChanged, true);
            app.Cyan_minSlider.Value = 3000;
            app.EditField_2 = uieditfield(app.CellSortingThreshholdingPanel, 'numeric');
            app.EditField_2.ValueDisplayFormat = '%.0f';
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 2500;
            app.Green_minSlider = uislider(app.CellSortingThreshholdingPanel);
            app.Green_minSlider.Limits = [1000 15000];
            app.Green_minSlider.MajorTicks = [1000 8000 15000];
            app.Green_minSlider.MajorTickLabels = {'1000', '8000', '15000'};
            app.Green_minSlider.ValueChangedFcn = createCallbackFcn(app, @Green_minSliderValueChanged, true);
            app.Green_minSlider.Value = 2500;
            app.EditField_3 = uieditfield(app.CellSortingThreshholdingPanel, 'numeric');
            app.EditField_3.ValueDisplayFormat = '%.0f';
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = 12500;
            app.Green_maxSlider = uislider(app.CellSortingThreshholdingPanel);
            app.Green_maxSlider.Limits = [5000 70000];
            app.Green_maxSlider.MajorTicks = [5000 22500 40000];
            app.Green_maxSlider.MajorTickLabels = {'5000', '22500', '40000'};
            app.Green_maxSlider.ValueChangedFcn = createCallbackFcn(app, @Green_maxSliderValueChanged, true);
            app.Green_maxSlider.Value = 12500;
            app.EditField_5 = uieditfield(app.CellSortingThreshholdingPanel, 'numeric');
            app.EditField_5.ValueDisplayFormat = '%.0f';
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5.Value = 4000;
            app.Area_maxSliderLabel = uilabel(app.CellSortingThreshholdingPanel);
            app.Area_maxSliderLabel.Text = 'Area_max';
            app.Area_maxSlider = uislider(app.CellSortingThreshholdingPanel);
            app.Area_maxSlider.Limits = [3000 5000];
            app.Area_maxSlider.ValueChangedFcn = createCallbackFcn(app, @Area_maxSliderValueChanged, true);
            app.Area_maxSlider.Value = 4000;
            app.EditField_6 = uieditfield(app.CellSortingThreshholdingPanel, 'numeric');
            app.EditField_6.ValueDisplayFormat = '%.0f';
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_6.Value = 150;
            app.Green_maxSliderLabel_2 = uilabel(app.CellSortingThreshholdingPanel);
            app.Green_maxSliderLabel_2.Text = 'Area_min   ';
            app.Area_minSlider = uislider(app.CellSortingThreshholdingPanel);
            app.Area_minSlider.Limits = [100 300];
            app.Area_minSlider.MajorTicks = [100 200 300];
            app.Area_minSlider.ValueChangedFcn = createCallbackFcn(app, @Area_minSliderValueChanged, true);
            app.Area_minSlider.Value = 150;
            app.CH2_MaxLabel = uilabel(app.CellSortingThreshholdingPanel);
            app.CH2_MaxLabel.Text = 'CH2_Max';
            app.CH2_MinLabel = uilabel(app.CellSortingThreshholdingPanel);
            app.CH2_MinLabel.Text = 'CH2_Min';
            app.CH1_MinLabel = uilabel(app.CellSortingThreshholdingPanel);
            app.CH1_MinLabel.Text = 'CH1_Min';
            app.ch1Label = uilabel(app.CellSortingThreshholdingPanel);
            app.ch1Label.Text = 'ch1';
            app.ch2Label_2 = uilabel(app.CellSortingThreshholdingPanel);
            app.ch2Label_2.Text = 'ch2';
            app.ch2Label = uilabel(app.CellSortingThreshholdingPanel);
            app.ch2Label.Text = 'ch2';
            app.UpdateThreshholdsButton = uibutton(app.CellSortingThreshholdingPanel, 'push');
            app.UpdateThreshholdsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateThreshholdsButtonPushed, true);
            app.UpdateThreshholdsButton.Text = 'Update Threshholds';
            app.StartRowEditFieldLabel = uilabel(app.CellSortingThreshholdingPanel);
            app.StartRowEditFieldLabel.Text = 'Start Row';
            app.StartRowEditField = uieditfield(app.CellSortingThreshholdingPanel, 'numeric');
            app.StartRowEditField.Limits = [1 72];
            app.StartRowEditField.Value = 1;
            app.EndRowEditFieldLabel = uilabel(app.CellSortingThreshholdingPanel);
            app.EndRowEditFieldLabel.Text = 'End Row';
            app.EndRowEditField = uieditfield(app.CellSortingThreshholdingPanel, 'numeric');
            app.EndRowEditField.Limits = [1 72];
            app.EndRowEditField.Value = 72;
            app.DirectoryPanel = uipanel(app.UIFigure);
            app.DirectoryPanel.Title = 'Directory';
            app.FoldernameEditFieldLabel = uilabel(app.DirectoryPanel);
            app.FoldernameEditFieldLabel.Text = 'Folder name';
            app.FoldernameEditField = uieditfield(app.DirectoryPanel, 'text');
            app.FoldernameEditField.ValueChangedFcn = createCallbackFcn(app, @FoldernameEditFieldValueChanged, true);
            app.SelectFolderButton = uibutton(app.DirectoryPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.ChipIDLabel = uilabel(app.DirectoryPanel);
            app.ChipIDLabel.Text = 'Chip ID:';
            app.ChipIDEditField = uieditfield(app.DirectoryPanel, 'numeric');
            app.ChipIDEditField.ValueDisplayFormat = '%.0f';
            app.ChipIDEditField.ValueChangedFcn = createCallbackFcn(app, @ChipIDEditFieldValueChanged, true);
            app.ALabel = uilabel(app.DirectoryPanel);
            app.ALabel.Text = 'A';
            app.EditField_10 = uieditfield(app.DirectoryPanel, 'numeric');
            app.EditField_10.Limits = [1 72];
            app.EditField_10.Value = 72;
            app.NumberofwellsLabel = uilabel(app.DirectoryPanel);
            app.NumberofwellsLabel.Text = 'Number of wells';
            app.EditField_11 = uieditfield(app.DirectoryPanel, 'numeric');
            app.EditField_11.Limits = [1 72];
            app.EditField_11.Value = 72;
            app.XLabel = uilabel(app.DirectoryPanel);
            app.XLabel.Text = 'X';
            app.dropdown = uidropdown(app.DirectoryPanel);
            app.dropdown.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G'};
            app.dropdown.Value = 'A';
            app.ImageDisplaySettingsPanel = uipanel(app.UIFigure);
            app.ImageDisplaySettingsPanel.Title = 'Image Display Settings ';
            app.EditField_7 = uieditfield(app.ImageDisplaySettingsPanel, 'numeric');
            app.EditField_7.Limits = [0 40];
            app.EditField_7.ValueDisplayFormat = '%.0f';
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EditField_7.Value = 10;
            app.CyanimageindexLabel = uilabel(app.ImageDisplaySettingsPanel);
            app.CyanimageindexLabel.Text = {'Cyan Contrast'; ' Adjustment'};
            app.CyanContrastAdjustmentSlider = uislider(app.ImageDisplaySettingsPanel);
            app.CyanContrastAdjustmentSlider.Limits = [1 40];
            app.CyanContrastAdjustmentSlider.MajorTicks = [1 20 40];
            app.CyanContrastAdjustmentSlider.ValueChangedFcn = createCallbackFcn(app, @CyanContrastAdjustmentSliderValueChanged, true);
            app.CyanContrastAdjustmentSlider.ValueChangingFcn = createCallbackFcn(app, @CyanContrastAdjustmentSliderValueChanging, true);
            app.CyanContrastAdjustmentSlider.Value = 10;
            app.GreenContrastAdjustmentSliderLabel = uilabel(app.ImageDisplaySettingsPanel);
            app.GreenContrastAdjustmentSliderLabel.Text = {'Green Contrast '; 'Adjustment'};
            app.GreenContrastAdjustmentSlider = uislider(app.ImageDisplaySettingsPanel);
            app.GreenContrastAdjustmentSlider.Limits = [1 40];
            app.GreenContrastAdjustmentSlider.MajorTicks = [1 20 40];
            app.GreenContrastAdjustmentSlider.ValueChangedFcn = createCallbackFcn(app, @GreenContrastAdjustmentSliderValueChanged, true);
            app.GreenContrastAdjustmentSlider.Value = 10;
            app.EditField_8 = uieditfield(app.ImageDisplaySettingsPanel, 'numeric');
            app.EditField_8.Limits = [0 40];
            app.EditField_8.ValueDisplayFormat = '%.0f';
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.EditField_8.Value = 10;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Row'; 'Column'; 'Status'; 'CH1_Max intensity'; 'CH2_Median intensity'; 'Condition'};
            app.UITable.ColumnRearrangeable = 'on';
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.ClickedFcn = createCallbackFcn(app, @UITableClicked, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.KeyPressFcn = createCallbackFcn(app, @UITableKeyPress, true);
            app.VisualizeSettingsPanel = uipanel(app.UIFigure);
            app.VisualizeSettingsPanel.Title = 'Visualize Settings';
            app.ViewSettingsButton = uibutton(app.VisualizeSettingsPanel, 'push');
            app.ViewSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewSettingsButtonPushed, true);
            app.ViewSettingsButton.Text = 'View Settings';
            app.CelltoviewEditFieldLabel = uilabel(app.VisualizeSettingsPanel);
            app.CelltoviewEditFieldLabel.Text = 'Cell to view';
            app.CelltoviewRow = uieditfield(app.VisualizeSettingsPanel, 'numeric');
            app.CelltoviewRow.Limits = [1 72];
            app.CelltoviewRow.Value = 1;
            app.CelltoviewCol = uieditfield(app.VisualizeSettingsPanel, 'numeric');
            app.CelltoviewCol.Limits = [1 72];
            app.CelltoviewCol.Value = 1;
            app.xLabel = uilabel(app.VisualizeSettingsPanel);
            app.xLabel.Text = 'x';
            app.EditField_12 = uieditfield(app.VisualizeSettingsPanel, 'numeric');
            app.EditField_12.ValueDisplayFormat = '%.0f';
            app.EditField_12.ValueChangedFcn = createCallbackFcn(app, @EditField_12ValueChanged, true);
            app.EditField_13 = uieditfield(app.VisualizeSettingsPanel, 'numeric');
            app.EditField_13.ValueDisplayFormat = '%.0f';
            app.RowLabel = uilabel(app.VisualizeSettingsPanel);
            app.RowLabel.Text = 'Row ';
            app.ColLabel = uilabel(app.VisualizeSettingsPanel);
            app.ColLabel.Text = 'Col';
            app.ViewingLabel = uilabel(app.VisualizeSettingsPanel);
            app.ViewingLabel.Text = 'Viewing';
            app.ProcessAllButton = uibutton(app.UIFigure, 'push');
            app.ProcessAllButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessAllButtonPushed, true);
            app.ProcessAllButton.Text = 'Process All';
            app.TileviewButton = uibutton(app.UIFigure, 'push');
            app.TileviewButton.ButtonPushedFcn = createCallbackFcn(app, @TileviewButtonPushed, true);
            app.TileviewButton.Text = 'Tile view';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://molonc.atlassian.net/wiki/spaces/SOFT/pages/1942847497/Single+Cell+Sorting+Image+App';
            app.Hyperlink.Text = 'HELP';
            app.SaveButton = uibutton(app.UIFigure, 'state');
            app.SaveButton.ValueChangedFcn = createCallbackFcn(app, @SaveButtonValueChanged, true);
            app.SaveButton.Text = 'Save ';
            app.LoadDataButton = uibutton(app.UIFigure, 'state');
            app.LoadDataButton.ValueChangedFcn = createCallbackFcn(app, @LoadDataButtonValueChanged, true);
            app.LoadDataButton.Text = 'Load Data';
            app.SortRowsUsingDropDownLabel = uilabel(app.UIFigure);
            app.SortRowsUsingDropDownLabel.Text = 'Sort Rows Using';
            app.SortRowsUsingDropDown = uidropdown(app.UIFigure);
            app.SortRowsUsingDropDown.Items = {'Condition', 'Status'};
            app.SortRowsUsingDropDown.Value = 'Condition';
            app.SortButton = uibutton(app.UIFigure, 'push');
            app.SortButton.ButtonPushedFcn = createCallbackFcn(app, @SortButtonPushed, true);
            app.SortButton.Text = 'Sort';
            app.BrightnessLabel_3 = uilabel(app.UIFigure);
            app.BrightnessLabel_3.Text = 'Brightness';
            app.BrightnessLabel_2 = uilabel(app.UIFigure);
            app.BrightnessLabel_2.Text = 'Brightness';
            app.AscendingCheckBox = uicheckbox(app.UIFigure);
            app.AscendingCheckBox.Text = 'Ascending';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SingleCellSorting_faster(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end